classdef EasyBehaviour < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EasyBehaviourV125UIFigure   matlab.ui.Figure
        Menu_File                   matlab.ui.container.Menu
        Menu_NewParadigm            matlab.ui.container.Menu
        Menu_NewExperiment          matlab.ui.container.Menu
        Menu_ExportResults          matlab.ui.container.Menu
        Menu_ExportImages           matlab.ui.container.Menu
        Menu_ExportMovie            matlab.ui.container.Menu
        Menu_ParadigmFeatures       matlab.ui.container.Menu
        Menu_ExportBehaviour        matlab.ui.container.Menu
        Menu_Quit                   matlab.ui.container.Menu
        Menu_Image                  matlab.ui.container.Menu
        Menu_BrightnessAndContrast  matlab.ui.container.Menu
        Menu_Scale                  matlab.ui.container.Menu
        Menu_CropXY                 matlab.ui.container.Menu
        Menu_CropT                  matlab.ui.container.Menu
        Menu_Configuration          matlab.ui.container.Menu
        Menu_Peripherals            matlab.ui.container.Menu
        Menu_Preference             matlab.ui.container.Menu
        Menu_Tool                   matlab.ui.container.Menu
        Menu_Tracker                matlab.ui.container.Menu
        Menu_Parameterization       matlab.ui.container.Menu
        Menu_PopulationFeatures     matlab.ui.container.Menu
        Menu_Plugins                matlab.ui.container.Menu
        Menu_Help                   matlab.ui.container.Menu
        Menu_Document               matlab.ui.container.Menu
        Menu_About                  matlab.ui.container.Menu
        GridLayout_Main             matlab.ui.container.GridLayout
        GridLayout2                 matlab.ui.container.GridLayout
        Lamp_Kernel                 matlab.ui.control.Lamp
        Label_3                     matlab.ui.control.Label
        Lamp_Device                 matlab.ui.control.Lamp
        NILabel                     matlab.ui.control.Label
        EditField_LeftTime          matlab.ui.control.EditField
        Label                       matlab.ui.control.Label
        DropDown_ColorPalette       matlab.ui.control.DropDown
        Label_4                     matlab.ui.control.Label
        GridLayout_Progress         matlab.ui.container.GridLayout
        Label_Progress              matlab.ui.control.Label
        Label_ProgressLeft          matlab.ui.control.Label
        UIAxes_TaskViewer           matlab.ui.control.UIAxes
        ContextMenu                 matlab.ui.container.ContextMenu
        Menu_SetROI                 matlab.ui.container.Menu
        Menu_ROI_Rectangle          matlab.ui.container.Menu
        Menu_ROI_Freehand           matlab.ui.container.Menu
        Menu_LabelReassignment      matlab.ui.container.Menu
    end

    properties (Constant, Hidden)
        VER = "1.2.5"
        DAEMON_PERIOD = 0.2     % minimal time that daemon process will not effect writting speed
        SUB_APPS_CODE = ["HWC", "PF", "SC", "TK"]
    end

    properties (Access = private, Hidden)
        %% kernel object
        kernel      (1,1)   EBKernel            % dynamic initialize before running

        %% kernel option object
        keropt      (1,1)   EBKernelOptions     % options for kernel before running

        %% writer object
        imwriter    (:,1)                       % EBImageWriter object, save images stack
        mvwriter    (:,1)                       % EBVideoWriter object, save movie

        %% application options
        pref_opt    (1,1)   struct              % application preference options
        disp_opt    (1,1)   struct              % display options (real time video replay)
        export_opt  (1,1)   struct              % movie export options
        scale_opt   (1,1)   struct              % scale bar options

        %% subapps
        BC_handle                               % BCAdjuster object, brightness and contrast control
        HWC_handle                              % HardwareConfiguration object, hardware manager
        PA_handle                               % Parameterization object, motion parameterization control
        PF_handle                               % Preference object, application behaviour control
        SC_handle                               % ScaleBar object, manages scale bar on display window
        TK_handle                               % Tracking object, handles tracking on motion imaging

        %% component
        VV_handle   (1,1)                       % video viewer handle, contained in matlab.ui.container.GridLayout (classical mode)
                                                % or matlab.ui.Figure (simple mode)
        VC_handle   (1,1)                       % if use "simple" display mode, this will be VV container
    end

    properties (Access = private)
        %% auxiliary variables / objects
        ebd_timer   (1,1)                       % 1-by-1 timer for system appearance update (daemon process)
        mpath       (1,1)   string              % file path of EasyBehaviour.mlapp
        patch_obj   (:,1)                       % patch object on task viewer
        parpool_obj (:,1)                       % parallel pool object handle
        progress    (1,1)   double              % running progress value
        task_lock   (1,1)   logical             % assign true if task has been refresh on panel
        text_obj    (:,1)                       % the trials label text
        user_lock   (1,1)   logical             % assign true if EBParadigm defined experiment is running
        user_mode   (1,1)                       % 1-by-1 EBStatus, running mode, MODE_RECORDING, MODE_READING, MODE_WRITTING
    end

    methods (Access = ?HardwareConfiguration)
        function SetDevices(app, dev)
            arguments
                app 
                dev     (1,1)   dictionary
            end

            % configure device by kernel
            app.kernel.add_device(dev);

            app.task_lock = false;
        end

        function [dev, isLock] = GetDevices(app)
            dev = app.kernel.Devices;
            isLock = app.user_lock;
        end
    end

    methods (Access = ?MovieExporter)
        function SetExportOptions(app, opt)
            arguments
                app
                opt    (1,1)   struct
            end

            app.export_opt = opt;
        end

        function [opt, isLock] = GetExportOptions(app)
            opt = app.export_opt;
            isLock = app.user_lock;
        end
    end

    methods (Access = ?Preference)
        function SetPreferOptions(app, opt)
            arguments
                app
                opt    (1,1)   struct
            end

            app.pref_opt = opt;

            UpdatePreference(app);
        end

        function [opt, isLock] = GetPreferOptions(app)
            opt = app.pref_opt;
            isLock = app.user_lock;
        end
    end

    methods (Access = ?ScaleBar)
        function SetScaleOptions(app, opt)
            arguments
                app
                opt    (1,1)   struct
            end

            app.scale_opt = opt;
        end

        function [opt, isLock] = GetScaleOptions(app)
            opt = app.scale_opt;
            isLock = app.user_lock;
        end
    end

    methods (Access = ?Tracking)
        function SetTrackingOptions(app, opt)
            arguments
                app
                opt    (1,1)   struct
            end

            app.keropt.TrackerOptions = opt;
        end

        function [opt, isLock] = GetTrackingOptions(app)
            opt = app.keropt.TrackerOptions;
            isLock = app.user_lock;
        end
    end

    methods (Access = ?Parameterization)
        function SetParameterizerOptions(app, opt)
            arguments
                app
                opt    (1,1)   struct
            end

            app.keropt.ParameterizerOptions = opt;
        end

        function [opt, isLock] = GetParameterizerOptions(app)
            opt = app.keropt.ParameterizerOptions;
            isLock = app.user_lock;
        end
    end

    methods (Access = ?PopulationFeatures)
        % TODO:
    end

    methods (Access = private)
        function InitEBProfile(app)
            % init preference profile
            app.pref_opt = prefset();

            % init video player profile
            app.disp_opt = dispset();

            % init movie exporter profile
            app.export_opt = exportset();

            % init scale options
            app.scale_opt = scaleset();

            % init variables
            app.progress = 0;
            app.patch_obj = [];
            app.parpool_obj = [];
            app.task_lock = false;
            app.text_obj = [];
            app.user_mode = EBStatus.MODE_RECORDING;
            app.user_lock = false;
            app.mpath = fileparts(mfilename("fullpath"));

            % init writer
            app.imwriter = [];
            app.mvwriter = [];

            % init daemon process (ebd_timer)
            app.ebd_timer = timer("BusyMode",       "drop", ...
                                  "ExecutionMode",  "fixedDelay", ...
                                  "Period",         app.DAEMON_PERIOD, ...
                                  "Name",           "EB_Daemon", ...
                                  "TasksToExecute", inf, ...
                                  "TimerFcn",       @app.UpdateAppearance);

            % init kernel
            app.kernel = EBKernel();
        end

        function InitParadigm(app)

        end

        function InitAppearance(app)
            %% Init Menu
            app.Menu_NewParadigm.Enable = "off";
            app.Menu_ExportResults.Enable = "off";

            %% Init Task Viewer Interaction
            app.UIAxes_TaskViewer.Toolbar = [];
            disableDefaultInteractivity(app.UIAxes_TaskViewer);

            %% Init LeftTime Indicator
            app.EditField_LeftTime.Editable = "off";
        end

        function UpdatePreference(app)
            %% Refresh Language
            RefreshLanguage(app);

            %% Update Progress Bar Appearance
            RefreshProgressBar(app);

            %% Update Window Style
            RefreshWindowStyle(app);

            %% Update Camera Buffer
            device = app.kernel.Devices;
            if device.isConfigured && device.isKey("camera")
                device{"camera"}.Storage = app.pref_opt.CameraCache;
            end

            %% Update Display Profile
            RefreshVideoPlayerProfile(app);

            %% Update Parallel Configuration
            % UpdateParallelCluster(app);

        end

        % TODO: 
        function RefreshLanguage(app)
            % Refresh Main Window 


            % Refresh Sub Apps Window

        end

        function RefreshProgressBar(app)
            app.GridLayout_Progress.Visible = app.pref_opt.ProgressBarVisible;
            app.Label_Progress.BackgroundColor = app.pref_opt.ProgressBarForeColor;
            app.Label_ProgressLeft.BackgroundColor = app.pref_opt.ProgressBarBackColor;
        end

        function RefreshWindowStyle(app)
            switch app.pref_opt.WindowStyle
                case "simple"
                    % mini tab with simple player (default)
                    app.EasyBehaviourV125UIFigure.Position = [100,100,500,150];
                    %                                vi  ta  pr  sb
                    app.GridLayout_Main.RowHeight = {0, '1x', 7, 25};   % flexible task viewer
                case "classical"
                    % full tab with player
                    app.EasyBehaviourV125UIFigure.Position = [100,100,700,450];
                    app.GridLayout_Main.RowHeight = {'1x', 80, 7, 25}; % fixed task viewr
                otherwise
            end
        end

        function RefreshVideoPlayerProfile(app)
            image_opt = app.disp_opt.Image;

            marker_opt = struct("TimeStamp",    app.pref_opt.TimeStampOpts, ...
                                "ScaleBar",     app.pref_opt.ScaleBarOpts, ...
                                "Metadata",     app.pref_opt.MetadataOpts, ...
                                "PlaySpeed",    app.pref_opt.PlaySpeedOpts, ...
                                "DetectBox",    app.pref_opt.DetectBoxOpts, ...
                                "Trace",        app.pref_opt.TraceOpts);

            app.disp_opt = dispset("Image",     image_opt, ...
                                   "Marker",    marker_opt);

            if isobject(app.VV_handle) && isvalid(app.VV_handle)
                app.VV_handle.SetDisplayOptions(app.disp_opt);
            end
        end

        function UpdateParallelCluster(app)
            % Note: should be called after tracking configuration
            if ~isempty(gcp("nocreate"))
                pobj = gcp("nocreate");
                if pobj.NumWorkers ~= app.pref_opt.CoresEnableCPU
                    % restart
                    upb = uiprogressdlg(app.EasyBehaviourV125UIFigure, "Indeterminate","on", ...
                        "Message","正在重启并行计算...", "Title", "系统消息", ...
                        "Icon","info");
                    delete(gcp);
                    app.parpool_obj = parpool("EBP", app.pref_opt.CoresEnableCPU, ...
                        "SpmdEnabled",false);
                    delete(upb);
                else
                    % control transformation
                    app.parpool_obj = gcp;
                end
            else
                % turn on
                upb = uiprogressdlg(app.EasyBehaviourV125UIFigure, "Indeterminate","on", ...
                        "Message","正在启动并行计算...", "Title", "系统消息", ...
                        "Icon","info");
                app.parpool_obj = parpool("EBP", app.pref_opt.CoresEnableCPU, ...
                    "SpmdEnabled",false);
                delete(upb);
            end
        end

        function CloseEasyBehaviourSystem(app)
            upb = uiprogressdlg(app.EasyBehaviourV125UIFigure, "Indeterminate","on", ...
                "Message","正在关闭EasyBehaviour...", "Title", "系统消息", ...
                "Icon","info");

            % stop daemon process
            stop(app.ebd_timer);
            delete(app.ebd_timer);

            % close sub apps
            for s = 1:numel(app.SUB_APPS_CODE)
                handle = eval(sprintf("app.%s_handle;", app.SUB_APPS_CODE(s)));
                if ~isempty(handle) && isvalid(handle)
                    if ismember("CloseThis", string(methods(handle)))
                        handle.CloseThis(); % overwritten
                    else
                        % call default destructor
                        delete(handle);
                    end
                end
                
            end

            % close video player or its parent(container)
            if isobject(app.VV_handle) && isvalid(app.VV_handle)
                delete(app.VV_handle);
            end
            if isobject(app.VC_handle) && isvalid(app.VC_handle)
                delete(app.VC_handle);
            end

            % free kernel
            app.kernel.delete();

            % remove support timers auto generate by MATLAB (single-shot)
            delete(timerfindall);

            % release parallel resource
            delete(app.parpool_obj);

            delete(upb);
            % ~
            delete(app);
        end

        function AutoResizeProgressBar(app)
            UpdateProgressBar(app, app.progress);
        end

        function UpdateStatusLamp(app, state)           
            % set device lamp
            app.Lamp_Device.Color = state(1).ToRGB();

            % set kernel lamp
            app.Lamp_Kernel.Color = state(2).ToRGB();
        end

        function UpdateProgressBar(app, p)
            % update progress
            app.progress = p;

            win_width = app.EasyBehaviourV125UIFigure.Position(3);
            offset_x = app.GridLayout_Main.Padding(1) ...
                + app.GridLayout_Progress.ColumnWidth{1};

            % dynamic calculate column width
            app.GridLayout_Progress.ColumnWidth{2} ...
                = round((win_width-2*offset_x)*p);

            % redraw right now
            drawnow nocallbacks
        end

        function tasks = UpdateColorPalette(app, tasks)
            if ~isempty(tasks)
                % split all independent symbol as channel
                symbols = [];
                for k = 1:numel(tasks.code)
                    symbols = [symbols; tasks.code(k).split("&")]; %#ok<AGROW>
                end
                idcmap = unique(symbols); idcmap(idcmap=="") = [];

                % set items data to color palette
                app.DropDown_ColorPalette.ItemsData = idcmap;
                app.DropDown_ColorPalette.Items = idcmap;

                % remove all possible style of color palette
                removeStyle(app.DropDown_ColorPalette);

                % append new styles
                cmap = jet(numel(idcmap));
                cmap(idcmap=="CLOSE",:) = [0, 0, 0];    % force to black
                for k = 1:numel(idcmap)
                    s = uistyle("BackgroundColor", cmap(k,:));
                    addStyle(app.DropDown_ColorPalette, s, "item", k);
                    % modofy forground color for high contrast
                    if idcmap(k)=="CLOSE"
                        s = uistyle("FontColor", [1, 1, 1]);
                        addStyle(app.DropDown_ColorPalette, s, "item", k);
                    end
                end

                % change to mixing color representation
                for k = 1:numel(tasks.code)
                    [~, p] = ismember(tasks.code(k).split("&"), idcmap);
                    tasks.mixing{k} = tasks.mixing{k}*cmap(p,:);    % replace to color
                end
            else
                % reset color palette
                app.DropDown_ColorPalette.ItemsData = [];
                app.DropDown_ColorPalette.Items = {};

                % remove all possible style of color palette
                removeStyle(app.DropDown_ColorPalette);
            end
        end

        function UpdateTaskViewer(app, tasks)
            if ~isempty(tasks)
                % set x, y range and xtick
                app.UIAxes_TaskViewer.XLim = [0, sum(tasks.delay)];

                % draw blocks on task viewer
                XX = cumsum([0; tasks.delay]);
                XX = [XX(1:end-1), XX(2:end)];
                XM = [repmat(XX(:,1),1,2), repmat(XX(:,2),1,2)]';
                YM = repmat([0;1;1;0],1,numel(tasks.delay));
                CM = reshape(cell2mat(tasks.mixing), [], 1, 3);

                % patch the task blocks
                if ~isempty(app.patch_obj) && isvalid(app.patch_obj), delete(app.patch_obj); end
                app.patch_obj =  patch(app.UIAxes_TaskViewer, XM, YM, CM, ...
                    'FaceAlpha',0.4, 'EdgeColor','none');

                % text on these patchs
                if ~isempty(app.text_obj) && any(isvalid(app.text_obj)), delete(app.text_obj); end
                app.text_obj = text(app.UIAxes_TaskViewer, ...
                    mean(XX,2), 0.5*ones(size(tasks.delay)), tasks.code.replace("CLOSE",""), ...
                    "FontSize",14, "VerticalAlignment","middle", ...
                    "HorizontalAlignment","center", "FontWeight","bold");

                app.UIAxes_TaskViewer.Visible = "on";
            else
                % reset task viewer
                app.UIAxes_TaskViewer.XLim = [0, 1];
                if ~isempty(app.patch_obj) && isvalid(app.patch_obj), delete(app.patch_obj); end
                if ~isempty(app.text_obj) && any(isvalid(app.text_obj)), delete(app.text_obj); end
                
                % hidden
                app.UIAxes_TaskViewer.Visible = "off";
            end
            
        end

        function UpdateAppearance(app, src, evt)
            % This function will be called every 0.5s by system checker agent
            % update the following components:
            %   - TaskViewer: task table, read from kernel
            %   - ColorPalette: generate by Task Table, read from kernel
            %   - ProgressBar: progress, calculate by LeftTime and Total Time
            %   - LeftTime: left time, seconds, read from kernel
            %   - StatusLamp: system status, include device status and
            %                 kernel status, read from kernel

            src;        %#ok<VUNUS> % timer: daemon process
            evt;        %#ok<VUNUS>

            status = app.kernel.Status;

            %% parse status and go to different switch

            % 1. Always Update Lamps
            UpdateStatusLamp(app, status);

            % 2. Update Task Viewer by task_lock Flag
            % 3. Create Video Player if Needed
            if app.task_lock == false
                %% Read TaskTable for 'TaskViewer' and 'ColorPalette'
                % readout task tables
                tasks = app.kernel.Tasks;   % [code, mixing, delay]

                %% Update Color Palette
                tasks = UpdateColorPalette(app, tasks); % [code, color, delay]

                %% Update Task Viewer
                UpdateTaskViewer(app, tasks);

                %% Create Viewer by mode
                if (status(2) == EBStatus.KERNEL_READY) && ...
                        ~app.kernel.IsRunning
                    if isobject(app.VC_handle), delete(app.VC_handle); end
                    if isobject(app.VV_handle)&&isvalid(app.VV_handle), delete(app.VV_handle); end
                    switch app.pref_opt.WindowStyle
                        case "classical"
                            app.VV_handle = EBVideoPlayer(app.GridLayout_Main);
                            app.VV_handle.SetKernel(app.kernel);
                            app.VV_handle.UpdateViewer();
                            app.VV_handle.SetDisplayOptions(app.disp_opt);
                            app.VV_handle.Layout.Row = 1;
                            app.VV_handle.Layout.Column = 1;
                        case "simple"
                            % we generate new uiFigure
                            app.VC_handle = uifigure("Name","Video Player", ...
                                "Icon", fullfile(app.mpath, "components", "VideoPlayer", "sources", "video_player.png"), ...
                                "Visible","off");
                            app.VV_handle = EBVideoPlayer(app.VC_handle);
                            app.VV_handle.SetKernel(app.kernel);
                            app.VV_handle.UpdateViewer();
                            app.VV_handle.SetDisplayOptions(app.disp_opt);
                            app.VC_handle.Visible = "on";
                        otherwise
                    end

                    % turn on Video Viewer
                    app.VV_handle.Enable = true;
                end

                % switch flag
                app.task_lock = true;
            end

            % 3. Update Left Time Determined by Task Status
            if status(2) == EBStatus.KERNEL_UNREADY
                app.EditField_LeftTime.Value = "";
            else
                tleft = app.kernel.LeftTime;
                % format to left time
                app.EditField_LeftTime.Value ...
                    = string(duration([0, 0, tleft], "Format","hh:mm:ss.S"));
            end

            % 4. Update Progress Bar Deterimined by Task Status
            switch status(3)
                case EBStatus.TASK_NONE
                    UpdateProgressBar(app, 0);  % reset
                case EBStatus.TASK_RUNNING
                    ttot = app.kernel.TotalTime;    % some time
                    
                    % update progress
                    p = min(max((ttot-tleft)/ttot, 0), 1);
                    UpdateProgressBar(app, p);
                case EBStatus.TASK_DONE
                    % app.Menu_NewParadigm.Enable = "on";
                    app.Menu_ExportResults.Enable = "on";
                    % update write progress
                    if app.user_mode == EBStatus.MODE_WRITTING
                        if isobject(app.imwriter) && isvalid(app.imwriter)  % assume running time < 0.01s
                            p = app.imwriter.WrittingProgress;
                        elseif isobject(app.mvwriter) && isvalid(app.mvwriter)
                            p = app.mvwriter.WrittingProgress;
                        else
                            % ??? nothing valid, delete now
                            % but user_mode has not changed
                            % must be final loop of deamon process (in 0.5 sec)
                            p = 1;
                        end

                        UpdateProgressBar(app, p);
                    end
                otherwise
            end
            
            % redraw immediately
            drawnow
        end

        function SubAppsLockSync(app, state)
            arguments
                app
                state   (1,1)   string  {mustBeMember(state, ["Lock", "Unlock"])} = "Lock"
            end

            % internal state
            switch state
                case "Lock"
                    app.user_lock = true;
                case "Unlock"
                    app.user_lock = false;
                otherwise
            end

            % modify sub apps
            for n = 1:numel(app.SUB_APPS_CODE)
                handle = eval(sprintf("app.%s_handle;", app.SUB_APPS_CODE(n)));
                if ~isempty(handle) && isvalid(handle)
                    if ismember(state, string(methods(class(handle))))
                        eval(sprintf("handle.%s();", state));
                    else
                        try
                            eval(sprintf("handle.%s();", state));
                        catch
                            throw(MException("EasyBehaviour:invalidSubApp", ...
                                "Sub app [%s] does not implement '%s' method " + ...
                                "or which is hidden to EasyBehaviour.", ...
                                class(handle), state));
                        end
                    end
                end
            end
        end

        function CreateNewExperiment(app)
            % remove display window
            if isobject(app.VC_handle) && isvalid(app.VC_handle)
                delete(app.VC_handle);
            end
            if isobject(app.VV_handle) && isvalid(app.VV_handle)
                delete(app.VV_handle);
            end

            % clear kernel video buffer
            app.kernel.clear_buffer();

            % reset task flag, wait for daemon regenerate
            app.task_lock = false;
        end
    end

    methods(Static)
        function status = DeployParallelProfile()
            % load the parallel setting on different platform
            % note that app will not recover the configuration
            pinfo = struct2table(ver);
            par_flag = any(string(pinfo.Name).contains(...
                "Parallel Computing Toolbox"));

            if par_flag == true
                profs = string(parallel.listProfiles);  % >=R2022b
                if ismember("EBP", profs)
                    if parallel.defaultProfile()~="EBP"
                        fprintf("Parallel profile changed: %s -> %s\n", ...
                            parallel.defaultProfile(), "EBP");
                        parallel.defaultProfile("EBP");
                    end
                else
                    % load parallel configuration
                    pathToMLAPP = fileparts(mfilename('fullpath'));
                    config_path = [pathToMLAPP, filesep, 'bin',filesep, ...
                        'EBP.mlsettings'];
                    if isfile(config_path)
                        pf = parallel.importProfile(config_path);
                        parallel.defaultProfile(pf); % set as default
                        disp("EBP profile set as default.");
                    else
                        throw(MException("EasyBehaviour:lostFile", ...
                            "Parallel cluster configuration file lost."));
                    end
                end
                status = true;
            else
                status = false;
            end
        end

        function r = ValidateExternalPackages()
            % This function validate external packages
            pinfo = struct2table(ver);
            par_flag = any(string(pinfo.Name).contains(...
                "Parallel Computing Toolbox"));
            img_flag = any(string(pinfo.Name).contains(...
                "Image Acquisition Toolbox"));
            daq_flag = any(string(pinfo.Name).contains(...
                "Data Acquisition Toolbox"));

            if ~img_flag
                warning("ValidateExternalPackage:toolboxNotInstall", ...
                    "Necessary toolbox: Image Acquisition Toolbox is not installed.");
            end
            if ~daq_flag
                warning("ValidateExternalPackage:toolboxNotInstall", ...
                    "Necessary toolbox: Data Acquisition Toolbox is not installed.");
            end
            if ~par_flag
                warning("ValidateExternalPackage:toolboxNotInstall", ...
                    "Necessary toolbox: Parallel Computing Toolbox is not installed.");
            end

            r = (img_flag && daq_flag && par_flag);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, debug_flag)
            
            % validate External Package
            r = EasyBehaviour.ValidateExternalPackages();

            if true == r
                % init System Profile
                InitEBProfile(app);

                % init Paradigm
                InitParadigm(app);
            else
                warning("EasyBehaviour:toolsLost", "The system initialization " + ...
                    "failed due to the lack of necessary toolbox.")
                CloseEasyBehaviourSystem(app)
            end

            % configure parallel cluster
            EasyBehaviour.DeployParallelProfile();

            % update preference
            UpdatePreference(app);

            % modify appearance
            InitAppearance(app);

            % update parallel cluster
            % UpdateParallelCluster(app);

            % start daemon process
            start(app.ebd_timer);

            % done
        end

        % Menu selected function: Menu_NewParadigm
        function Menu_NewParadigmSelected(app, event)
            
        end

        % Menu selected function: Menu_Quit
        function Menu_QuitSelected(app, event)
            CloseEasyBehaviourSystem(app);
        end

        % Menu selected function: Menu_Document
        function Menu_DocumentSelected(app, event)
            
        end

        % Menu selected function: Menu_About
        function Menu_AboutSelected(app, event)
            msg = sprintf(['Easy Larvae Behaviour \n' ...
                'Larvae odors-behaviour analysis system \n' ...
                'Version: 1.2.5 (Archimedes) release @si-lab \n' ...
                'Release Date: 2024/12/29 \n' ...
                'Author: Weihan Li \n' ...
                'E-mail: liweihan20@mails.ucas.ac.cn']);
            uialert(app.EasyBehaviourV125UIFigure, msg, "消息", "Icon","info");
        end

        % Close request function: EasyBehaviourV125UIFigure
        function EasyBehaviourV125UIFigureCloseRequest(app, event)
            
            CloseEasyBehaviourSystem(app);
        end

        % Key press function: EasyBehaviourV125UIFigure
        function EasyBehaviourV125UIFigureKeyPress(app, event)
            key = event.Key;
            
            switch key
                case "return"
                    % kernel already running
                    if app.user_lock, return; end
                    
                    status = app.kernel.Status;
                    if status(2) ~= EBStatus.KERNEL_READY, return; end

                    % total features test
                    app.kernel.Feature = [EBStatus.TRACKER_ENABLE, ...
                                          EBStatus.PARAMETERIZER_ENABLE, ...
                                          EBStatus.POPULATION_ENABLE];

                    slt = uiconfirm(app.EasyBehaviourV125UIFigure, ...
                        "开始实验？","系统消息","Icon","question");

                    if isequal("OK", slt)
                        % video viewer acquire is enabled
                        app.VV_handle.Connect = true;

                        % running ... EB main process is caught in
                        % TODO: 
                        SubAppsLockSync(app, "Lock");
                        app.kernel.run();
                        SubAppsLockSync(app, "Unlock");

                        % video viewer acquire is disabled
                        app.VV_handle.Connect = false;
                    end

                    % 
                case "escape"
                    % kernel not running
                    if ~app.user_lock, return; end

                    slt = uiconfirm(app.EasyBehaviourV125UIFigure, ...
                        "终止实验并放弃当前数据？","系统消息","Icon","question");

                    if isequal("OK", slt)
                        % disconnect video viewer
                        app.VV_handle.Connect = false;

                        % abort the experiment
                        app.kernel.abort();

                        % unlock sub apps
                        SubAppsLockSync(app, "Unlock");

                        % create new experiment
                        CreateNewExperiment(app);
                    end
                case "space"
                    % pause/recover experiment
                otherwise
            end
        end

        % Menu selected function: Menu_Parameterization
        function Menu_ParameterizationSelected(app, event)
            % Parameterization：
            % 参数化：选择图像前景的(时空)局部运动特征
            % [运动特征]：
            %   - 运动速度
            %   - 加速度
            %   - ...
            % 提供自定义选项：自定义函数 U(S,F,T)->u, S: 前景像素(强度&坐标)集, 
            % F: 前景梯度算子(滤波器：分割像素集)， T: 时间窗口
            % u: 大小由F定义的向量，数值为U定义的运动特征
        end

        % Menu selected function: Menu_BrightnessAndContrast
        function Menu_BrightnessAndContrastSelected(app, event)
            
        end

        % Menu selected function: Menu_Scale
        function Menu_ScaleSelected(app, event)
            if isempty(app.SC_handle) || ~isvalid(app.SC_handle)
                app.SC_handle = ScaleBar(app);
            end
        end

        % Menu selected function: Menu_Peripherals
        function Menu_PeripheralsSelected(app, event)
            if isempty(app.HWC_handle) ...
                    || ~isvalid(app.HWC_handle)
                dlg = uiprogressdlg(app.EasyBehaviourV125UIFigure, "Icon","info", ...
                    "Indeterminate","on", "Message","正在打开硬件配置程序...", ...
                    "Title","HWC消息");

                app.HWC_handle = HardwareConfiguration(app);

                delete(dlg);
            end
        end

        % Menu selected function: Menu_Preference
        function Menu_PreferenceSelected(app, event)
            if isempty(app.PF_handle) || ~isvalid(app.PF_handle)
                app.PF_handle = Preference(app);
            end
        end

        % Menu selected function: Menu_Tracker
        function Menu_TrackerSelected(app, event)
            if isempty(app.TK_handle) || ~isvalid(app.TK_handle)
                app.TK_handle = Tracking(app);
            end
        end

        % Size changed function: EasyBehaviourV125UIFigure
        function EasyBehaviourV125UIFigureSizeChanged(app, event)

            AutoResizeProgressBar(app);
        end

        % Menu selected function: Menu_ExportImages
        function Menu_ExportImagesSelected(app, event)
            data = app.kernel.Images;
            info = app.kernel.ImagesInfo;

            mode_ = app.user_mode;

            app.imwriter = EBImageWriter(data, info);

            fmt = "IMAGE";
            slt = uiconfirm(app.EasyBehaviourV125UIFigure, "保存至图片集（或图片文件夹）？", ...
                "选择保存格式", "Icon","question", "Options", {'Yes','Otherwise'}, ...
                "DefaultOption", 'Yes');
            if isequal(slt, "Yes"), fmt = "IMAGE_STACK"; end

            % cover running mode
            app.user_mode = EBStatus.MODE_WRITTING;
            app.imwriter.write(fmt);
            % recover mode
            pause(app.DAEMON_PERIOD);        % avoid calling deleted object, fully refresh
            app.user_mode = mode_;

            delete(app.imwriter);
        end

        % Menu selected function: Menu_NewExperiment
        function Menu_NewExperimentSelected(app, event)
            slt = uiconfirm(app.EasyBehaviourV125UIFigure, ...
                "新实验将覆盖历史数据，确认开始？", ...
                "系统消息", "Icon","warning");
            
            if slt == "OK"
                CreateNewExperiment(app);
            end
        end

        % Menu selected function: Menu_ExportMovie
        function Menu_ExportMovieSelected(app, event)
            % update export options
            devices = app.kernel.Devices;
            app.export_opt.OutputWidth = devices{"camera"}.ROIWidth;
            app.export_opt.OutputHeight = devices{"camera"}.ROIHeight;
            
            % get movie writer options, waitfor 
            uiwait(MovieExporter(app));

            if app.export_opt.ExportStatus
                switch app.export_opt.OutputMarker
                    case "none"
                        markers = EBVideoMarker.none();
                    case "current"
                        markers = app.VV_handle.WithMarkers;
                    case "full"
                        markers = EBVideoMarker.full();
                    otherwise
                end
                
                mode_ = app.user_mode;

                switch app.export_opt.Source
                    case "camera"
                        app.mvwriter = EBVideoWriter(app.kernel.Images, app.export_opt, markers);
                    case "processed"
                        app.mvwriter = EBVideoWriter(app.kernel.Video, app.export_opt, markers);
                end

                % cover running mode
                app.user_mode = EBStatus.MODE_WRITTING;
                app.mvwriter.write();

                % recover mode
                pause(app.DAEMON_PERIOD);        % avoid calling deleted object, fully refresh
                app.user_mode = mode_;

                delete(app.mvwriter);
            else
                % user canceled, omit
            end
        end

        % Menu selected function: Menu_ParadigmFeatures
        function Menu_ParadigmFeaturesSelected(app, event)
            % read from kernel and export table as .xls file
            pf = app.kernel.ParadigmFeatures;

            if ~isempty(pf)
                [file, path] = uiputfile({'*.xls', 'EXCEL 97-2003 工作簿(*.xls)'}, ...
                    "导出实验范式(Meta Data for MAGAT)", "profile.xls");
                writetable(pf, fullfile(path, file), "WriteVariableNames",true, ...
                    "FileType","text","WriteMode","overwrite","Delimiter","\t");

                fprintf("%s 保存成功\n", fullfile(path, file));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.EasyBehaviourV125UIFigure = uifigure('Visible', 'off');
            app.EasyBehaviourV125UIFigure.Name = 'Easy Behaviour V1.2.5';
            app.EasyBehaviourV125UIFigure.Icon = fullfile(pathToMLAPP, 'sources', 'behaviour.png');
            app.EasyBehaviourV125UIFigure.CloseRequestFcn = createCallbackFcn(app, @EasyBehaviourV125UIFigureCloseRequest, true);
            app.EasyBehaviourV125UIFigure.SizeChangedFcn = createCallbackFcn(app, @EasyBehaviourV125UIFigureSizeChanged, true);
            app.EasyBehaviourV125UIFigure.KeyPressFcn = createCallbackFcn(app, @EasyBehaviourV125UIFigureKeyPress, true);
            app.Menu_File = uimenu(app.EasyBehaviourV125UIFigure);
            app.Menu_File.Text = '文件';
            app.Menu_NewParadigm = uimenu(app.Menu_File);
            app.Menu_NewParadigm.MenuSelectedFcn = createCallbackFcn(app, @Menu_NewParadigmSelected, true);
            app.Menu_NewParadigm.Enable = 'off';
            app.Menu_NewParadigm.Text = '新范式';
            app.Menu_NewExperiment = uimenu(app.Menu_File);
            app.Menu_NewExperiment.MenuSelectedFcn = createCallbackFcn(app, @Menu_NewExperimentSelected, true);
            app.Menu_NewExperiment.Text = '新实验';
            app.Menu_ExportResults = uimenu(app.Menu_File);
            app.Menu_ExportResults.Enable = 'off';
            app.Menu_ExportResults.Separator = 'on';
            app.Menu_ExportResults.Text = '导出...';
            app.Menu_ExportImages = uimenu(app.Menu_ExportResults);
            app.Menu_ExportImages.MenuSelectedFcn = createCallbackFcn(app, @Menu_ExportImagesSelected, true);
            app.Menu_ExportImages.Text = '原始图像';
            app.Menu_ExportMovie = uimenu(app.Menu_ExportResults);
            app.Menu_ExportMovie.MenuSelectedFcn = createCallbackFcn(app, @Menu_ExportMovieSelected, true);
            app.Menu_ExportMovie.Text = '影片';
            app.Menu_ParadigmFeatures = uimenu(app.Menu_ExportResults);
            app.Menu_ParadigmFeatures.MenuSelectedFcn = createCallbackFcn(app, @Menu_ParadigmFeaturesSelected, true);
            app.Menu_ParadigmFeatures.Separator = 'on';
            app.Menu_ParadigmFeatures.Text = '范式特征(MAGAT)';
            app.Menu_ExportBehaviour = uimenu(app.Menu_ExportResults);
            app.Menu_ExportBehaviour.Text = '行为学参数';
            app.Menu_Quit = uimenu(app.Menu_File);
            app.Menu_Quit.MenuSelectedFcn = createCallbackFcn(app, @Menu_QuitSelected, true);
            app.Menu_Quit.Text = '退出';
            app.Menu_Image = uimenu(app.EasyBehaviourV125UIFigure);
            app.Menu_Image.Text = '图像';
            app.Menu_BrightnessAndContrast = uimenu(app.Menu_Image);
            app.Menu_BrightnessAndContrast.MenuSelectedFcn = createCallbackFcn(app, @Menu_BrightnessAndContrastSelected, true);
            app.Menu_BrightnessAndContrast.Enable = 'off';
            app.Menu_BrightnessAndContrast.Text = '亮度/对比度';
            app.Menu_Scale = uimenu(app.Menu_Image);
            app.Menu_Scale.MenuSelectedFcn = createCallbackFcn(app, @Menu_ScaleSelected, true);
            app.Menu_Scale.Text = '比例/尺';
            app.Menu_CropXY = uimenu(app.Menu_Image);
            app.Menu_CropXY.Separator = 'on';
            app.Menu_CropXY.Text = 'XY 裁剪';
            app.Menu_CropT = uimenu(app.Menu_Image);
            app.Menu_CropT.Text = 'T 裁剪';
            app.Menu_Configuration = uimenu(app.EasyBehaviourV125UIFigure);
            app.Menu_Configuration.Text = '配置';
            app.Menu_Peripherals = uimenu(app.Menu_Configuration);
            app.Menu_Peripherals.MenuSelectedFcn = createCallbackFcn(app, @Menu_PeripheralsSelected, true);
            app.Menu_Peripherals.Text = '外部设备';
            app.Menu_Preference = uimenu(app.Menu_Configuration);
            app.Menu_Preference.MenuSelectedFcn = createCallbackFcn(app, @Menu_PreferenceSelected, true);
            app.Menu_Preference.Text = '首选项';
            app.Menu_Tool = uimenu(app.EasyBehaviourV125UIFigure);
            app.Menu_Tool.Text = '工具';
            app.Menu_Tracker = uimenu(app.Menu_Tool);
            app.Menu_Tracker.MenuSelectedFcn = createCallbackFcn(app, @Menu_TrackerSelected, true);
            app.Menu_Tracker.Text = '追踪器';
            app.Menu_Parameterization = uimenu(app.Menu_Tool);
            app.Menu_Parameterization.MenuSelectedFcn = createCallbackFcn(app, @Menu_ParameterizationSelected, true);
            app.Menu_Parameterization.Text = '参数化';
            app.Menu_PopulationFeatures = uimenu(app.Menu_Tool);
            app.Menu_PopulationFeatures.Enable = 'off';
            app.Menu_PopulationFeatures.Text = '群体特征';
            app.Menu_Plugins = uimenu(app.EasyBehaviourV125UIFigure);
            app.Menu_Plugins.Enable = 'off';
            app.Menu_Plugins.Text = '插件';
            app.Menu_Help = uimenu(app.EasyBehaviourV125UIFigure);
            app.Menu_Help.Text = '帮助';
            app.Menu_Document = uimenu(app.Menu_Help);
            app.Menu_Document.MenuSelectedFcn = createCallbackFcn(app, @Menu_DocumentSelected, true);
            app.Menu_Document.Text = '文档';
            app.Menu_About = uimenu(app.Menu_Help);
            app.Menu_About.MenuSelectedFcn = createCallbackFcn(app, @Menu_AboutSelected, true);
            app.Menu_About.Text = '关于';
            app.GridLayout_Main = uigridlayout(app.EasyBehaviourV125UIFigure);
            app.GridLayout_Main.Tooltip = {'按下"回车"开始实验'};
            app.UIAxes_TaskViewer = uiaxes(app.GridLayout_Main);
            title(app.UIAxes_TaskViewer, '实验任务')
            xlabel(app.UIAxes_TaskViewer, 'Time (s)')
            zlabel(app.UIAxes_TaskViewer, 'Z')
            app.UIAxes_TaskViewer.YTick = [];
            app.UIAxes_TaskViewer.Visible = 'off';
            app.GridLayout_Progress = uigridlayout(app.GridLayout_Main);
            app.Label_ProgressLeft = uilabel(app.GridLayout_Progress);
            app.Label_ProgressLeft.Text = '';
            app.Label_Progress = uilabel(app.GridLayout_Progress);
            app.Label_Progress.Text = '';
            app.GridLayout2 = uigridlayout(app.GridLayout_Main);
            app.Label_4 = uilabel(app.GridLayout2);
            app.Label_4.Text = '调色板';
            app.DropDown_ColorPalette = uidropdown(app.GridLayout2);
            app.DropDown_ColorPalette.Items = {};
            app.DropDown_ColorPalette.Value = {};
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = '剩余时间';
            app.EditField_LeftTime = uieditfield(app.GridLayout2, 'text');
            app.NILabel = uilabel(app.GridLayout2);
            app.NILabel.Text = '设备';
            app.Lamp_Device = uilamp(app.GridLayout2);
            app.Label_3 = uilabel(app.GridLayout2);
            app.Label_3.Text = '内核';
            app.Lamp_Kernel = uilamp(app.GridLayout2);
            app.ContextMenu = uicontextmenu(app.EasyBehaviourV125UIFigure);
            app.Menu_SetROI = uimenu(app.ContextMenu);
            app.Menu_SetROI.Text = '设置ROI';
            app.Menu_ROI_Rectangle = uimenu(app.Menu_SetROI);
            app.Menu_ROI_Rectangle.Text = '矩形';
            app.Menu_ROI_Freehand = uimenu(app.Menu_SetROI);
            app.Menu_ROI_Freehand.Text = '自由选择';
            app.Menu_LabelReassignment = uimenu(app.ContextMenu);
            app.Menu_LabelReassignment.Text = '重分配标签';
            % Assign app.ContextMenu
            app.GridLayout_Main.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.EasyBehaviourV125UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EasyBehaviour(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EasyBehaviourV125UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EasyBehaviourV125UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EasyBehaviourV125UIFigure)
        end
    end
end