classdef Port < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                matlab.ui.container.GridLayout
        portlinevalvecameraPanel  matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        CheckBox_CameraLine8      matlab.ui.control.CheckBox
        CheckBox_CameraLine7      matlab.ui.control.CheckBox
        CheckBox_CameraLine6      matlab.ui.control.CheckBox
        CheckBox_CameraLine5      matlab.ui.control.CheckBox
        CheckBox_CameraLine4      matlab.ui.control.CheckBox
        CheckBox_CameraLine3      matlab.ui.control.CheckBox
        CheckBox_CameraLine2      matlab.ui.control.CheckBox
        CheckBox_CameraLine1      matlab.ui.control.CheckBox
        EditField_ValveLine8      matlab.ui.control.EditField
        Label_8                   matlab.ui.control.Label
        EditField_ValveLine7      matlab.ui.control.EditField
        Label_7                   matlab.ui.control.Label
        EditField_ValveLine6      matlab.ui.control.EditField
        Label_6                   matlab.ui.control.Label
        EditField_ValveLine5      matlab.ui.control.EditField
        Label_5                   matlab.ui.control.Label
        EditField_ValveLine4      matlab.ui.control.EditField
        Label_4                   matlab.ui.control.Label
        EditField_ValveLine3      matlab.ui.control.EditField
        Label_3                   matlab.ui.control.Label
        EditField_ValveLine2      matlab.ui.control.EditField
        Label_2                   matlab.ui.control.Label
        EditField_ValveLine1      matlab.ui.control.EditField
        Label                     matlab.ui.control.Label
    end

    properties (AbortSet, Access = public, GetObservable, SetObservable)
        port (1,1) double {mustBeNonnegative(port), mustBeInteger(port)} = 0; % port on device
        vlmap (8,1) string {mustBeText(vlmap)} = strings(8,1); % valve-line mapping vector
    end
    
    properties (Access = public)
        camap (8,1) logical = false(8,1); % camera line
        enable (1,1) logical = false;
    end
    
    
    methods (Access = private)
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EditField_ValveLine1
        function EditField_ValveLine1ValueChanged(comp, event)
            value = comp.EditField_ValveLine1.Value;
            comp.vlmap(1) = value;
            comp.CheckBox_CameraLine1.Enable = isequal(value, "");
        end

        % Value changed function: EditField_ValveLine2
        function EditField_ValveLine2ValueChanged(comp, event)
            value = comp.EditField_ValveLine2.Value;
            comp.vlmap(2) = value;
            comp.CheckBox_CameraLine2.Enable = isequal(value, "");
        end

        % Value changed function: EditField_ValveLine3
        function EditField_ValveLine3ValueChanged(comp, event)
            value = comp.EditField_ValveLine3.Value;
            comp.vlmap(3) = value;
            comp.CheckBox_CameraLine3.Enable = isequal(value, "");
        end

        % Value changed function: EditField_ValveLine4
        function EditField_ValveLine4ValueChanged(comp, event)
            value = comp.EditField_ValveLine4.Value;
            comp.vlmap(4) = value;
            comp.CheckBox_CameraLine4.Enable = isequal(value, "");
        end

        % Value changed function: EditField_ValveLine5
        function EditField_ValveLine5ValueChanged(comp, event)
            value = comp.EditField_ValveLine5.Value;
            comp.vlmap(5) = value;
            comp.CheckBox_CameraLine5.Enable = isequal(value, "");
        end

        % Value changed function: EditField_ValveLine6
        function EditField_ValveLine6ValueChanged(comp, event)
            value = comp.EditField_ValveLine6.Value;
            comp.vlmap(6) = value;
            comp.CheckBox_CameraLine6.Enable = isequal(value, "");
        end

        % Value changed function: EditField_ValveLine7
        function EditField_ValveLine7ValueChanged(comp, event)
            value = comp.EditField_ValveLine7.Value;
            comp.vlmap(7) = value;
            comp.CheckBox_CameraLine7.Enable = isequal(value, "");
        end

        % Value changed function: EditField_ValveLine8
        function EditField_ValveLine8ValueChanged(comp, event)
            value = comp.EditField_ValveLine8.Value;
            comp.vlmap(8) = value;
            comp.CheckBox_CameraLine8.Enable = isequal(value, "");
        end

        % Value changed function: CheckBox_CameraLine1
        function CheckBox_CameraLine1ValueChanged(comp, event)
            value = comp.CheckBox_CameraLine1.Value;
            comp.EditField_ValveLine1.Enable = ~value;
            comp.camap = false(8, 1); 
            comp.camap(1) = value;
        end

        % Value changed function: CheckBox_CameraLine2
        function CheckBox_CameraLine2ValueChanged(comp, event)
            value = comp.CheckBox_CameraLine2.Value;
            comp.EditField_ValveLine2.Enable = ~value;
            comp.camap = false(8, 1); 
            comp.camap(2) = value;
        end

        % Value changed function: CheckBox_CameraLine3
        function CheckBox_CameraLine3ValueChanged(comp, event)
            value = comp.CheckBox_CameraLine3.Value;
            comp.EditField_ValveLine3.Enable = ~value;
            comp.camap = false(8, 1); 
            comp.camap(3) = value;
        end

        % Value changed function: CheckBox_CameraLine4
        function CheckBox_CameraLine4ValueChanged(comp, event)
            value = comp.CheckBox_CameraLine4.Value;
            comp.EditField_ValveLine4.Enable = ~value;
            comp.camap = false(8, 1); 
            comp.camap(4) = value;
        end

        % Value changed function: CheckBox_CameraLine5
        function CheckBox_CameraLine5ValueChanged(comp, event)
            value = comp.CheckBox_CameraLine5.Value;
            comp.EditField_ValveLine5.Enable = ~value;
            comp.camap = false(8, 1); 
            comp.camap(5) = value;
        end

        % Value changed function: CheckBox_CameraLine6
        function CheckBox_CameraLine6ValueChanged(comp, event)
            value = comp.CheckBox_CameraLine6.Value;
            comp.EditField_ValveLine6.Enable = ~value;
            comp.camap = false(8, 1); 
            comp.camap(6) = value;
        end

        % Value changed function: CheckBox_CameraLine7
        function CheckBox_CameraLine7ValueChanged(comp, event)
            value = comp.CheckBox_CameraLine7.Value;
            comp.EditField_ValveLine7.Enable = ~value;
            comp.camap = false(8, 1); 
            comp.camap(7) = value;
        end

        % Value changed function: CheckBox_CameraLine8
        function CheckBox_CameraLine8ValueChanged(comp, event)
            value = comp.CheckBox_CameraLine8.Value;
            comp.EditField_ValveLine7.Enable = ~value;
            comp.camap = false(8, 1); 
            comp.camap(8) = value;
        end
    end

    methods (Access = protected)
        
        % 公共属性值发生更改时执行的代码
        function update(comp)
            % update value
            for k = 1:8
                % set status
                evstr = sprintf("comp.EditField_ValveLine%d.Enable = comp.enable; " + ...
                                "comp.CheckBox_CameraLine%d.Enable = comp.enable;", k, k);
                eval(evstr);

                % set the value
                evstr = sprintf("comp.EditField_ValveLine%d.Value = comp.vlmap(%d);" + ...
                                "comp.CheckBox_CameraLine%d.Enable = (comp.vlmap(%d)=="""");" + ...
                                "comp.CheckBox_CameraLine%d.Value = comp.camap(%d);", k,k, k,k, k,k);
                eval(evstr);
            end

            % update label
            comp.Label.Text = sprintf("%d.0", comp.port);
            for k = 2:8
                evstr = sprintf("comp.Label_%d.Text = ""%d.%d"";", k, comp.port,k-1);
                eval(evstr);
            end
        end

        % 创建底层组件
        function setup(comp)

            comp.Position = [1 1 140 290];
            comp.BackgroundColor = [0.94 0.94 0.94];

            % Create GridLayout
            comp.GridLayout = uigridlayout(comp);
            comp.GridLayout.ColumnWidth = {'1x'};
            comp.GridLayout.RowHeight = {'1x'};
            comp.GridLayout.Padding = [0 0 0 0];

            % Create portlinevalvecameraPanel
            comp.portlinevalvecameraPanel = uipanel(comp.GridLayout);
            comp.portlinevalvecameraPanel.TitlePosition = 'centertop';
            comp.portlinevalvecameraPanel.Title = 'port.line|valve|camera';
            comp.portlinevalvecameraPanel.Layout.Row = 1;
            comp.portlinevalvecameraPanel.Layout.Column = 1;
            comp.portlinevalvecameraPanel.FontWeight = 'bold';

            % Create GridLayout2
            comp.GridLayout2 = uigridlayout(comp.portlinevalvecameraPanel);
            comp.GridLayout2.ColumnWidth = {30, '1x', 22};
            comp.GridLayout2.RowHeight = {22, 22, 22, 22, 22, 22, 22, 22};

            % Create Label
            comp.Label = uilabel(comp.GridLayout2);
            comp.Label.HorizontalAlignment = 'right';
            comp.Label.FontSize = 14;
            comp.Label.FontWeight = 'bold';
            comp.Label.Layout.Row = 1;
            comp.Label.Layout.Column = 1;
            comp.Label.Text = '0.0';

            % Create EditField_ValveLine1
            comp.EditField_ValveLine1 = uieditfield(comp.GridLayout2, 'text');
            comp.EditField_ValveLine1.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @EditField_ValveLine1ValueChanged, true);
            comp.EditField_ValveLine1.Tag = '0';
            comp.EditField_ValveLine1.HorizontalAlignment = 'center';
            comp.EditField_ValveLine1.FontSize = 14;
            comp.EditField_ValveLine1.FontWeight = 'bold';
            comp.EditField_ValveLine1.Layout.Row = 1;
            comp.EditField_ValveLine1.Layout.Column = 2;

            % Create Label_2
            comp.Label_2 = uilabel(comp.GridLayout2);
            comp.Label_2.HorizontalAlignment = 'right';
            comp.Label_2.FontSize = 14;
            comp.Label_2.FontWeight = 'bold';
            comp.Label_2.Layout.Row = 2;
            comp.Label_2.Layout.Column = 1;
            comp.Label_2.Text = '0.1';

            % Create EditField_ValveLine2
            comp.EditField_ValveLine2 = uieditfield(comp.GridLayout2, 'text');
            comp.EditField_ValveLine2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @EditField_ValveLine2ValueChanged, true);
            comp.EditField_ValveLine2.Tag = '1';
            comp.EditField_ValveLine2.HorizontalAlignment = 'center';
            comp.EditField_ValveLine2.FontSize = 14;
            comp.EditField_ValveLine2.FontWeight = 'bold';
            comp.EditField_ValveLine2.Layout.Row = 2;
            comp.EditField_ValveLine2.Layout.Column = 2;

            % Create Label_3
            comp.Label_3 = uilabel(comp.GridLayout2);
            comp.Label_3.HorizontalAlignment = 'right';
            comp.Label_3.FontSize = 14;
            comp.Label_3.FontWeight = 'bold';
            comp.Label_3.Layout.Row = 3;
            comp.Label_3.Layout.Column = 1;
            comp.Label_3.Text = '0.2';

            % Create EditField_ValveLine3
            comp.EditField_ValveLine3 = uieditfield(comp.GridLayout2, 'text');
            comp.EditField_ValveLine3.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @EditField_ValveLine3ValueChanged, true);
            comp.EditField_ValveLine3.Tag = '2';
            comp.EditField_ValveLine3.HorizontalAlignment = 'center';
            comp.EditField_ValveLine3.FontSize = 14;
            comp.EditField_ValveLine3.FontWeight = 'bold';
            comp.EditField_ValveLine3.Layout.Row = 3;
            comp.EditField_ValveLine3.Layout.Column = 2;

            % Create Label_4
            comp.Label_4 = uilabel(comp.GridLayout2);
            comp.Label_4.HorizontalAlignment = 'right';
            comp.Label_4.FontSize = 14;
            comp.Label_4.FontWeight = 'bold';
            comp.Label_4.Layout.Row = 4;
            comp.Label_4.Layout.Column = 1;
            comp.Label_4.Text = '0.3';

            % Create EditField_ValveLine4
            comp.EditField_ValveLine4 = uieditfield(comp.GridLayout2, 'text');
            comp.EditField_ValveLine4.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @EditField_ValveLine4ValueChanged, true);
            comp.EditField_ValveLine4.Tag = '3';
            comp.EditField_ValveLine4.HorizontalAlignment = 'center';
            comp.EditField_ValveLine4.FontSize = 14;
            comp.EditField_ValveLine4.FontWeight = 'bold';
            comp.EditField_ValveLine4.Layout.Row = 4;
            comp.EditField_ValveLine4.Layout.Column = 2;

            % Create Label_5
            comp.Label_5 = uilabel(comp.GridLayout2);
            comp.Label_5.HorizontalAlignment = 'right';
            comp.Label_5.FontSize = 14;
            comp.Label_5.FontWeight = 'bold';
            comp.Label_5.Layout.Row = 5;
            comp.Label_5.Layout.Column = 1;
            comp.Label_5.Text = '0.4';

            % Create EditField_ValveLine5
            comp.EditField_ValveLine5 = uieditfield(comp.GridLayout2, 'text');
            comp.EditField_ValveLine5.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @EditField_ValveLine5ValueChanged, true);
            comp.EditField_ValveLine5.Tag = '4';
            comp.EditField_ValveLine5.HorizontalAlignment = 'center';
            comp.EditField_ValveLine5.FontSize = 14;
            comp.EditField_ValveLine5.FontWeight = 'bold';
            comp.EditField_ValveLine5.Layout.Row = 5;
            comp.EditField_ValveLine5.Layout.Column = 2;

            % Create Label_6
            comp.Label_6 = uilabel(comp.GridLayout2);
            comp.Label_6.HorizontalAlignment = 'right';
            comp.Label_6.FontSize = 14;
            comp.Label_6.FontWeight = 'bold';
            comp.Label_6.Layout.Row = 6;
            comp.Label_6.Layout.Column = 1;
            comp.Label_6.Text = '0.5';

            % Create EditField_ValveLine6
            comp.EditField_ValveLine6 = uieditfield(comp.GridLayout2, 'text');
            comp.EditField_ValveLine6.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @EditField_ValveLine6ValueChanged, true);
            comp.EditField_ValveLine6.Tag = '5';
            comp.EditField_ValveLine6.HorizontalAlignment = 'center';
            comp.EditField_ValveLine6.FontSize = 14;
            comp.EditField_ValveLine6.FontWeight = 'bold';
            comp.EditField_ValveLine6.Layout.Row = 6;
            comp.EditField_ValveLine6.Layout.Column = 2;

            % Create Label_7
            comp.Label_7 = uilabel(comp.GridLayout2);
            comp.Label_7.HorizontalAlignment = 'right';
            comp.Label_7.FontSize = 14;
            comp.Label_7.FontWeight = 'bold';
            comp.Label_7.Layout.Row = 7;
            comp.Label_7.Layout.Column = 1;
            comp.Label_7.Text = '0.6';

            % Create EditField_ValveLine7
            comp.EditField_ValveLine7 = uieditfield(comp.GridLayout2, 'text');
            comp.EditField_ValveLine7.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @EditField_ValveLine7ValueChanged, true);
            comp.EditField_ValveLine7.Tag = '6';
            comp.EditField_ValveLine7.HorizontalAlignment = 'center';
            comp.EditField_ValveLine7.FontSize = 14;
            comp.EditField_ValveLine7.FontWeight = 'bold';
            comp.EditField_ValveLine7.Layout.Row = 7;
            comp.EditField_ValveLine7.Layout.Column = 2;

            % Create Label_8
            comp.Label_8 = uilabel(comp.GridLayout2);
            comp.Label_8.HorizontalAlignment = 'right';
            comp.Label_8.FontSize = 14;
            comp.Label_8.FontWeight = 'bold';
            comp.Label_8.Layout.Row = 8;
            comp.Label_8.Layout.Column = 1;
            comp.Label_8.Text = '0.7';

            % Create EditField_ValveLine8
            comp.EditField_ValveLine8 = uieditfield(comp.GridLayout2, 'text');
            comp.EditField_ValveLine8.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @EditField_ValveLine8ValueChanged, true);
            comp.EditField_ValveLine8.Tag = '7';
            comp.EditField_ValveLine8.HorizontalAlignment = 'center';
            comp.EditField_ValveLine8.FontSize = 14;
            comp.EditField_ValveLine8.FontWeight = 'bold';
            comp.EditField_ValveLine8.Layout.Row = 8;
            comp.EditField_ValveLine8.Layout.Column = 2;

            % Create CheckBox_CameraLine1
            comp.CheckBox_CameraLine1 = uicheckbox(comp.GridLayout2);
            comp.CheckBox_CameraLine1.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @CheckBox_CameraLine1ValueChanged, true);
            comp.CheckBox_CameraLine1.Text = '';
            comp.CheckBox_CameraLine1.Layout.Row = 1;
            comp.CheckBox_CameraLine1.Layout.Column = 3;

            % Create CheckBox_CameraLine2
            comp.CheckBox_CameraLine2 = uicheckbox(comp.GridLayout2);
            comp.CheckBox_CameraLine2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @CheckBox_CameraLine2ValueChanged, true);
            comp.CheckBox_CameraLine2.Text = '';
            comp.CheckBox_CameraLine2.Layout.Row = 2;
            comp.CheckBox_CameraLine2.Layout.Column = 3;

            % Create CheckBox_CameraLine3
            comp.CheckBox_CameraLine3 = uicheckbox(comp.GridLayout2);
            comp.CheckBox_CameraLine3.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @CheckBox_CameraLine3ValueChanged, true);
            comp.CheckBox_CameraLine3.Text = '';
            comp.CheckBox_CameraLine3.Layout.Row = 3;
            comp.CheckBox_CameraLine3.Layout.Column = 3;

            % Create CheckBox_CameraLine4
            comp.CheckBox_CameraLine4 = uicheckbox(comp.GridLayout2);
            comp.CheckBox_CameraLine4.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @CheckBox_CameraLine4ValueChanged, true);
            comp.CheckBox_CameraLine4.Text = '';
            comp.CheckBox_CameraLine4.Layout.Row = 4;
            comp.CheckBox_CameraLine4.Layout.Column = 3;

            % Create CheckBox_CameraLine5
            comp.CheckBox_CameraLine5 = uicheckbox(comp.GridLayout2);
            comp.CheckBox_CameraLine5.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @CheckBox_CameraLine5ValueChanged, true);
            comp.CheckBox_CameraLine5.Text = '';
            comp.CheckBox_CameraLine5.Layout.Row = 5;
            comp.CheckBox_CameraLine5.Layout.Column = 3;

            % Create CheckBox_CameraLine6
            comp.CheckBox_CameraLine6 = uicheckbox(comp.GridLayout2);
            comp.CheckBox_CameraLine6.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @CheckBox_CameraLine6ValueChanged, true);
            comp.CheckBox_CameraLine6.Text = '';
            comp.CheckBox_CameraLine6.Layout.Row = 6;
            comp.CheckBox_CameraLine6.Layout.Column = 3;

            % Create CheckBox_CameraLine7
            comp.CheckBox_CameraLine7 = uicheckbox(comp.GridLayout2);
            comp.CheckBox_CameraLine7.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @CheckBox_CameraLine7ValueChanged, true);
            comp.CheckBox_CameraLine7.Text = '';
            comp.CheckBox_CameraLine7.Layout.Row = 7;
            comp.CheckBox_CameraLine7.Layout.Column = 3;

            % Create CheckBox_CameraLine8
            comp.CheckBox_CameraLine8 = uicheckbox(comp.GridLayout2);
            comp.CheckBox_CameraLine8.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @CheckBox_CameraLine8ValueChanged, true);
            comp.CheckBox_CameraLine8.Text = '';
            comp.CheckBox_CameraLine8.Layout.Row = 8;
            comp.CheckBox_CameraLine8.Layout.Column = 3;
        end
    end
end