classdef Valves < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout   matlab.ui.container.GridLayout
        APanel       matlab.ui.container.Panel
        GridLayout2  matlab.ui.container.GridLayout
        GridLayout4  matlab.ui.container.GridLayout
        Button_8     matlab.ui.control.Button
        Button_7     matlab.ui.control.Button
        Button_6     matlab.ui.control.Button
        Button_5     matlab.ui.control.Button
        Button_4     matlab.ui.control.Button
        Button_3     matlab.ui.control.Button
        Button_2     matlab.ui.control.Button
        Button_1     matlab.ui.control.Button
        GridLayout3  matlab.ui.container.GridLayout
        Lamp_8       matlab.ui.control.Lamp
        Lamp_7       matlab.ui.control.Lamp
        Lamp_6       matlab.ui.control.Lamp
        Lamp_5       matlab.ui.control.Lamp
        Lamp_4       matlab.ui.control.Lamp
        Lamp_3       matlab.ui.control.Lamp
        Lamp_2       matlab.ui.control.Lamp
        Lamp_1       matlab.ui.control.Lamp
    end

    properties (AbortSet, Access = public, GetObservable, SetObservable)
        Enable (1,8) logical = true(1,8);
    end
    
    properties (Access = private)
        command (1,8) logical = false(1,8);
        port (1,1) string {mustBeTextScalar(port)} = "A";
    end
    
    properties (GetAccess=private, SetAccess = immutable)
        caller  (1,1)           % must be ManualController object handle
    end

    methods (Access = ?ManualController)
        function setCaller(comp, caller_)
            comp.caller = caller_;
        end

        function setPort(comp, port_)
            comp.port = port_;
            
            comp.APanel.Title = sprintf("控制面板(%s)", comp.port);
            comp.Button_1.Text = comp.port+"1";
            comp.Button_2.Text = comp.port+"2";
            comp.Button_3.Text = comp.port+"3";
            comp.Button_4.Text = comp.port+"4";
            comp.Button_5.Text = comp.port+"5";
            comp.Button_6.Text = comp.port+"6";
            comp.Button_7.Text = comp.port+"7";
            comp.Button_8.Text = comp.port+"8";
        end
    end
    
    methods (Access = private)
        function valves_write(comp)
            comp.caller.send_command(comp.port, comp.command);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            
        end

        % Button pushed function: Button_1
        function Button_1Pushed(comp, event)
            comp.command(1) = ~comp.command(1);

            if comp.command(1)
                comp.Lamp_1.Color = [0.00, 1.00, 0.00];
            else
                comp.Lamp_1.Color = [1.00, 0.00, 0.00];
            end

            comp.valves_write();
        end

        % Button pushed function: Button_2
        function Button_2Pushed(comp, event)
            comp.command(2) = ~comp.command(2);

            if comp.command(2)
                comp.Lamp_2.Color = [0.00, 1.00, 0.00];
            else
                comp.Lamp_2.Color = [1.00, 0.00, 0.00];
            end

            comp.valves_write();
        end

        % Button pushed function: Button_3
        function Button_3Pushed(comp, event)
            comp.command(3) = ~comp.command(3);

            if comp.command(3)
                comp.Lamp_3.Color = [0.00, 1.00, 0.00];
            else
                comp.Lamp_3.Color = [1.00, 0.00, 0.00];
            end

            comp.valves_write();
        end

        % Button pushed function: Button_4
        function Button_4Pushed(comp, event)
            comp.command(4) = ~comp.command(4);

            if comp.command(4)
                comp.Lamp_4.Color = [0.00, 1.00, 0.00];
            else
                comp.Lamp_4.Color = [1.00, 0.00, 0.00];
            end

            comp.valves_write();
        end

        % Button pushed function: Button_5
        function Button_5Pushed(comp, event)
            comp.command(5) = ~comp.command(5);

            if comp.command(5)
                comp.Lamp_5.Color = [0.00, 1.00, 0.00];
            else
                comp.Lamp_5.Color = [1.00, 0.00, 0.00];
            end

            comp.valves_write();
        end

        % Button pushed function: Button_6
        function Button_6Pushed(comp, event)
            comp.command(6) = ~comp.command(6);

            if comp.command(6)
                comp.Lamp_6.Color = [0.00, 1.00, 0.00];
            else
                comp.Lamp_6.Color = [1.00, 0.00, 0.00];
            end

            comp.valves_write();
        end

        % Button pushed function: Button_7
        function Button_7Pushed(comp, event)
            comp.command(7) = ~comp.command(7);

            if comp.command(7)
                comp.Lamp_7.Color = [0.00, 1.00, 0.00];
            else
                comp.Lamp_7.Color = [1.00, 0.00, 0.00];
            end

            comp.valves_write();
        end

        % Button pushed function: Button_8
        function Button_8Pushed(comp, event)
            comp.command(8) = ~comp.command(8);

            if comp.command(8)
                comp.Lamp_8.Color = [0.00, 1.00, 0.00];
            else
                comp.Lamp_8.Color = [1.00, 0.00, 0.00];
            end

            comp.valves_write();
        end
    end

    methods (Access = protected)
        
        % 公共属性值发生更改时执行的代码
        function update(comp)
            % 使用此函数更新底层组件
            for n = 1:numel(comp.Enable)
                eval(sprintf("comp.Button_%d.Enable = comp.Enable(%d); " + ...
                    "comp.Lamp_%d.Enable = comp.Enable(%d);", ...
                    n, n, n, n));
            end
        end

        % 创建底层组件
        function setup(comp)

            comp.Position = [1 1 480 120];
            comp.BackgroundColor = [0.94 0.94 0.94];

            % Create GridLayout
            comp.GridLayout = uigridlayout(comp);
            comp.GridLayout.ColumnWidth = {'1x'};
            comp.GridLayout.RowHeight = {'1x'};
            comp.GridLayout.Padding = [0 0 0 0];

            % Create APanel
            comp.APanel = uipanel(comp.GridLayout);
            comp.APanel.TitlePosition = 'centertop';
            comp.APanel.Title = '控制面板(A)';
            comp.APanel.BackgroundColor = [0.902 0.902 0.902];
            comp.APanel.Layout.Row = 1;
            comp.APanel.Layout.Column = 1;
            comp.APanel.FontSize = 14;

            % Create GridLayout2
            comp.GridLayout2 = uigridlayout(comp.APanel);
            comp.GridLayout2.ColumnWidth = {'1x'};
            comp.GridLayout2.RowSpacing = 5;
            comp.GridLayout2.BackgroundColor = [0.902 0.902 0.902];

            % Create GridLayout3
            comp.GridLayout3 = uigridlayout(comp.GridLayout2);
            comp.GridLayout3.ColumnWidth = {'1x', '1x', '1x', '1x', '1x', '1x', '1x', '1x'};
            comp.GridLayout3.RowHeight = {'1x'};
            comp.GridLayout3.ColumnSpacing = 30;
            comp.GridLayout3.Padding = [15 5 15 5];
            comp.GridLayout3.Layout.Row = 1;
            comp.GridLayout3.Layout.Column = 1;
            comp.GridLayout3.BackgroundColor = [0.8 0.8 0.8];

            % Create Lamp_1
            comp.Lamp_1 = uilamp(comp.GridLayout3);
            comp.Lamp_1.Layout.Row = 1;
            comp.Lamp_1.Layout.Column = 1;
            comp.Lamp_1.Color = [1 0 0];

            % Create Lamp_2
            comp.Lamp_2 = uilamp(comp.GridLayout3);
            comp.Lamp_2.Layout.Row = 1;
            comp.Lamp_2.Layout.Column = 2;
            comp.Lamp_2.Color = [1 0 0];

            % Create Lamp_3
            comp.Lamp_3 = uilamp(comp.GridLayout3);
            comp.Lamp_3.Layout.Row = 1;
            comp.Lamp_3.Layout.Column = 3;
            comp.Lamp_3.Color = [1 0 0];

            % Create Lamp_4
            comp.Lamp_4 = uilamp(comp.GridLayout3);
            comp.Lamp_4.Layout.Row = 1;
            comp.Lamp_4.Layout.Column = 4;
            comp.Lamp_4.Color = [1 0 0];

            % Create Lamp_5
            comp.Lamp_5 = uilamp(comp.GridLayout3);
            comp.Lamp_5.Layout.Row = 1;
            comp.Lamp_5.Layout.Column = 5;
            comp.Lamp_5.Color = [1 0 0];

            % Create Lamp_6
            comp.Lamp_6 = uilamp(comp.GridLayout3);
            comp.Lamp_6.Layout.Row = 1;
            comp.Lamp_6.Layout.Column = 6;
            comp.Lamp_6.Color = [1 0 0];

            % Create Lamp_7
            comp.Lamp_7 = uilamp(comp.GridLayout3);
            comp.Lamp_7.Layout.Row = 1;
            comp.Lamp_7.Layout.Column = 7;
            comp.Lamp_7.Color = [1 0 0];

            % Create Lamp_8
            comp.Lamp_8 = uilamp(comp.GridLayout3);
            comp.Lamp_8.Layout.Row = 1;
            comp.Lamp_8.Layout.Column = 8;
            comp.Lamp_8.Color = [1 0 0];

            % Create GridLayout4
            comp.GridLayout4 = uigridlayout(comp.GridLayout2);
            comp.GridLayout4.ColumnWidth = {'1x', '1x', '1x', '1x', '1x', '1x', '1x', '1x'};
            comp.GridLayout4.RowHeight = {'1x'};
            comp.GridLayout4.ColumnSpacing = 25;
            comp.GridLayout4.Padding = [10 5 10 5];
            comp.GridLayout4.Layout.Row = 2;
            comp.GridLayout4.Layout.Column = 1;
            comp.GridLayout4.BackgroundColor = [0.8 0.8 0.8];

            % Create Button_1
            comp.Button_1 = uibutton(comp.GridLayout4, 'push');
            comp.Button_1.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_1Pushed, true);
            comp.Button_1.IconAlignment = 'center';
            comp.Button_1.VerticalAlignment = 'bottom';
            comp.Button_1.BackgroundColor = [0.302 0.302 0.302];
            comp.Button_1.FontSize = 14;
            comp.Button_1.FontWeight = 'bold';
            comp.Button_1.FontColor = [0.9412 0.9412 0.9412];
            comp.Button_1.Layout.Row = 1;
            comp.Button_1.Layout.Column = 1;
            comp.Button_1.Text = 'A1';

            % Create Button_2
            comp.Button_2 = uibutton(comp.GridLayout4, 'push');
            comp.Button_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_2Pushed, true);
            comp.Button_2.IconAlignment = 'center';
            comp.Button_2.VerticalAlignment = 'bottom';
            comp.Button_2.BackgroundColor = [0.302 0.302 0.302];
            comp.Button_2.FontSize = 14;
            comp.Button_2.FontWeight = 'bold';
            comp.Button_2.FontColor = [0.9412 0.9412 0.9412];
            comp.Button_2.Layout.Row = 1;
            comp.Button_2.Layout.Column = 2;
            comp.Button_2.Text = 'A2';

            % Create Button_3
            comp.Button_3 = uibutton(comp.GridLayout4, 'push');
            comp.Button_3.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_3Pushed, true);
            comp.Button_3.IconAlignment = 'center';
            comp.Button_3.VerticalAlignment = 'bottom';
            comp.Button_3.BackgroundColor = [0.302 0.302 0.302];
            comp.Button_3.FontSize = 14;
            comp.Button_3.FontWeight = 'bold';
            comp.Button_3.FontColor = [0.9412 0.9412 0.9412];
            comp.Button_3.Layout.Row = 1;
            comp.Button_3.Layout.Column = 3;
            comp.Button_3.Text = 'A3';

            % Create Button_4
            comp.Button_4 = uibutton(comp.GridLayout4, 'push');
            comp.Button_4.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_4Pushed, true);
            comp.Button_4.IconAlignment = 'center';
            comp.Button_4.VerticalAlignment = 'bottom';
            comp.Button_4.BackgroundColor = [0.302 0.302 0.302];
            comp.Button_4.FontSize = 14;
            comp.Button_4.FontWeight = 'bold';
            comp.Button_4.FontColor = [0.9412 0.9412 0.9412];
            comp.Button_4.Layout.Row = 1;
            comp.Button_4.Layout.Column = 4;
            comp.Button_4.Text = 'A4';

            % Create Button_5
            comp.Button_5 = uibutton(comp.GridLayout4, 'push');
            comp.Button_5.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_5Pushed, true);
            comp.Button_5.IconAlignment = 'center';
            comp.Button_5.VerticalAlignment = 'bottom';
            comp.Button_5.BackgroundColor = [0.302 0.302 0.302];
            comp.Button_5.FontSize = 14;
            comp.Button_5.FontWeight = 'bold';
            comp.Button_5.FontColor = [0.9412 0.9412 0.9412];
            comp.Button_5.Layout.Row = 1;
            comp.Button_5.Layout.Column = 5;
            comp.Button_5.Text = 'A5';

            % Create Button_6
            comp.Button_6 = uibutton(comp.GridLayout4, 'push');
            comp.Button_6.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_6Pushed, true);
            comp.Button_6.IconAlignment = 'center';
            comp.Button_6.VerticalAlignment = 'bottom';
            comp.Button_6.BackgroundColor = [0.302 0.302 0.302];
            comp.Button_6.FontSize = 14;
            comp.Button_6.FontWeight = 'bold';
            comp.Button_6.FontColor = [0.9412 0.9412 0.9412];
            comp.Button_6.Layout.Row = 1;
            comp.Button_6.Layout.Column = 6;
            comp.Button_6.Text = 'A6';

            % Create Button_7
            comp.Button_7 = uibutton(comp.GridLayout4, 'push');
            comp.Button_7.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_7Pushed, true);
            comp.Button_7.IconAlignment = 'center';
            comp.Button_7.VerticalAlignment = 'bottom';
            comp.Button_7.BackgroundColor = [0.302 0.302 0.302];
            comp.Button_7.FontSize = 14;
            comp.Button_7.FontWeight = 'bold';
            comp.Button_7.FontColor = [0.9412 0.9412 0.9412];
            comp.Button_7.Layout.Row = 1;
            comp.Button_7.Layout.Column = 7;
            comp.Button_7.Text = 'A7';

            % Create Button_8
            comp.Button_8 = uibutton(comp.GridLayout4, 'push');
            comp.Button_8.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_8Pushed, true);
            comp.Button_8.IconAlignment = 'center';
            comp.Button_8.VerticalAlignment = 'bottom';
            comp.Button_8.BackgroundColor = [0.302 0.302 0.302];
            comp.Button_8.FontSize = 14;
            comp.Button_8.FontWeight = 'bold';
            comp.Button_8.FontColor = [0.9412 0.9412 0.9412];
            comp.Button_8.Layout.Row = 1;
            comp.Button_8.Layout.Column = 8;
            comp.Button_8.Text = 'A8';
            
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end