classdef VideoPlayer < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout           matlab.ui.container.GridLayout
        GridLayout2          matlab.ui.container.GridLayout
        GridLayout4          matlab.ui.container.GridLayout
        Button_Setting       matlab.ui.control.Button
        Button_SlowDown      matlab.ui.control.Button
        Button_Play          matlab.ui.control.Button
        GridLayout3          matlab.ui.container.GridLayout
        Button_CurrentFrame  matlab.ui.control.Button
        Button_SpeedUp       matlab.ui.control.Button
        Slider_Time          matlab.ui.control.Slider
        UIAxes_Viewer        matlab.ui.control.UIAxes
    end

    properties (AbortSet, Access = private, GetObservable, SetObservable)
        withDetectBox (1,1) logical = false;
        withMetadata (1,1) logical = true;
        withPlaySpeed (1,1) logical = true;
        withScaleBar (1,1) logical = false;
        withTimeStamp (1,1) logical = true;
        withTrajectory (1,1) logical = false;
    end
    
    properties (AbortSet, Access = public, GetObservable, SetObservable)
        Enable (1,1) logical = true;
        ScreenSize (1,2) double {mustBePositive(ScreenSize), mustBeInteger(ScreenSize)} = [512, 272];
    end
    

    properties (Access = private)
        kernel  (1,1)   EBKernel  % EBKernel object
        pconfig (1,1)             % PlayerConfiguration object
        player  (1,1)   timer     % timer for data acqusition from kernel
        hImage  (1,1)             % handle of image
    end

    methods
        function delete(comp)
            stop(comp.player);
            
            delete(comp.player);
        end
    end

    methods (Access = ?PlayerConfiguration)

        function SetTimeStampState(comp, state)
            arguments
                comp
                state   (1,1)   logical 
            end
            comp.withTimeStamp = state;
        end

        function SetScaleBarState(comp, state)
            arguments
                comp
                state   (1,1)   logical
            end
            comp.withScaleBar = state;
        end

        function SetPlayerSpeedState(comp, state)
            arguments
                comp
                state   (1,1)   logical
            end
            comp.withPlaySpeed = state;
        end

        function SetMetadataState(comp, state)
            arguments
                comp
                state   (1,1)   logical
            end
            comp.withMetadata = state;
        end

        function SetDetectBoxState(comp, state)
            arguments
                comp
                state   (1,1)   logical
            end
            comp.withDetectBox = state;
        end

        function SetTrajectoryState(comp, state)
            arguments
                comp
                state   (1,1)   logical
            end
            comp.withTrajectory = state;
        end
    end

    methods (Access = ?EasyBehaviour)
        function SetKernel(comp, ker)
            arguments
                comp
                ker     (1,1)   EBKernel
            end

            comp.kernel = ker;
        end
    end

    methods (Access = private)

        function UpdateScreen(comp, src, evt)
            % This function will be called by timer(player)
            % ask comp.kernel for useful data

            vf = comp.kernel.CurrentVideoFrame; % EBVideoFrame object, see also EBVideoFrame

            % selective drawing with tag (checked)


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            % init viewer
            comp.UIAxes_Viewer.Visible = "off";
            comp.UIAxes_Viewer.Toolbar = [];
            disableDefaultInteractivity(comp.UIAxes_Viewer);
            comp.UIAxes_Viewer.XLim = [0, comp.ScreenSize(1)];
            comp.UIAxes_Viewer.YLim = [0, comp.ScreenSize(2)];
            % adjust aspect ratio
            pbaspect(comp.UIAxes_Viewer, [comp.ScreenSize(1), ...
                                          comp.ScreenSize(2),...
                                          1]);

            % init timer
            comp.player = timer("BusyMode",         "drop",...
                                "ExecutionMode",    "fixedRate", ...
                                "Name",             "VideoPlayer_Agent", ...
                                "Period",           1, ...
                                "StartDelay",       0,...
                                "TasksToExecute",   inf, ...
                                "TimerFcn",         @comp.UpdateScreen);

        end

        % Button pushed function: Button_Setting
        function Button_SettingPushed(comp, event)
            if ~isobject(comp.pconfig) || ~isvalid(comp.pconfig)
                comp.pconfig = PlayerConfiguration(comp);
                comp.pconfig.GetOptions(comp.withTimeStamp, ...
                                        comp.withScaleBar, ...
                                        comp.withPlaySpeed, ...
                                        comp.withMetadata, ...
                                        comp.withDetectBox, ...
                                        comp.withTrajectory);
            end
        end

        % Button pushed function: Button_SlowDown
        function Button_SlowDownPushed(comp, event)
            
        end

        % Button pushed function: Button_Play
        function Button_PlayPushed(comp, event)
            
        end

        % Button pushed function: Button_SpeedUp
        function Button_SpeedUpPushed(comp, event)
            
        end

        % Button pushed function: Button_CurrentFrame
        function Button_CurrentFramePushed(comp, event)
            
        end

        % Value changing function: Slider_Time
        function Slider_TimeValueChanging(comp, event)
            changingValue = event.Value;
            
        end
    end

    methods (Access = protected)
        
        % 公共属性值发生更改时执行的代码
        function update(comp)
            % Enable / Disable
            comp.Button_SlowDown.Enable = comp.Enable;
            comp.Button_Play.Enable = comp.Enable;
            comp.Button_SpeedUp.Enable = comp.Enable;
            comp.Button_CurrentFrame.Enable = comp.Enable;
            comp.Slider_Time.Enable = comp.Enable;

            if ~comp.Enable, comp.Slider_Time.Limits = [0, 1]; end
            
            if comp.Enable
                comp.hImage = image(comp.UIAxes_Viewer, zeros(comp.ScreenSize([2,1])));
                colormap(comp.UIAxes_Viewer, "gray");
            else
                if isobject(comp.hImage), delete(comp.hImage); end
                comp.UIAxes_Viewer.Visible = "off";
            end

            % Switch timer
            if comp.Enable
                if ~isequal("on", comp.player.Running)
                    start(comp.player);
                end
            else
                stop(comp.player);
            end
        end

        % 创建底层组件
        function setup(comp)

            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));

            comp.Position = [1 1 532 392];
            comp.BackgroundColor = [0.94 0.94 0.94];

            % Create GridLayout
            comp.GridLayout = uigridlayout(comp);
            comp.GridLayout.ColumnWidth = {'1x'};
            comp.GridLayout.RowHeight = {'1x', 40, 40};

            % Create UIAxes_Viewer
            comp.UIAxes_Viewer = uiaxes(comp.GridLayout);
            title(comp.UIAxes_Viewer, '相机图像(实时)')
            comp.UIAxes_Viewer.Toolbar.Visible = 'off';
            comp.UIAxes_Viewer.XTick = [];
            comp.UIAxes_Viewer.YTick = [];
            comp.UIAxes_Viewer.FontSize = 14;
            comp.UIAxes_Viewer.Layout.Row = 1;
            comp.UIAxes_Viewer.Layout.Column = 1;

            % Create Slider_Time
            comp.Slider_Time = uislider(comp.GridLayout);
            comp.Slider_Time.Limits = [0 60];
            comp.Slider_Time.MajorTicks = [0 10 20 30 40 50 60];
            comp.Slider_Time.ValueChangingFcn = matlab.apps.createCallbackFcn(comp, @Slider_TimeValueChanging, true);
            comp.Slider_Time.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60];
            comp.Slider_Time.Layout.Row = 2;
            comp.Slider_Time.Layout.Column = 1;

            % Create GridLayout2
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.GridLayout2.ColumnWidth = {'1x', 50, '1x'};
            comp.GridLayout2.RowHeight = {'1x'};
            comp.GridLayout2.Padding = [0 0 0 0];
            comp.GridLayout2.Layout.Row = 3;
            comp.GridLayout2.Layout.Column = 1;

            % Create GridLayout3
            comp.GridLayout3 = uigridlayout(comp.GridLayout2);
            comp.GridLayout3.ColumnWidth = {50, '1x', 50};
            comp.GridLayout3.RowHeight = {'1x'};
            comp.GridLayout3.Padding = [0 0 0 0];
            comp.GridLayout3.Layout.Row = 1;
            comp.GridLayout3.Layout.Column = 3;

            % Create Button_SpeedUp
            comp.Button_SpeedUp = uibutton(comp.GridLayout3, 'push');
            comp.Button_SpeedUp.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_SpeedUpPushed, true);
            comp.Button_SpeedUp.Icon = fullfile(pathToMLAPP, 'sources', 'speed_up.png');
            comp.Button_SpeedUp.Layout.Row = 1;
            comp.Button_SpeedUp.Layout.Column = 1;
            comp.Button_SpeedUp.Text = '';

            % Create Button_CurrentFrame
            comp.Button_CurrentFrame = uibutton(comp.GridLayout3, 'push');
            comp.Button_CurrentFrame.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_CurrentFramePushed, true);
            comp.Button_CurrentFrame.Icon = fullfile(pathToMLAPP, 'sources', 'current.png');
            comp.Button_CurrentFrame.Layout.Row = 1;
            comp.Button_CurrentFrame.Layout.Column = 3;
            comp.Button_CurrentFrame.Text = '';

            % Create Button_Play
            comp.Button_Play = uibutton(comp.GridLayout2, 'push');
            comp.Button_Play.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_PlayPushed, true);
            comp.Button_Play.Icon = fullfile(pathToMLAPP, 'sources', 'play.png');
            comp.Button_Play.Layout.Row = 1;
            comp.Button_Play.Layout.Column = 2;
            comp.Button_Play.Text = '';

            % Create GridLayout4
            comp.GridLayout4 = uigridlayout(comp.GridLayout2);
            comp.GridLayout4.ColumnWidth = {50, '1x', 50};
            comp.GridLayout4.RowHeight = {'1x'};
            comp.GridLayout4.Padding = [0 0 0 0];
            comp.GridLayout4.Layout.Row = 1;
            comp.GridLayout4.Layout.Column = 1;

            % Create Button_SlowDown
            comp.Button_SlowDown = uibutton(comp.GridLayout4, 'push');
            comp.Button_SlowDown.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_SlowDownPushed, true);
            comp.Button_SlowDown.Icon = fullfile(pathToMLAPP, 'sources', 'slow_down.png');
            comp.Button_SlowDown.Layout.Row = 1;
            comp.Button_SlowDown.Layout.Column = 3;
            comp.Button_SlowDown.Text = '';

            % Create Button_Setting
            comp.Button_Setting = uibutton(comp.GridLayout4, 'push');
            comp.Button_Setting.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_SettingPushed, true);
            comp.Button_Setting.Icon = fullfile(pathToMLAPP, 'sources', 'setting.png');
            comp.Button_Setting.Layout.Row = 1;
            comp.Button_Setting.Layout.Column = 1;
            comp.Button_Setting.Text = '';
            
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end