classdef PlayerConfiguration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlayerConfigurationUIFigure  matlab.ui.Figure
        GridLayout2                  matlab.ui.container.GridLayout
        Panel                        matlab.ui.container.Panel
        GridLayout                   matlab.ui.container.GridLayout
        CheckBox_Trajectory          matlab.ui.control.CheckBox
        CheckBox_DetectBox           matlab.ui.control.CheckBox
        CheckBox_PlaySpeed           matlab.ui.control.CheckBox
        CheckBox_Metadata            matlab.ui.control.CheckBox
        CheckBox_ScaleBar            matlab.ui.control.CheckBox
        CheckBox_TimeStamp           matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        caller      % caller, EBVideoPlayer (comp) object
    end
    
    methods (Access=?EBVideoPlayer)
        function GetOptions(app, withTimeStamp, withScaleBar, withPlaySpeed, withMetadata, withDetectBox, withTrajectory)
            arguments
                app
                withTimeStamp   (1,1)   logical
                withScaleBar    (1,1)   logical
                withPlaySpeed   (1,1)   logical
                withMetadata    (1,1)   logical
                withDetectBox   (1,1)   logical
                withTrajectory  (1,1)   logical
            end

            app.CheckBox_TimeStamp.Value = withTimeStamp;
            app.CheckBox_ScaleBar.Value = withScaleBar;
            app.CheckBox_PlaySpeed.Value = withPlaySpeed;
            app.CheckBox_Metadata.Value = withMetadata;
            app.CheckBox_DetectBox.Value = withDetectBox;
            app.CheckBox_Trajectory.Value = withTrajectory;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            arguments
                app
                caller  (1,1)   EBVideoPlayer
            end

            app.caller = caller;
        end

        % Value changed function: CheckBox_TimeStamp
        function CheckBox_TimeStampValueChanged(app, event)
            value = app.CheckBox_TimeStamp.Value;

            app.caller.SetTimeStampState(value);
        end

        % Value changed function: CheckBox_ScaleBar
        function CheckBox_ScaleBarValueChanged(app, event)
            value = app.CheckBox_ScaleBar.Value;
            
            app.caller.SetScaleBarState(value);
        end

        % Value changed function: CheckBox_Metadata
        function CheckBox_MetadataValueChanged(app, event)
            value = app.CheckBox_Metadata.Value;
            
            app.caller.SetMetadataState(value);
        end

        % Value changed function: CheckBox_PlaySpeed
        function CheckBox_PlaySpeedValueChanged(app, event)
            value = app.CheckBox_PlaySpeed.Value;
            
            app.caller.SetPlayerSpeedState(value);
        end

        % Value changed function: CheckBox_DetectBox
        function CheckBox_DetectBoxValueChanged(app, event)
            value = app.CheckBox_DetectBox.Value;
            
            app.caller.SetDetectBoxState(value);
        end

        % Value changed function: CheckBox_Trajectory
        function CheckBox_TrajectoryValueChanged(app, event)
            value = app.CheckBox_Trajectory.Value;
            
            app.caller.SetTrajectoryState(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PlayerConfigurationUIFigure = uifigure('Visible', 'off');
            app.PlayerConfigurationUIFigure.Name = 'PlayerConfiguration';
            app.PlayerConfigurationUIFigure.Icon = 'setting.png';
            app.GridLayout2 = uigridlayout(app.PlayerConfigurationUIFigure);
            app.Panel = uipanel(app.GridLayout2);
            app.Panel.Title = '附加显示项';
            app.GridLayout = uigridlayout(app.Panel);
            app.CheckBox_TimeStamp = uicheckbox(app.GridLayout);
            app.CheckBox_TimeStamp.ValueChangedFcn = createCallbackFcn(app, @CheckBox_TimeStampValueChanged, true);
            app.CheckBox_TimeStamp.Text = '时间戳';
            app.CheckBox_ScaleBar = uicheckbox(app.GridLayout);
            app.CheckBox_ScaleBar.ValueChangedFcn = createCallbackFcn(app, @CheckBox_ScaleBarValueChanged, true);
            app.CheckBox_ScaleBar.Enable = 'off';
            app.CheckBox_ScaleBar.Text = '比例尺';
            app.CheckBox_Metadata = uicheckbox(app.GridLayout);
            app.CheckBox_Metadata.ValueChangedFcn = createCallbackFcn(app, @CheckBox_MetadataValueChanged, true);
            app.CheckBox_Metadata.Text = '元数据';
            app.CheckBox_PlaySpeed = uicheckbox(app.GridLayout);
            app.CheckBox_PlaySpeed.ValueChangedFcn = createCallbackFcn(app, @CheckBox_PlaySpeedValueChanged, true);
            app.CheckBox_PlaySpeed.Text = '播放速度';
            app.CheckBox_DetectBox = uicheckbox(app.GridLayout);
            app.CheckBox_DetectBox.ValueChangedFcn = createCallbackFcn(app, @CheckBox_DetectBoxValueChanged, true);
            app.CheckBox_DetectBox.Enable = 'off';
            app.CheckBox_DetectBox.Text = '检测框';
            app.CheckBox_Trajectory = uicheckbox(app.GridLayout);
            app.CheckBox_Trajectory.ValueChangedFcn = createCallbackFcn(app, @CheckBox_TrajectoryValueChanged, true);
            app.CheckBox_Trajectory.Enable = 'off';
            app.CheckBox_Trajectory.Text = '运动轨迹';
            % Show the figure after all components are created
            app.PlayerConfigurationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlayerConfiguration(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.PlayerConfigurationUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.PlayerConfigurationUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlayerConfigurationUIFigure)
        end
    end
end