classdef HWC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HardwareConfigurationUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        Tab_Camera                     matlab.ui.container.Tab
        GridLayout11                   matlab.ui.container.GridLayout
        GridLayout16                   matlab.ui.container.GridLayout
        Button_Preview                 matlab.ui.control.Button
        Switch_Connect                 matlab.ui.control.Switch
        GridLayout7                    matlab.ui.container.GridLayout
        Panel_ImageFormat              matlab.ui.container.Panel
        GridLayout9                    matlab.ui.container.GridLayout
        DropDown_BitDepth              matlab.ui.control.DropDown
        Label_4                        matlab.ui.control.Label
        EditField_Gamma                matlab.ui.control.NumericEditField
        Label_3                        matlab.ui.control.Label
        ROIEditField_Height            matlab.ui.control.NumericEditField
        ROIEditField_4Label            matlab.ui.control.Label
        EditField_Width                matlab.ui.control.NumericEditField
        ROIEditField_3Label            matlab.ui.control.Label
        EditField_OffsetY              matlab.ui.control.NumericEditField
        ROIEditField_2Label            matlab.ui.control.Label
        EditField_OffsetX              matlab.ui.control.NumericEditField
        ROIXLabel                      matlab.ui.control.Label
        Spinner_BinningVertical        matlab.ui.control.Spinner
        BinningSpinner_2Label          matlab.ui.control.Label
        Spinner_BinningHorizontal      matlab.ui.control.Spinner
        BinningSpinnerLabel            matlab.ui.control.Label
        Panel_DigitalIO                matlab.ui.container.Panel
        GridLayout17                   matlab.ui.container.GridLayout
        GridLayout20                   matlab.ui.container.GridLayout
        DropDown_LineMode              matlab.ui.control.DropDown
        Label_11                       matlab.ui.control.Label
        DropDown_LineSelector          matlab.ui.control.DropDown
        Label_10                       matlab.ui.control.Label
        GridLayout18                   matlab.ui.container.GridLayout
        DropDown_LineSource            matlab.ui.control.DropDown
        Label_9                        matlab.ui.control.Label
        CheckBox_LineInverter          matlab.ui.control.CheckBox
        Label_8                        matlab.ui.control.Label
        Panel_Capture                  matlab.ui.container.Panel
        GridLayout12                   matlab.ui.container.GridLayout
        GridLayout14                   matlab.ui.container.GridLayout
        EditField_MaxFrameRate         matlab.ui.control.NumericEditField
        Label_7                        matlab.ui.control.Label
        EditField_SensorReadoutTime    matlab.ui.control.NumericEditField
        Label_6                        matlab.ui.control.Label
        GridLayout13                   matlab.ui.container.GridLayout
        EditField_FrameRate            matlab.ui.control.NumericEditField
        FPSEditFieldLabel              matlab.ui.control.Label
        EditField_ExposureTime         matlab.ui.control.NumericEditField
        Label_5                        matlab.ui.control.Label
        Panel_ImageDevice              matlab.ui.container.Panel
        GridLayout19                   matlab.ui.container.GridLayout
        DropDown_DeviceID              matlab.ui.control.DropDown
        IDDropDownLabel                matlab.ui.control.Label
        DropDown_Adapter               matlab.ui.control.DropDown
        Label_2                        matlab.ui.control.Label
        Tab_Valve                      matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        GridLayout5                    matlab.ui.container.GridLayout
        GridLayout_MappingTable        matlab.ui.container.GridLayout
        Port2                          Port
        Port                           Port
        GridLayout6                    matlab.ui.container.GridLayout
        Button_WriteMappingTable       matlab.ui.control.Button
        Button_ReadMappingTable        matlab.ui.control.Button
        GridLayout4                    matlab.ui.container.GridLayout
        Button_LockMappingTable        matlab.ui.control.StateButton
        DropDown_IODevice              matlab.ui.control.DropDown
        IODropDownLabel                matlab.ui.control.Label
        GridLayout3                    matlab.ui.container.GridLayout
        Button_Load_Valve              matlab.ui.control.Button
        EditField_ExperimentConfig_Valve  matlab.ui.control.EditField
        Label                          matlab.ui.control.Label
        Tab_PressureController         matlab.ui.container.Tab
        Tab_FlowMeter                  matlab.ui.container.Tab
        Tab_EBPlus                     matlab.ui.container.Tab
    end

    properties (Constant, Hidden)
        DEVICES_TYPES = ["camera", "valves", "pressure_controller", "flowmeter", "ebplus"]
        DEVICES_SUPPORTED = ["EBCamera", "EBValves", "EBPressureController", "EBFlowMeter", "EBPlus"]
    end


    properties (Access = private)
        caller      % 1-by-1 EasyBehaviour object as parent

        devices     % 1-by-1 dictionary, key(TYPE) -> object
    end
    
    methods (Access = private)
        function InitData(app)
            camera = app.caller.GetCamera();
            valves = app.caller.GetValves();
            pressure_controller = [];
            flowmeter = [];
            ebplus = {};

            app.devices = dictionary(app.DEVICES_TYPES, ...
                {camera, valves, pressure_controller, flowmeter, ebplus});
        end

        function InitPanels(app)
            %
            InitCameraPanel(app);

            InitValvesPanel(app);
        end

        function InitCameraPanel(app)
            CameraPanelSwitch(app, "disconnect");  
        end

        function CameraPanelSwitch(app, state)   
            arguments
                app
                state   (1,1)   string  {mustBeMember(state, ...
                            ["disconnect", "connect", "live", "acquire"])}
            end

            switch state
                case "disconnect"
                    %% reset image device panel
                    app.Panel_ImageDevice.Title = "图像设备 (None)";
                    app.DropDown_Adapter.ItemsData = imaqhwinfo().InstalledAdaptors;
                    if ismember(camera.Adapter, string(app.DropDown_Adapter.ItemsData))
                        app.DropDown_Adapter.Value = app.devices("camera").Adapter;
                    else
                        throw(MException("HWC:unsupportedCameraAdapter", ...
                            sprintf("Camera Adapter: %s has not been installed.", ...
                            app.devices("camera").Adapter)));
                    end
                    app.DropDown_DeviceID.ItemsData = imaqhwinfo(app.devices("camera").Adapter).DeviceIDs; % default identity

                    %% reset image format panel
                    app.Spinner_BinningHorizontal.Enable = "off";
                    app.Spinner_BinningVertical.Enable = "off";
                    app.EditField_OffsetX.Enable = "off";
                    app.EditField_OffsetY.Enable = "off";
                    app.EditField_Width.Enable = "off";

                case "connect"

                case "live"

                case "acquire"

                otherwise
            end
        end

        function InitValvesPanel(app)

        end
    end
    
    methods (Access = public)
        
        function results = func2(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.caller = mainapp;

            InitData(app);

            InitPanels(app);
        end

        % Value changed function: EditField_ExperimentConfig_Valve
        function EditField_ExperimentConfig_ValveValueChanged(app, event)
            value = app.EditField_ExperimentConfig_Valve.Value;
            
        end

        % Button pushed function: Button_Load_Valve
        function Button_Load_ValvePushed(app, event)
            
        end

        % Value changed function: DropDown_IODevice
        function DropDown_IODeviceValueChanged(app, event)
            value = app.DropDown_IODevice.Value;
            
        end

        % Value changed function: Button_LockMappingTable
        function Button_LockMappingTableValueChanged(app, event)
            value = app.Button_LockMappingTable.Value;
            
        end

        % Button pushed function: Button_ReadMappingTable
        function Button_ReadMappingTablePushed(app, event)
            
        end

        % Button pushed function: Button_WriteMappingTable
        function Button_WriteMappingTablePushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HardwareConfigurationUIFigure = uifigure('Visible', 'off');
            app.HardwareConfigurationUIFigure.Name = 'Hardware Configuration';
            app.HardwareConfigurationUIFigure.Icon = 'hardware.png';
            app.GridLayout = uigridlayout(app.HardwareConfigurationUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Tab_Camera = uitab(app.TabGroup);
            app.Tab_Camera.Title = '相机';
            app.GridLayout11 = uigridlayout(app.Tab_Camera);
            app.GridLayout7 = uigridlayout(app.GridLayout11);
            app.Panel_ImageDevice = uipanel(app.GridLayout7);
            app.Panel_ImageDevice.Title = '图像设备 (None)';
            app.GridLayout19 = uigridlayout(app.Panel_ImageDevice);
            app.Label_2 = uilabel(app.GridLayout19);
            app.Label_2.Text = '适配器';
            app.DropDown_Adapter = uidropdown(app.GridLayout19);
            app.DropDown_Adapter.Items = {'gentl', 'winvideo'};
            app.DropDown_Adapter.Value = 'gentl';
            app.IDDropDownLabel = uilabel(app.GridLayout19);
            app.IDDropDownLabel.Text = '设备ID';
            app.DropDown_DeviceID = uidropdown(app.GridLayout19);
            app.DropDown_DeviceID.Items = {'1'};
            app.DropDown_DeviceID.Value = '1';
            app.Panel_Capture = uipanel(app.GridLayout7);
            app.Panel_Capture.Title = '捕获';
            app.GridLayout12 = uigridlayout(app.Panel_Capture);
            app.GridLayout13 = uigridlayout(app.GridLayout12);
            app.Label_5 = uilabel(app.GridLayout13);
            app.Label_5.Text = '曝光时间';
            app.EditField_ExposureTime = uieditfield(app.GridLayout13, 'numeric');
            app.EditField_ExposureTime.Limits = [100 1000000];
            app.EditField_ExposureTime.Tooltip = {'微秒'};
            app.EditField_ExposureTime.Value = 10000;
            app.FPSEditFieldLabel = uilabel(app.GridLayout13);
            app.FPSEditFieldLabel.Text = '帧率(FPS)';
            app.EditField_FrameRate = uieditfield(app.GridLayout13, 'numeric');
            app.EditField_FrameRate.Limits = [1 100];
            app.EditField_FrameRate.Value = 30;
            app.GridLayout14 = uigridlayout(app.GridLayout12);
            app.Label_6 = uilabel(app.GridLayout14);
            app.Label_6.Text = '读出时间';
            app.EditField_SensorReadoutTime = uieditfield(app.GridLayout14, 'numeric');
            app.EditField_SensorReadoutTime.Limits = [0 Inf];
            app.EditField_SensorReadoutTime.Editable = 'off';
            app.EditField_SensorReadoutTime.Tooltip = {'微秒'};
            app.EditField_SensorReadoutTime.Value = 8600;
            app.Label_7 = uilabel(app.GridLayout14);
            app.Label_7.Text = '最大帧率';
            app.EditField_MaxFrameRate = uieditfield(app.GridLayout14, 'numeric');
            app.EditField_MaxFrameRate.Limits = [0 Inf];
            app.EditField_MaxFrameRate.Editable = 'off';
            app.EditField_MaxFrameRate.Tooltip = {'理论上限'};
            app.EditField_MaxFrameRate.Value = 100;
            app.Panel_DigitalIO = uipanel(app.GridLayout7);
            app.Panel_DigitalIO.Title = '数字IO';
            app.GridLayout17 = uigridlayout(app.Panel_DigitalIO);
            app.GridLayout18 = uigridlayout(app.GridLayout17);
            app.Label_8 = uilabel(app.GridLayout18);
            app.Label_8.Text = '反转通道';
            app.CheckBox_LineInverter = uicheckbox(app.GridLayout18);
            app.CheckBox_LineInverter.Text = '';
            app.CheckBox_LineInverter.Value = true;
            app.Label_9 = uilabel(app.GridLayout18);
            app.Label_9.Text = '通道源';
            app.DropDown_LineSource = uidropdown(app.GridLayout18);
            app.DropDown_LineSource.Items = {'Exposure Active', 'Frame Trigger Wait', 'Frame Burst Trigger Wait'};
            app.DropDown_LineSource.Value = 'Exposure Active';
            app.GridLayout20 = uigridlayout(app.GridLayout17);
            app.Label_10 = uilabel(app.GridLayout20);
            app.Label_10.Text = '通道';
            app.DropDown_LineSelector = uidropdown(app.GridLayout20);
            app.DropDown_LineSelector.Items = {'Line1', 'Line2', 'Line3', 'Line4'};
            app.DropDown_LineSelector.Value = 'Line2';
            app.Label_11 = uilabel(app.GridLayout20);
            app.Label_11.Text = '模式';
            app.DropDown_LineMode = uidropdown(app.GridLayout20);
            app.DropDown_LineMode.Items = {'input', 'output'};
            app.DropDown_LineMode.Enable = 'off';
            app.DropDown_LineMode.Value = 'input';
            app.Panel_ImageFormat = uipanel(app.GridLayout7);
            app.Panel_ImageFormat.Title = '图像格式';
            app.GridLayout9 = uigridlayout(app.Panel_ImageFormat);
            app.BinningSpinnerLabel = uilabel(app.GridLayout9);
            app.BinningSpinnerLabel.Text = '水平Binning';
            app.Spinner_BinningHorizontal = uispinner(app.GridLayout9);
            app.Spinner_BinningHorizontal.Limits = [1 4];
            app.Spinner_BinningHorizontal.ValueDisplayFormat = '%.0f';
            app.Spinner_BinningHorizontal.Value = 1;
            app.BinningSpinner_2Label = uilabel(app.GridLayout9);
            app.BinningSpinner_2Label.Text = '竖直Binning';
            app.Spinner_BinningVertical = uispinner(app.GridLayout9);
            app.Spinner_BinningVertical.Limits = [1 4];
            app.Spinner_BinningVertical.ValueDisplayFormat = '%.0f';
            app.Spinner_BinningVertical.Value = 1;
            app.ROIXLabel = uilabel(app.GridLayout9);
            app.ROIXLabel.Text = 'ROI水平偏移';
            app.EditField_OffsetX = uieditfield(app.GridLayout9, 'numeric');
            app.EditField_OffsetX.Limits = [0 Inf];
            app.ROIEditField_2Label = uilabel(app.GridLayout9);
            app.ROIEditField_2Label.Text = 'ROI竖直偏移';
            app.EditField_OffsetY = uieditfield(app.GridLayout9, 'numeric');
            app.EditField_OffsetY.Limits = [0 Inf];
            app.ROIEditField_3Label = uilabel(app.GridLayout9);
            app.ROIEditField_3Label.Text = 'ROI宽度';
            app.EditField_Width = uieditfield(app.GridLayout9, 'numeric');
            app.EditField_Width.Limits = [1 Inf];
            app.EditField_Width.Value = 800;
            app.ROIEditField_4Label = uilabel(app.GridLayout9);
            app.ROIEditField_4Label.Text = 'ROI高度';
            app.ROIEditField_Height = uieditfield(app.GridLayout9, 'numeric');
            app.ROIEditField_Height.Limits = [1 Inf];
            app.ROIEditField_Height.Value = 600;
            app.Label_3 = uilabel(app.GridLayout9);
            app.Label_3.Text = '伽马';
            app.EditField_Gamma = uieditfield(app.GridLayout9, 'numeric');
            app.EditField_Gamma.Limits = [0.001 3.999];
            app.EditField_Gamma.ValueDisplayFormat = '%.3f';
            app.EditField_Gamma.Value = 1;
            app.Label_4 = uilabel(app.GridLayout9);
            app.Label_4.Text = '像素深度';
            app.DropDown_BitDepth = uidropdown(app.GridLayout9);
            app.DropDown_BitDepth.Items = {'8', '12'};
            app.DropDown_BitDepth.Value = '8';
            app.GridLayout16 = uigridlayout(app.GridLayout11);
            app.Switch_Connect = uiswitch(app.GridLayout16, 'slider');
            app.Button_Preview = uibutton(app.GridLayout16, 'push');
            app.Button_Preview.Icon = 'npreview.png';
            app.Button_Preview.Text = '预览';
            app.Tab_Valve = uitab(app.TabGroup);
            app.Tab_Valve.Title = '电磁阀';
            app.GridLayout2 = uigridlayout(app.Tab_Valve);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = '实验配置';
            app.EditField_ExperimentConfig_Valve = uieditfield(app.GridLayout3, 'text');
            app.EditField_ExperimentConfig_Valve.ValueChangedFcn = createCallbackFcn(app, @EditField_ExperimentConfig_ValveValueChanged, true);
            app.EditField_ExperimentConfig_Valve.Placeholder = '\expdef\valve.cfg';
            app.Button_Load_Valve = uibutton(app.GridLayout3, 'push');
            app.Button_Load_Valve.ButtonPushedFcn = createCallbackFcn(app, @Button_Load_ValvePushed, true);
            app.Button_Load_Valve.Icon = 'folder.png';
            app.Button_Load_Valve.Text = '';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.IODropDownLabel = uilabel(app.GridLayout4);
            app.IODropDownLabel.Text = '数字IO设备';
            app.DropDown_IODevice = uidropdown(app.GridLayout4);
            app.DropDown_IODevice.Items = {'Dev1', 'SimDev1'};
            app.DropDown_IODevice.ValueChangedFcn = createCallbackFcn(app, @DropDown_IODeviceValueChanged, true);
            app.DropDown_IODevice.Value = 'Dev1';
            app.Button_LockMappingTable = uibutton(app.GridLayout4, 'state');
            app.Button_LockMappingTable.ValueChangedFcn = createCallbackFcn(app, @Button_LockMappingTableValueChanged, true);
            app.Button_LockMappingTable.Icon = 'lock.png';
            app.Button_LockMappingTable.Text = '';
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.Button_ReadMappingTable = uibutton(app.GridLayout6, 'push');
            app.Button_ReadMappingTable.ButtonPushedFcn = createCallbackFcn(app, @Button_ReadMappingTablePushed, true);
            app.Button_ReadMappingTable.Text = '读取映射表';
            app.Button_WriteMappingTable = uibutton(app.GridLayout6, 'push');
            app.Button_WriteMappingTable.ButtonPushedFcn = createCallbackFcn(app, @Button_WriteMappingTablePushed, true);
            app.Button_WriteMappingTable.Text = '写入映射表';
            app.GridLayout_MappingTable = uigridlayout(app.GridLayout5);
            app.Port = Port(app.GridLayout_MappingTable);
            app.Port.vlmap = [1;2;3;4;0;0;0;0];
            app.Port2 = Port(app.GridLayout_MappingTable);
            app.Tab_PressureController = uitab(app.TabGroup);
            app.Tab_PressureController.Title = '压强控制器';
            app.Tab_FlowMeter = uitab(app.TabGroup);
            app.Tab_FlowMeter.Title = '流量计';
            app.Tab_EBPlus = uitab(app.TabGroup);
            app.Tab_EBPlus.Title = 'EB+';
            % Show the figure after all components are created
            app.HardwareConfigurationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HWC(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.HardwareConfigurationUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.HardwareConfigurationUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HardwareConfigurationUIFigure)
        end
    end
end