classdef HardwareConfiguration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HardwareConfigurationUIFigure   matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        Tab_DIOControl                  matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        GridLayout21                    matlab.ui.container.GridLayout
        DropDown_DAQVendor              matlab.ui.control.DropDown
        Label_12                        matlab.ui.control.Label
        GridLayout5                     matlab.ui.container.GridLayout
        GridLayout_MappingTable         matlab.ui.container.GridLayout
        GridLayout6                     matlab.ui.container.GridLayout
        Switch_DAQConnect               matlab.ui.control.Switch
        Button_DAQTest                  matlab.ui.control.Button
        Button_WriteMappingTable        matlab.ui.control.Button
        Button_ReadMappingTable         matlab.ui.control.Button
        GridLayout4                     matlab.ui.container.GridLayout
        Button_RefreshValveDevice       matlab.ui.control.Button
        DropDown_DAQDigitalDevice       matlab.ui.control.DropDown
        IODropDownLabel                 matlab.ui.control.Label
        GridLayout3                     matlab.ui.container.GridLayout
        Button_LoadValvesConfiguration  matlab.ui.control.Button
        EditField_ValvesConfigFilePath  matlab.ui.control.EditField
        Label                           matlab.ui.control.Label
        Tab_Camera                      matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        GridLayout16                    matlab.ui.container.GridLayout
        Button_CameraPreview            matlab.ui.control.Button
        Switch_CameraConnect            matlab.ui.control.Switch
        GridLayout7                     matlab.ui.container.GridLayout
        Panel_DigitalIO                 matlab.ui.container.Panel
        GridLayout17                    matlab.ui.container.GridLayout
        GridLayout20                    matlab.ui.container.GridLayout
        DropDown_LineMode               matlab.ui.control.DropDown
        Label_11                        matlab.ui.control.Label
        DropDown_LineSelector           matlab.ui.control.DropDown
        Label_10                        matlab.ui.control.Label
        GridLayout18                    matlab.ui.container.GridLayout
        DropDown_LineSource             matlab.ui.control.DropDown
        Label_9                         matlab.ui.control.Label
        CheckBox_LineInverter           matlab.ui.control.CheckBox
        Label_8                         matlab.ui.control.Label
        Panel_ImageFormat               matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        DropDown_BitDepth               matlab.ui.control.DropDown
        Label_4                         matlab.ui.control.Label
        EditField_Gamma                 matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        EditField_Height                matlab.ui.control.NumericEditField
        ROIEditField_4Label             matlab.ui.control.Label
        EditField_Width                 matlab.ui.control.NumericEditField
        ROIEditField_3Label             matlab.ui.control.Label
        EditField_OffsetY               matlab.ui.control.NumericEditField
        ROIEditField_2Label             matlab.ui.control.Label
        EditField_OffsetX               matlab.ui.control.NumericEditField
        ROIXLabel                       matlab.ui.control.Label
        Spinner_BinningVertical         matlab.ui.control.Spinner
        BinningSpinner_2Label           matlab.ui.control.Label
        Spinner_BinningHorizontal       matlab.ui.control.Spinner
        BinningSpinnerLabel             matlab.ui.control.Label
        Panel_Capture                   matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        GridLayout14                    matlab.ui.container.GridLayout
        EditField_MaxFrameRate          matlab.ui.control.NumericEditField
        Label_7                         matlab.ui.control.Label
        EditField_SensorReadoutTime     matlab.ui.control.NumericEditField
        Label_6                         matlab.ui.control.Label
        GridLayout13                    matlab.ui.container.GridLayout
        EditField_AcquireFrameRate      matlab.ui.control.NumericEditField
        FPSEditFieldLabel               matlab.ui.control.Label
        EditField_ExposureTime          matlab.ui.control.NumericEditField
        Label_5                         matlab.ui.control.Label
        Panel_ImageDevice               matlab.ui.container.Panel
        GridLayout19                    matlab.ui.container.GridLayout
        DropDown_CameraDeviceID         matlab.ui.control.DropDown
        IDDropDownLabel                 matlab.ui.control.Label
        DropDown_CameraAdapter          matlab.ui.control.DropDown
        Label_2                         matlab.ui.control.Label
        Tab_PressureController          matlab.ui.container.Tab
        Tab_FlowMeter                   matlab.ui.container.Tab
        Tab_EBPlus                      matlab.ui.container.Tab
    end

    properties (Constant, Hidden)
        VER = "1.0.0"
        MODE = "_RELEASE_"
        DEVICES_TYPES = ["camera", "daq_device", "pressure_controller", "flowmeter"]
        DEVICES_SUPPORTED = ["EBCamera", "EBDAQ", "EBPressureController", "EBFlowMeter"]
        PORT_COMPONENTS_SIZE = [140, 290]   % width, height
        CAMERA_ROI_MAX = dictionary(["gentl", "virtual"], {[2048, 1088], [2048, 1088]})    % width ,height
    end

    properties (Access = private)
        caller              % 1-by-1 EasyBehaviour object as parent

        mc_handle           % the ManualController handle

        devices             % 1-by-1 dictionary, key(TYPE) -> object

        daq_ports           % 1-by-m cell with Port object

        is_camera_preview   % 1-by-1 logical, false as default

        is_lock
    end

    methods (Access = ?EasyBehaviour)

        function Lock(app)
            % call this for panels locking when parent running experiment
            DAQPanelSwitch(app, "running");
            CameraPanelSwitch(app, "acquire");
        end

        function Unlock(app)
            if app.devices{"daq_device"}.IsConnected
                DAQPanelSwitch(app, "connect");
            else
                DAQPanelSwitch(app, "disconnect");
            end
            if app.devices{"camera"}.IsConnected
                CameraPanelSwitch(app, "connect");
            else
                CameraPanelSwitch(app, "disconnect");
            end
        end
        
        function CloseThis(app)
            HardwareConfigurationUIFigureCloseRequest(app, []);
        end
    end

    methods (Access = ?ManualController)
        function SetValves(app, code)
            app.devices{"daq_device"}.Test(code);
        end
    end
    
    methods (Access = private)
        function InitHardwareDevice(app)
            % get possible devices handle
            [app.devices, app.is_lock] = app.caller.GetDevices();

            if app.devices.isConfigured
                % just use connected device
                return;
            else
                % connect to new device
                %% Init camera object
                if app.MODE == "_DEBUG_"
                    camera = EBCamera("virtual");
                    camera.Connect();
                else
                    camera = EBCamera("gentl");
                end
                

                %% Init DAQ object
                daq_device = EBDAQ(@vcsl);
                
                if app.MODE == "_DEBUG_"
                    daq_device.Vendor = "virtual";
                    daq_device.PortNumber = 3;  % anyway
                    daq_device.Connect();
                else
                    dvl = daqvendorlist();
                    daq_device.Vendor = dvl.ID(1);
                    dl = daqlist(dvl.ID(1));
                    if ~isempty(dl)
                        ss = dl.DeviceInfo(1).Subsystems(1);    % should be DigitalIOInfo
                        np = ss.NumberOfChannelsAvailable/EBDAQ.NLINE_EACH_PORT;
                        daq_device.PortNumber = np;
                    else
                        throw(MException("HWC:daqLost", ...
                            "EBDAQ Device lost. Try to reconnect the device."));
                    end
                end

                %% Init pressure controller object
                pressure_controller = EBPressureController();

                %% Init flowmeter object
                flowmeter = EBFlowMeter();

                app.devices = dictionary(app.DEVICES_TYPES, ...
                    {camera, daq_device, pressure_controller, flowmeter});

                if app.MODE == "_DEBUG_"
                    app.caller.SetDevices(app.devices);
                end
            end
        end

        function InitData(app)
            app.daq_ports = {};
            app.is_camera_preview = false;
            app.mc_handle = [];
        end

        function InitPanels(app)
            %
            InitDAQPanel(app);
            %
            InitCameraPanel(app);

            if app.is_lock, Lock(app); else, Unlock(app); end
        end

        function InitCameraPanel(app)
            % hardware object exist before

            %% initialize basic parameters
            switch app.MODE
                case "_DEBUG_"
                    app.DropDown_CameraAdapter.ItemsData = "virtual";
                    app.DropDown_CameraAdapter.Value = app.devices{"camera"}.Adapter;
                    app.DropDown_CameraDeviceID.ItemsData = "1";
                otherwise
                    app.DropDown_CameraAdapter.ItemsData = imaqhwinfo().InstalledAdaptors;
                    if ismember(app.devices{"camera"}.Adapter, ...
                            string(app.DropDown_CameraAdapter.ItemsData))
                        app.DropDown_CameraAdapter.Value = app.devices{"camera"}.Adapter;
                    else
                        throw(MException("HWC:unsupportedCameraAdapter", ...
                            sprintf("Camera Adapter: %s has not been installed.", ...
                            app.devices{"camera"}.Adapter)));
                    end
                    app.DropDown_CameraDeviceID.ItemsData = ...
                        imaqhwinfo(app.devices{"camera"}.Adapter).DeviceIDs; % default identity
                    
                    %% modify appearance
                    MaxROI = app.CAMERA_ROI_MAX{app.devices{"camera"}.Adapter};
                    app.EditField_Width.Limits = [1, MaxROI(1)];
                    app.EditField_Height.Limits = [1, MaxROI(2)];
                    if app.devices{"camera"}.IsConnected
                        GetCameraConfiguration(app);
                        CameraPanelSwitch(app, "connect");
                    else
                        CameraPanelSwitch(app, "disconnect");
                    end
            end
        end
            

        function CameraPanelSwitch(app, state)   
            arguments
                app
                state   (1,1)   string  {mustBeMember(state, ...
                            ["disconnect", "connect", "live", "acquire"])}
            end

            pathToMLAPP = fileparts(mfilename('fullpath'));

            switch state
                case "disconnect"
                    %% reset image device panel
                    app.Panel_ImageDevice.Title = "图像设备 (None)";
                    app.DropDown_CameraAdapter.Enable = "on";
                    app.DropDown_CameraDeviceID.Enable = "on";

                    %% reset image format panel
                    app.Spinner_BinningHorizontal.Enable = "on";
                    app.Spinner_BinningVertical.Enable = "on";
                    app.EditField_OffsetX.Enable = "on";
                    app.EditField_OffsetY.Enable = "on";
                    app.EditField_Width.Enable = "on";
                    app.EditField_Height.Enable = "on";
                    app.EditField_Gamma.Enable = "off";
                    app.DropDown_BitDepth.Enable = "on";

                    %% reset capture panel
                    app.EditField_ExposureTime.Enable = "off";
                    app.EditField_AcquireFrameRate.Enable = "off";
                    app.EditField_SensorReadoutTime.Enable = "off";
                    app.EditField_MaxFrameRate.Enable = "off";

                    %% reset digital IO panel
                    app.DropDown_LineSelector.Enable = "off";
                    app.DropDown_LineMode.Enable = "off";
                    app.CheckBox_LineInverter.Enable = "off";
                    app.DropDown_LineSource.Enable = "off";

                    %% reset preview button
                    app.Button_CameraPreview.Enable = "off";
                    app.Button_CameraPreview.Icon = ...
                        fullfile(pathToMLAPP, 'sources', 'npreview.png');

                    %% reset connect switch
                    app.Switch_CameraConnect.Value = "off";
                case "connect"
                    %% modify image device panel
                    app.DropDown_CameraAdapter.Enable = "off";
                    app.DropDown_CameraDeviceID.Enable = "off";

                    %% modify image format panel
                    app.Spinner_BinningHorizontal.Enable = "off";
                    app.Spinner_BinningVertical.Enable = "off";
                    app.EditField_OffsetX.Enable = "on";
                    app.EditField_OffsetY.Enable = "on";
                    app.EditField_Width.Enable = "off";
                    app.EditField_Height.Enable = "off";
                    app.EditField_Gamma.Enable = "on";
                    app.DropDown_BitDepth.Enable = "off";

                    %% modify capture panel
                    app.EditField_ExposureTime.Enable = "on";
                    app.EditField_AcquireFrameRate.Enable = "on";
                    app.EditField_SensorReadoutTime.Enable = "on";
                    app.EditField_MaxFrameRate.Enable = "on";

                    %% modify digital IO panel
                    app.DropDown_LineSelector.Enable = "on";
                    % app.DropDown_LineMode.Enable = "on";      % not supported
                    app.CheckBox_LineInverter.Enable = "on";
                    app.DropDown_LineSource.Enable = "on";

                    %% modify preview button
                    app.Button_CameraPreview.Enable = "on";
                    app.Button_CameraPreview.Icon = ...
                        fullfile(pathToMLAPP, 'sources', 'preview.png');

                    %% modify connect switch
                    app.Switch_CameraConnect.Value = "on";
                case "live"
                    %% modify image device panel
                    app.DropDown_CameraAdapter.Enable = "off";
                    app.DropDown_CameraDeviceID.Enable = "off";

                    %% modify image format panel
                    app.Spinner_BinningHorizontal.Enable = "off";
                    app.Spinner_BinningVertical.Enable = "off";
                    app.EditField_OffsetX.Enable = "on";
                    app.EditField_OffsetY.Enable = "on";
                    app.EditField_Width.Enable = "off";
                    app.EditField_Height.Enable = "off";
                    app.EditField_Gamma.Enable = "on";
                    app.DropDown_BitDepth.Enable = "off";

                    %% modify capture panel
                    app.EditField_ExposureTime.Enable = "on";
                    app.EditField_AcquireFrameRate.Enable = "on";
                    app.EditField_SensorReadoutTime.Enable = "on";
                    app.EditField_MaxFrameRate.Enable = "on";

                    %% modify digital IO panel
                    app.DropDown_LineSelector.Enable = "on";
                    % app.DropDown_LineMode.Enable = "on";      % not supported
                    app.CheckBox_LineInverter.Enable = "on";
                    app.DropDown_LineSource.Enable = "on";

                    %% modify preview button
                    app.Button_CameraPreview.Enable = "on";
                    app.Button_CameraPreview.Icon = ...
                        fullfile(pathToMLAPP, 'sources', 'stop.png');

                    %% modify connect switch
                    app.Switch_CameraConnect.Value = "on";
                case "acquire"
                     %% modify image device panel
                    app.DropDown_CameraAdapter.Enable = "off";
                    app.DropDown_CameraDeviceID.Enable = "off";

                    %% modify image format panel
                    app.Spinner_BinningHorizontal.Enable = "off";
                    app.Spinner_BinningVertical.Enable = "off";
                    app.EditField_OffsetX.Enable = "off";
                    app.EditField_OffsetY.Enable = "off";
                    app.EditField_Width.Enable = "off";
                    app.EditField_Height.Enable = "off";
                    app.EditField_Gamma.Enable = "off";
                    app.DropDown_BitDepth.Enable = "off";

                    %% modify capture panel
                    app.EditField_ExposureTime.Enable = "off";
                    app.EditField_AcquireFrameRate.Enable = "off";
                    app.EditField_SensorReadoutTime.Enable = "on";
                    app.EditField_MaxFrameRate.Enable = "on";

                    %% modify digital IO panel
                    app.DropDown_LineSelector.Enable = "off";
                    % app.DropDown_LineMode.Enable = "on";      % not supported
                    app.CheckBox_LineInverter.Enable = "off";
                    app.DropDown_LineSource.Enable = "off";

                    %% modify preview button
                    app.Button_CameraPreview.Enable = "off";
                    app.Button_CameraPreview.Icon = ...
                        fullfile(pathToMLAPP, 'sources', 'npreview.png');

                    %% modify connect switch
                    app.Switch_CameraConnect.Value = "on";
                otherwise
            end
        end

        function SetCameraConfiguration(app)
            % set properties
            app.devices{"camera"}.AcquireFrameRate = app.EditField_AcquireFrameRate.Value;
            app.devices{"camera"}.ExposureTime = app.EditField_ExposureTime.Value;
            app.devices{"camera"}.Gamma = app.EditField_Gamma.Value;
            app.devices{"camera"}.LineInverter = app.CheckBox_LineInverter.Value;
            app.devices{"camera"}.LineSelector = app.DropDown_LineSelector.Value;
            app.devices{"camera"}.LineSource = app.DropDown_LineSource.Value;

            % update panels
            app.Panel_ImageDevice.Title = sprintf("图像设备 (%s)", ...
                app.devices{"camera"}.DeviceModelName);
        end

        function GetCameraConfiguration(app)
            % get properties
            app.EditField_AcquireFrameRate.Value = app.devices{"camera"}.AcquireFrameRate;
            app.Spinner_BinningHorizontal.Value = app.devices{"camera"}.BinningHorizontal;
            app.Spinner_BinningVertical.Value = app.devices{"camera"}.BinningVertical;
            app.EditField_ExposureTime.Value = app.devices{"camera"}.ExposureTime;
            app.EditField_Gamma.Value = app.devices{"camera"}.Gamma;
            app.CheckBox_LineInverter.Value = app.devices{"camera"}.LineInverter;
            app.DropDown_LineSelector.Value = app.devices{"camera"}.LineSelector;
            app.DropDown_LineSource.Value = app.devices{"camera"}.LineSource;
            app.EditField_OffsetX.Value = app.devices{"camera"}.OffsetX;
            app.EditField_OffsetY.Value = app.devices{"camera"}.OffsetY;
            app.EditField_Width.Value = app.devices{"camera"}.ROIWidth;
            app.EditField_Height.Value = app.devices{"camera"}.ROIHeight;

            % update panels
            app.Panel_ImageDevice.Title = sprintf("图像设备 (%s)", ...
                app.devices{"camera"}.DeviceModelName);
        end

        function InitDAQPanel(app)
            %% init basic parameters
            switch app.MODE
                case "_DEBUG_"
                    %
                    app.DropDown_DAQVendor.ItemsData = "virtual";
                    app.DropDown_DAQDigitalDevice.ItemsData = "";
                otherwise
                    dvl = daqvendorlist();
                    app.DropDown_DAQVendor.ItemsData = dvl.ID;
                    app.DropDown_DAQVendor.Items = dvl.FullName;

                    dl = daqlist(dvl.ID(1));
                    app.DropDown_DAQDigitalDevice.ItemsData = dl.DeviceID;
                    app.DropDown_DAQDigitalDevice.Items = dl.DeviceID + ...
                        "(" + dl.Model + ")";
            end

            if app.devices{"daq_device"}.IsConnected
                GetDAQConfiguration(app);

                DAQPanelSwitch(app, "connect");
            else
                %% init code file
                file_init = fullfile(fileparts(mfilename("fullpath")), 'excode', 'valve.vcs');
                if isfile(file_init)
                    app.EditField_ValvesConfigFilePath.Value = file_init;
                end

                %% refresh port-valve mapping
                RemoveAllDAQPorts(app);
                AppendDAQPorts(app, app.devices{"daq_device"}.PortNumber);
                app.daq_ports{1}.vlmap = ["1";"2";"3";"4";"";"";"";""]; % open port 0 ~ 3

                %% modify appearance
                DAQPanelSwitch(app, "disconnect");
            end
        end

        function DAQPanelSwitch(app, state)
            arguments
                app
                state   (1,1)   string  {mustBeMember(state, ...
                            ["disconnect", "connect", "running"])}
            end

            switch state
                case "disconnect"
                    %% reset basic configurations
                    app.EditField_ValvesConfigFilePath.Enable = "on";
                    app.DropDown_DAQVendor.Enable = "on";
                    app.DropDown_DAQDigitalDevice.Enable = "on";

                    %% reset buttons
                    app.Button_LoadValvesConfiguration.Enable = "on";
                    app.Button_RefreshValveDevice.Enable = "on";
                    app.Button_ReadMappingTable.Enable = "on";
                    app.Button_WriteMappingTable.Enable = "on";
                    app.Button_DAQTest.Enable = "off";

                    %% reset ports UI
                    for k = 1:numel(app.daq_ports)
                        app.daq_ports{k}.enable = true;
                    end

                    %% reset connect switch
                    app.Switch_DAQConnect.Value = "off";
                case "connect"
                    %% modify basic configurations
                    app.EditField_ValvesConfigFilePath.Enable = "off";
                    app.DropDown_DAQVendor.Enable = "off";
                    app.DropDown_DAQDigitalDevice.Enable = "off";
                    
                    %% modify buttons
                    app.Button_LoadValvesConfiguration.Enable = "off";
                    app.Button_RefreshValveDevice.Enable = "off";
                    app.Button_ReadMappingTable.Enable = "off";
                    app.Button_WriteMappingTable.Enable = "on";
                    app.Button_DAQTest.Enable = "on";

                    %% modify ports UI
                    for k = 1:numel(app.daq_ports)
                        app.daq_ports{k}.enable = false;
                    end

                    %% modify connect switch
                    app.Switch_DAQConnect.Value = "on";
                case "running"
                    %% modify basic configurations
                    app.EditField_ValvesConfigFilePath.Enable = "off";
                    app.DropDown_DAQVendor.Enable = "off";
                    app.DropDown_DAQDigitalDevice.Enable = "off";
                    
                    %% modify buttons
                    app.Button_LoadValvesConfiguration.Enable = "off";
                    app.Button_RefreshValveDevice.Enable = "off";
                    app.Button_ReadMappingTable.Enable = "off";
                    app.Button_WriteMappingTable.Enable = "off";
                    app.Button_DAQTest.Enable = "off";

                    %% modify ports UI
                    for k = 1:numel(app.daq_ports)
                        app.daq_ports{k}.enable = false;
                    end

                    %% modify connect switch
                    app.Switch_DAQConnect.Value = "on";
                otherwise
            end
        end

        function SetDAQConfiguration(app)
            % set configuration
            app.devices{"daq_device"}.CodeFile = app.EditField_ValvesConfigFilePath.Value;
            app.devices{"daq_device"}.Vendor = app.DropDown_DAQVendor.Value;
            app.devices{"daq_device"}.DeviceID = app.DropDown_DAQDigitalDevice.Value;

            % set valve mapping table and camera trigger port
            vp = [];
            vl = [];
            cp = [nan, nan];
            for np = 1:numel(app.daq_ports)
                for nl = 1:EBDAQ.NLINE_EACH_PORT
                    if ~isequal(app.daq_ports{np}.vlmap(nl),"")
                        vp = [vp; np-1, nl-1]; %#ok<AGROW>
                        vl = [vl; app.daq_ports{np}.vlmap(nl)]; %#ok<AGROW>
                    end
                end
                if any(app.daq_ports{np}.camap)
                    cp = [np-1, find(app.daq_ports{np}.camap, 1, "last")-1];
                end
            end
            app.devices{"daq_device"}.CameraPort = cp;
            app.devices{"daq_device"}.ValvesPort = vp;
            app.devices{"daq_device"}.MakeValvePortMapping(vl);
        end

        function GetDAQConfiguration(app)
            % get configuration
            app.EditField_ValvesConfigFilePath.Value = app.devices{"daq_device"}.CodeFile;
            app.DropDown_DAQVendor.Value = app.devices{"daq_device"}.Vendor;
            app.DropDown_DAQDigitalDevice.Value = app.devices{"daq_device"}.DeviceID;

            % recover DAQ Ports
            AppendDAQPorts(app, app.devices{"daq_device"}.PortNumber);

            if app.MODE ~= "_DEBUG_"
                % get valve mapping table and camera trigger port
                cp = app.devices{"daq_device"}.CameraPort;
                vp = app.devices{"daq_device"}.ValvesPort;
                vl = app.devices{"daq_device"}.ValvesLabel;

                app.daq_ports{cp(1)+1}.camap(cp(2)+1) = true;   % camera port

                for nv = 1:size(vp, 1)                          % valves port
                    app.daq_ports{vp(nv,1)+1}.vlmap(vp(nv,2)+1) = vl(nv);
                end
            end
        end

        function RemoveAllDAQPorts(app)
            if ~isempty(app.daq_ports)
                cellfun(@(x)delete(x), app.daq_ports);
                app.daq_ports = {};
            end
            app.GridLayout_MappingTable.ColumnWidth = {'1x'};
        end

        function AppendDAQPorts(app, n)
            app.GridLayout_MappingTable.ColumnWidth = ...
                repmat({app.PORT_COMPONENTS_SIZE(1)}, 1, n);
            app.daq_ports = cell(1, n);
            for k = 1:n
                % append one by one
                app.daq_ports{k} = Port(app.GridLayout_MappingTable);
                app.daq_ports{k}.Layout.Row = 1;
                app.daq_ports{k}.Layout.Column = k;

                % modify the properties
                app.daq_ports{k}.enable = false;
                app.daq_ports{k}.port = k-1;  % port index
                app.daq_ports{k}.vlmap = strings(EBDAQ.NLINE_EACH_PORT, 1);  % mapping daq_device
            end
        end 
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.caller = mainapp;

            InitData(app);

            try
                InitHardwareDevice(app);
                
                InitPanels(app);
            catch ME
                rethrow(ME);
            end
        end

        % Value changed function: EditField_ValvesConfigFilePath
        function EditField_ValvesConfigFilePathValueChanged(app, event)
            value = app.EditField_ValvesConfigFilePath.Value;
            
            if isfile(value)
                app.EditField_ValvesConfigFilePath.FontColor = [0,0,0];
                app.devices{"daq_device"}.CodeFile = value;
            else
                app.EditField_ValvesConfigFilePath.FontColor = [1,0,0];
                app.EditField_ValvesConfigFilePath.Value = "";
            end
        end

        % Button pushed function: Button_LoadValvesConfiguration
        function Button_LoadValvesConfigurationPushed(app, event)
            [file, path] = uigetfile({'*.vcs', 'VCSL Source Code File(*.vcs)'}, ...
                                     '选择VCSL源码文件', ...
                                     app.devices{"daq_device"}.CodeFile);

            if ~isnumeric(file) && isfile(fullfile(path, file))
                app.devices{"daq_device"}.CodeFile = fullfile(path, file);
                app.EditField_ValvesConfigFilePath.Value = ...
                    app.devices{"daq_device"}.CodeFile;
            end
        end

        % Value changed function: DropDown_DAQDigitalDevice
        function DropDown_DAQDigitalDeviceValueChanged(app, event)
            value = app.DropDown_DAQDigitalDevice.Value;
            
            app.devices{"daq_device"}.DeviceID = value;
        end

        % Button pushed function: Button_ReadMappingTable
        function Button_ReadMappingTablePushed(app, event)
            [file, path] = uigetfile({'*.map', 'DAQ Port Mapping File(*.map)'}, ...
                                     '选择DAQ端口映射文件', ...
                                     'EBDAQ.map');      % map file is .mat

            if ~isnumeric(file)
                mapping = load(fullfile(path, file));
                if ~isempty(setdiff(["valves_line", "camera_line", "device_id", "vendor"], ...
                        string(fieldnames(mapping))))
                    throw(MException("HWC:invalidPortMappingFile", ...
                        "Unsupported port mapping file version."));
                else
                    if ~isequal(mapping.device_id, app.devices{"daq_device"}.DeviceID) ...
                            || ~isequal(mapping.vendor, app.devices{"daq_device"}.Vendor)
                        throw(MException("HWC:deviceNotMatch", ...
                            "Current hardware does not support specific port mapping."));
                    else
                        for k = 1:numel(app.daq_ports)
                            app.daq_ports{k}.vlmap = mapping.valves_line(:, k);
                            app.daq_ports{k}.camap = mapping.camera_line(:, k);
                        end
                    end
                end
            end
        end

        % Button pushed function: Button_WriteMappingTable
        function Button_WriteMappingTablePushed(app, event)
            [file, path] = uiputfile({'*.map', 'DAQ Port Mapping File(*.map)'}, ...
                                     '选择DAQ端口映射文件', ...
                                     'EBDAQ.map');

            if ~isnumeric(file)
                % generate mapping struct
                valves_line = strings(EBDAQ.NLINE_EACH_PORT, numel(app.daq_ports));
                camera_line = false(EBDAQ.NLINE_EACH_PORT, numel(app.daq_ports));
                for k = 1:numel(app.daq_ports)
                    valves_line(:, k) = app.daq_ports{k}.vlmap;
                    camera_line(:, k) = app.daq_ports{k}.camap;
                end
                mapping.valves_line = valves_line;
                mapping.camera_line = camera_line;
                mapping.device_id = app.devices{"daq_device"}.DeviceID;
                mapping.vendor = app.devices{"daq_device"}.Vendor;

                save(fullfile(path, file), "mapping", '-mat');
            end
        end

        % Value changed function: DropDown_CameraAdapter
        function DropDown_CameraAdapterValueChanged(app, event)
            value = app.DropDown_CameraAdapter.Value;
            
            app.devices{"camera"}.Adapter = value;
        end

        % Value changed function: DropDown_CameraDeviceID
        function DropDown_CameraDeviceIDValueChanged(app, event)
            value = app.DropDown_CameraDeviceID.Value;
            
            app.devices{"camera"}.DeviceID = str2double(value);
        end

        % Value changed function: Spinner_BinningHorizontal
        function Spinner_BinningHorizontalValueChanged(app, event)
            value = app.Spinner_BinningHorizontal.Value;
            preval = app.devices{"camera"}.BinningHorizontal;
            prew = app.EditField_Width.Value;

            app.devices{"camera"}.BinningHorizontal = value;
            
            % synchronous ROI width
            MaxROI = app.CAMERA_ROI_MAX{app.devices{"camera"}.Adapter};
            app.EditField_Width.Limits = [1, ceil(MaxROI(1)/value)];
            app.EditField_Width.Value = fix(prew * preval / value);
            app.devices{"camera"}.ROIWidth = app.EditField_Width.Value;

            % synchronous Offset X
            x_max = app.EditField_Width.Limits(2);
            if x_max - app.EditField_Width.Value > 0
                app.EditField_OffsetX.Editable = "on";
                app.EditField_OffsetX.Limits = [0, x_max - app.EditField_Width.Value];
            else
                app.EditField_OffsetX.Editable = "off";
                app.EditField_OffsetX.Limits = [0, 1];
            end
            app.EditField_OffsetX.Value = 0;
        end

        % Value changed function: Spinner_BinningVertical
        function Spinner_BinningVerticalValueChanged(app, event)
            value = app.Spinner_BinningVertical.Value;
            preval = app.devices{"camera"}.BinningVertical;
            preh = app.EditField_Height.Value;
            
            app.devices{"camera"}.BinningVertical = value;

            % synchronous ROI height
            MaxROI = app.CAMERA_ROI_MAX{app.devices{"camera"}.Adapter};
            app.EditField_Height.Limits = [1, ceil(MaxROI(2)/value)];
            app.EditField_Height.Value = fix(preh * preval / value);
            app.devices{"camera"}.ROIHeight = app.EditField_Height.Value;

            % synchronous Offset Y
            y_max = app.EditField_Height.Limits(2);
            if y_max - app.EditField_Height.Value > 0
                app.EditField_OffsetY.Editable = "on";
                app.EditField_OffsetY.Limits = [0, y_max - app.EditField_Height.Value];
            else
                app.EditField_OffsetY.Editable = "off";
                app.EditField_OffsetY.Limits = [0, 1];
            end
            app.EditField_OffsetY.Value = 0;
        end

        % Value changed function: EditField_OffsetX
        function EditField_OffsetXValueChanged(app, event)
            value = app.EditField_OffsetX.Value;
            
            app.devices{"camera"}.OffsetX = value;
        end

        % Value changed function: EditField_OffsetY
        function EditField_OffsetYValueChanged(app, event)
            value = app.EditField_OffsetY.Value;

            app.devices{"camera"}.OffsetY = value;
        end

        % Value changed function: EditField_Width
        function EditField_WidthValueChanged(app, event)
            value = app.EditField_Width.Value;
            x_max = app.EditField_Width.Limits(2);
            
            app.devices{"camera"}.ROIWidth = value;

            % synchronous OffsetX
            if x_max - value > 0
                app.EditField_OffsetX.Editable = "on";
                app.EditField_OffsetX.Limits = [0, x_max - value];
            else
                app.EditField_OffsetX.Editable = "off";
                app.EditField_OffsetX.Limits = [0, 1];
            end
            app.EditField_OffsetX.Value = 0;
        end

        % Value changed function: EditField_Height
        function EditField_HeightValueChanged(app, event)
            value = app.EditField_Height.Value;
            y_max = app.EditField_Height.Limits(2);
            
            app.devices{"camera"}.ROIHeight = value;

            % synchronous OffsetY
            if y_max - value > 0
                app.EditField_OffsetY.Editable = "on";
                app.EditField_OffsetY.Limits = [0, y_max - value];
            else
                app.EditField_OffsetY.Editable = "off";
                app.EditField_OffsetY.Limits = [0, 1];
            end
            app.EditField_OffsetY.Value = 0;
        end

        % Value changed function: EditField_Gamma
        function EditField_GammaValueChanged(app, event)
            value = app.EditField_Gamma.Value;
            
            app.devices{"camera"}.Gamma = value;
        end

        % Value changed function: DropDown_BitDepth
        function DropDown_BitDepthValueChanged(app, event)
            value = app.DropDown_BitDepth.Value;

            app.devices{"camera"}.Format = value;
        end

        % Value changed function: EditField_ExposureTime
        function EditField_ExposureTimeValueChanged(app, event)
            value = app.EditField_ExposureTime.Value;
            
            app.devices{"camera"}.ExposureTime = value;

            % synchronous ReadoutTime and MaxFrameRate
            app.EditField_SensorReadoutTime.Value = app.devices{"camera"}.ReadoutTime;
            app.EditField_MaxFrameRate.Value = app.devices{"camera"}.MaxFrameRate;
        end

        % Value changed function: EditField_AcquireFrameRate
        function EditField_AcquireFrameRateValueChanged(app, event)
            value = app.EditField_AcquireFrameRate.Value;
            
            app.devices{"camera"}.AcquireFrameRate = value;
        end

        % Value changed function: DropDown_LineSelector
        function DropDown_LineSelectorValueChanged(app, event)
            value = app.DropDown_LineSelector.Value;
            
            app.devices{"camera"}.LineSelector = value;
        end

        % Value changed function: CheckBox_LineInverter
        function CheckBox_LineInverterValueChanged(app, event)
            value = app.CheckBox_LineInverter.Value;
            
            app.devices{"camera"}.LineInverter = value;
        end

        % Value changed function: DropDown_LineSource
        function DropDown_LineSourceValueChanged(app, event)
            value = app.DropDown_LineSource.Value;
            
            app.devices{"camera"}.LineSource = value;
        end

        % Value changed function: Switch_CameraConnect
        function Switch_CameraConnectValueChanged(app, event)
            value = app.Switch_CameraConnect.Value;
            
            % must waitfor connection established
            dlg = uiprogressdlg(app.HardwareConfigurationUIFigure, "Icon","info", ...
                "Indeterminate","on", "Message","正在与相机通信...", ...
                "Title","相机消息");

            switch value
                case "on"
                    CameraPanelSwitch(app, "connect");

                    app.devices{"camera"}.Connect();

                    SetCameraConfiguration(app);
                case "off"
                    app.devices{"camera"}.Disconnect();
                    CameraPanelSwitch(app, "disconnect");
                otherwise
            end

            % must waitfor connection established
            delete(dlg);

            app.caller.SetDevices(app.devices);
        end

        % Button pushed function: Button_CameraPreview
        function Button_CameraPreviewPushed(app, event)
            app.is_camera_preview = ~app.is_camera_preview;

            if app.is_camera_preview
                CameraPanelSwitch(app, "live");
                app.devices{"camera"}.Live("on");
            else
                app.devices{"camera"}.Live("off");
                CameraPanelSwitch(app, "connect");
            end
        end

        % Button pushed function: Button_RefreshValveDevice
        function Button_RefreshValveDevicePushed(app, event)
            % refresh daq devices
            InitDAQPanel(app);
        end

        % Value changed function: DropDown_DAQVendor
        function DropDown_DAQVendorValueChanged(app, event)
            value = app.DropDown_DAQVendor.Value;
            
            app.devices{"daq_device"}.Vendor = value;
        end

        % Value changed function: Switch_DAQConnect
        function Switch_DAQConnectValueChanged(app, event)
            value = app.Switch_DAQConnect.Value;
            
            % must waitfor connection established
            dlg = uiprogressdlg(app.HardwareConfigurationUIFigure, "Icon","info", ...
                "Indeterminate","on", "Message","正在与DAQ设备通信...", ...
                "Title","DAQ消息");

            switch value
                case "on"
                    % lock panels
                    DAQPanelSwitch(app, "connect");

                    % apply configuration
                    SetDAQConfiguration(app);

                    % connect
                    app.devices{"daq_device"}.Connect();
                case "off"
                    % disconnect
                    app.devices{"daq_device"}.Disconnect();

                    % unlock panels
                    DAQPanelSwitch(app, "disconnect");
                otherwise
            end

            delete(dlg);

            app.caller.SetDevices(app.devices);
        end

        % Button pushed function: Button_DAQTest
        function Button_DAQTestPushed(app, event)
            if app.devices{"daq_device"}.IsConnected
                modules = app.devices{"daq_device"}.ValvesPort;

                if ~isobject(app.mc_handle) || ~isvalid(app.mc_handle)
                    app.mc_handle = ManualController(app, modules);
                end
            end
        end

        % Close request function: HardwareConfigurationUIFigure
        function HardwareConfigurationUIFigureCloseRequest(app, event)
            % stop any live
            if isKey(app.devices, "camera") ...
                    && app.devices{"camera"}.IsConnected ...
                    && app.devices{"camera"}.IsLiving
                app.devices{"camera"}.Live("off");
            end

            if isobject(app.mc_handle) && isvalid(app.mc_handle)
                delete(app.mc_handle);
            end

            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.HardwareConfigurationUIFigure = uifigure('Visible', 'off');
            app.HardwareConfigurationUIFigure.Name = 'Hardware Configuration';
            app.HardwareConfigurationUIFigure.Icon = fullfile(pathToMLAPP, 'sources', 'hardware.png');
            app.HardwareConfigurationUIFigure.CloseRequestFcn = createCallbackFcn(app, @HardwareConfigurationUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.HardwareConfigurationUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Tab_DIOControl = uitab(app.TabGroup);
            app.Tab_DIOControl.Title = 'DAQ 设备';
            app.GridLayout2 = uigridlayout(app.Tab_DIOControl);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = '实验配置';
            app.EditField_ValvesConfigFilePath = uieditfield(app.GridLayout3, 'text');
            app.EditField_ValvesConfigFilePath.ValueChangedFcn = createCallbackFcn(app, @EditField_ValvesConfigFilePathValueChanged, true);
            app.EditField_ValvesConfigFilePath.Placeholder = '.\excode\valve.vcs';
            app.Button_LoadValvesConfiguration = uibutton(app.GridLayout3, 'push');
            app.Button_LoadValvesConfiguration.ButtonPushedFcn = createCallbackFcn(app, @Button_LoadValvesConfigurationPushed, true);
            app.Button_LoadValvesConfiguration.Icon = fullfile(pathToMLAPP, 'sources', 'folder.png');
            app.Button_LoadValvesConfiguration.Text = '';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.IODropDownLabel = uilabel(app.GridLayout4);
            app.IODropDownLabel.Text = '数字IO设备';
            app.DropDown_DAQDigitalDevice = uidropdown(app.GridLayout4);
            app.DropDown_DAQDigitalDevice.Items = {'Dev1(USB-6501)', 'SimDev1(NI Simulatred DAQ Device)'};
            app.DropDown_DAQDigitalDevice.ValueChangedFcn = createCallbackFcn(app, @DropDown_DAQDigitalDeviceValueChanged, true);
            app.DropDown_DAQDigitalDevice.Value = 'Dev1(USB-6501)';
            app.Button_RefreshValveDevice = uibutton(app.GridLayout4, 'push');
            app.Button_RefreshValveDevice.ButtonPushedFcn = createCallbackFcn(app, @Button_RefreshValveDevicePushed, true);
            app.Button_RefreshValveDevice.Icon = fullfile(pathToMLAPP, 'sources', 'refresh.png');
            app.Button_RefreshValveDevice.Text = '';
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.Button_ReadMappingTable = uibutton(app.GridLayout6, 'push');
            app.Button_ReadMappingTable.ButtonPushedFcn = createCallbackFcn(app, @Button_ReadMappingTablePushed, true);
            app.Button_ReadMappingTable.Text = '读映射';
            app.Button_WriteMappingTable = uibutton(app.GridLayout6, 'push');
            app.Button_WriteMappingTable.ButtonPushedFcn = createCallbackFcn(app, @Button_WriteMappingTablePushed, true);
            app.Button_WriteMappingTable.Text = '写映射';
            app.Button_DAQTest = uibutton(app.GridLayout6, 'push');
            app.Button_DAQTest.ButtonPushedFcn = createCallbackFcn(app, @Button_DAQTestPushed, true);
            app.Button_DAQTest.Enable = 'off';
            app.Button_DAQTest.Tooltip = {''};
            app.Button_DAQTest.Text = '测试';
            app.Switch_DAQConnect = uiswitch(app.GridLayout6, 'slider');
            app.Switch_DAQConnect.Items = {'关', '开'};
            app.Switch_DAQConnect.ItemsData = {'off', 'on'};
            app.Switch_DAQConnect.ValueChangedFcn = createCallbackFcn(app, @Switch_DAQConnectValueChanged, true);
            app.Switch_DAQConnect.Value = 'off';
            app.GridLayout_MappingTable = uigridlayout(app.GridLayout5);
            app.GridLayout21 = uigridlayout(app.GridLayout2);
            app.Label_12 = uilabel(app.GridLayout21);
            app.Label_12.Text = '适配器源';
            app.DropDown_DAQVendor = uidropdown(app.GridLayout21);
            app.DropDown_DAQVendor.Items = {'National Instruments(TM)', 'Analog Devices', 'Measurement Computing', 'Microsoft DirectSound', 'Digilent'};
            app.DropDown_DAQVendor.ValueChangedFcn = createCallbackFcn(app, @DropDown_DAQVendorValueChanged, true);
            app.DropDown_DAQVendor.Value = 'National Instruments(TM)';
            app.Tab_Camera = uitab(app.TabGroup);
            app.Tab_Camera.Title = '相机';
            app.GridLayout11 = uigridlayout(app.Tab_Camera);
            app.GridLayout7 = uigridlayout(app.GridLayout11);
            app.Panel_ImageDevice = uipanel(app.GridLayout7);
            app.Panel_ImageDevice.Title = '图像设备 (None)';
            app.GridLayout19 = uigridlayout(app.Panel_ImageDevice);
            app.Label_2 = uilabel(app.GridLayout19);
            app.Label_2.Text = '适配器';
            app.DropDown_CameraAdapter = uidropdown(app.GridLayout19);
            app.DropDown_CameraAdapter.Items = {'gentl', 'winvideo'};
            app.DropDown_CameraAdapter.ValueChangedFcn = createCallbackFcn(app, @DropDown_CameraAdapterValueChanged, true);
            app.DropDown_CameraAdapter.Value = 'gentl';
            app.IDDropDownLabel = uilabel(app.GridLayout19);
            app.IDDropDownLabel.Text = '设备ID';
            app.DropDown_CameraDeviceID = uidropdown(app.GridLayout19);
            app.DropDown_CameraDeviceID.Items = {'1'};
            app.DropDown_CameraDeviceID.ValueChangedFcn = createCallbackFcn(app, @DropDown_CameraDeviceIDValueChanged, true);
            app.DropDown_CameraDeviceID.Value = '1';
            app.Panel_Capture = uipanel(app.GridLayout7);
            app.Panel_Capture.Title = '捕获';
            app.GridLayout12 = uigridlayout(app.Panel_Capture);
            app.GridLayout13 = uigridlayout(app.GridLayout12);
            app.Label_5 = uilabel(app.GridLayout13);
            app.Label_5.Text = '曝光时间';
            app.EditField_ExposureTime = uieditfield(app.GridLayout13, 'numeric');
            app.EditField_ExposureTime.Limits = [5000 1000000];
            app.EditField_ExposureTime.ValueChangedFcn = createCallbackFcn(app, @EditField_ExposureTimeValueChanged, true);
            app.EditField_ExposureTime.Tooltip = {'微秒'};
            app.EditField_ExposureTime.Value = 10000;
            app.FPSEditFieldLabel = uilabel(app.GridLayout13);
            app.FPSEditFieldLabel.Text = '帧率(FPS)';
            app.EditField_AcquireFrameRate = uieditfield(app.GridLayout13, 'numeric');
            app.EditField_AcquireFrameRate.Limits = [1 10];
            app.EditField_AcquireFrameRate.ValueChangedFcn = createCallbackFcn(app, @EditField_AcquireFrameRateValueChanged, true);
            app.EditField_AcquireFrameRate.Value = 10;
            app.GridLayout14 = uigridlayout(app.GridLayout12);
            app.Label_6 = uilabel(app.GridLayout14);
            app.Label_6.Text = '读出时间';
            app.EditField_SensorReadoutTime = uieditfield(app.GridLayout14, 'numeric');
            app.EditField_SensorReadoutTime.Limits = [0 Inf];
            app.EditField_SensorReadoutTime.Editable = 'off';
            app.EditField_SensorReadoutTime.Tooltip = {'微秒'};
            app.EditField_SensorReadoutTime.Value = 12000;
            app.Label_7 = uilabel(app.GridLayout14);
            app.Label_7.Text = '最大帧率';
            app.EditField_MaxFrameRate = uieditfield(app.GridLayout14, 'numeric');
            app.EditField_MaxFrameRate.Limits = [0 Inf];
            app.EditField_MaxFrameRate.Editable = 'off';
            app.EditField_MaxFrameRate.Tooltip = {'理论上限'};
            app.EditField_MaxFrameRate.Value = 83.33;
            app.Panel_ImageFormat = uipanel(app.GridLayout7);
            app.Panel_ImageFormat.Title = '图像格式';
            app.GridLayout9 = uigridlayout(app.Panel_ImageFormat);
            app.BinningSpinnerLabel = uilabel(app.GridLayout9);
            app.BinningSpinnerLabel.Text = '水平Binning';
            app.Spinner_BinningHorizontal = uispinner(app.GridLayout9);
            app.Spinner_BinningHorizontal.Limits = [1 4];
            app.Spinner_BinningHorizontal.ValueDisplayFormat = '%.0f';
            app.Spinner_BinningHorizontal.ValueChangedFcn = createCallbackFcn(app, @Spinner_BinningHorizontalValueChanged, true);
            app.Spinner_BinningHorizontal.Value = 1;
            app.BinningSpinner_2Label = uilabel(app.GridLayout9);
            app.BinningSpinner_2Label.Text = '竖直Binning';
            app.Spinner_BinningVertical = uispinner(app.GridLayout9);
            app.Spinner_BinningVertical.Limits = [1 4];
            app.Spinner_BinningVertical.ValueDisplayFormat = '%.0f';
            app.Spinner_BinningVertical.ValueChangedFcn = createCallbackFcn(app, @Spinner_BinningVerticalValueChanged, true);
            app.Spinner_BinningVertical.Value = 1;
            app.ROIXLabel = uilabel(app.GridLayout9);
            app.ROIXLabel.Text = 'ROI水平偏移';
            app.EditField_OffsetX = uieditfield(app.GridLayout9, 'numeric');
            app.EditField_OffsetX.Limits = [0 Inf];
            app.EditField_OffsetX.ValueChangedFcn = createCallbackFcn(app, @EditField_OffsetXValueChanged, true);
            app.EditField_OffsetX.Editable = 'off';
            app.ROIEditField_2Label = uilabel(app.GridLayout9);
            app.ROIEditField_2Label.Text = 'ROI竖直偏移';
            app.EditField_OffsetY = uieditfield(app.GridLayout9, 'numeric');
            app.EditField_OffsetY.Limits = [0 Inf];
            app.EditField_OffsetY.ValueChangedFcn = createCallbackFcn(app, @EditField_OffsetYValueChanged, true);
            app.EditField_OffsetY.Editable = 'off';
            app.ROIEditField_3Label = uilabel(app.GridLayout9);
            app.ROIEditField_3Label.Text = 'ROI宽度';
            app.EditField_Width = uieditfield(app.GridLayout9, 'numeric');
            app.EditField_Width.Limits = [1 2048];
            app.EditField_Width.ValueChangedFcn = createCallbackFcn(app, @EditField_WidthValueChanged, true);
            app.EditField_Width.Value = 2048;
            app.ROIEditField_4Label = uilabel(app.GridLayout9);
            app.ROIEditField_4Label.Text = 'ROI高度';
            app.EditField_Height = uieditfield(app.GridLayout9, 'numeric');
            app.EditField_Height.Limits = [1 1088];
            app.EditField_Height.ValueChangedFcn = createCallbackFcn(app, @EditField_HeightValueChanged, true);
            app.EditField_Height.Value = 1088;
            app.Label_3 = uilabel(app.GridLayout9);
            app.Label_3.Text = '伽马';
            app.EditField_Gamma = uieditfield(app.GridLayout9, 'numeric');
            app.EditField_Gamma.Limits = [0.001 3.999];
            app.EditField_Gamma.ValueDisplayFormat = '%.3f';
            app.EditField_Gamma.ValueChangedFcn = createCallbackFcn(app, @EditField_GammaValueChanged, true);
            app.EditField_Gamma.Value = 1;
            app.Label_4 = uilabel(app.GridLayout9);
            app.Label_4.Text = '像素深度';
            app.DropDown_BitDepth = uidropdown(app.GridLayout9);
            app.DropDown_BitDepth.Items = {'8', '12'};
            app.DropDown_BitDepth.ItemsData = {'Mono8', 'Mono12'};
            app.DropDown_BitDepth.ValueChangedFcn = createCallbackFcn(app, @DropDown_BitDepthValueChanged, true);
            app.DropDown_BitDepth.Value = 'Mono12';
            app.Panel_DigitalIO = uipanel(app.GridLayout7);
            app.Panel_DigitalIO.Title = '数字IO';
            app.GridLayout17 = uigridlayout(app.Panel_DigitalIO);
            app.GridLayout18 = uigridlayout(app.GridLayout17);
            app.Label_8 = uilabel(app.GridLayout18);
            app.Label_8.Text = '反转通道';
            app.CheckBox_LineInverter = uicheckbox(app.GridLayout18);
            app.CheckBox_LineInverter.ValueChangedFcn = createCallbackFcn(app, @CheckBox_LineInverterValueChanged, true);
            app.CheckBox_LineInverter.Text = '';
            app.CheckBox_LineInverter.Value = true;
            app.Label_9 = uilabel(app.GridLayout18);
            app.Label_9.Text = '通道源';
            app.DropDown_LineSource = uidropdown(app.GridLayout18);
            app.DropDown_LineSource.Items = {'Exposure Active', 'Frame Trigger Wait', 'Frame Burst Trigger Wait'};
            app.DropDown_LineSource.ItemsData = {'ExposureActive', 'FrameTriggerWait', 'FrameBurstTriggerWait'};
            app.DropDown_LineSource.ValueChangedFcn = createCallbackFcn(app, @DropDown_LineSourceValueChanged, true);
            app.DropDown_LineSource.Value = 'ExposureActive';
            app.GridLayout20 = uigridlayout(app.GridLayout17);
            app.Label_10 = uilabel(app.GridLayout20);
            app.Label_10.Text = '通道';
            app.DropDown_LineSelector = uidropdown(app.GridLayout20);
            app.DropDown_LineSelector.Items = {'Line1', 'Line2', 'Line3', 'Line4'};
            app.DropDown_LineSelector.ValueChangedFcn = createCallbackFcn(app, @DropDown_LineSelectorValueChanged, true);
            app.DropDown_LineSelector.Value = 'Line2';
            app.Label_11 = uilabel(app.GridLayout20);
            app.Label_11.Text = '模式';
            app.DropDown_LineMode = uidropdown(app.GridLayout20);
            app.DropDown_LineMode.Items = {'input', 'output'};
            app.DropDown_LineMode.Enable = 'off';
            app.DropDown_LineMode.Value = 'input';
            app.GridLayout16 = uigridlayout(app.GridLayout11);
            app.Switch_CameraConnect = uiswitch(app.GridLayout16, 'slider');
            app.Switch_CameraConnect.Items = {'关', '开'};
            app.Switch_CameraConnect.ItemsData = {'off', 'on'};
            app.Switch_CameraConnect.ValueChangedFcn = createCallbackFcn(app, @Switch_CameraConnectValueChanged, true);
            app.Switch_CameraConnect.Value = 'off';
            app.Button_CameraPreview = uibutton(app.GridLayout16, 'push');
            app.Button_CameraPreview.ButtonPushedFcn = createCallbackFcn(app, @Button_CameraPreviewPushed, true);
            app.Button_CameraPreview.Icon = fullfile(pathToMLAPP, 'sources', 'npreview.png');
            app.Button_CameraPreview.Text = '预览';
            app.Tab_PressureController = uitab(app.TabGroup);
            app.Tab_PressureController.Title = '压强控制器';
            app.Tab_FlowMeter = uitab(app.TabGroup);
            app.Tab_FlowMeter.Title = '流量计';
            app.Tab_EBPlus = uitab(app.TabGroup);
            app.Tab_EBPlus.Title = 'EB+';
            % Show the figure after all components are created
            app.HardwareConfigurationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HardwareConfiguration(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.HardwareConfigurationUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.HardwareConfigurationUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HardwareConfigurationUIFigure)
        end
    end
end