classdef ManualController < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ManualValvesControlUIFigure  matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        Panel                        matlab.ui.container.Panel
        GridLayout_ValvesGroup       matlab.ui.container.GridLayout
    end

    properties (Constant, Hidden)
        VALVES_COMPONENT_WIDTH = 480
        VALVES_COMPONENT_HEIGHT = 120
        GRID_LAYOUT_ROW_SPACING = 10
        GRID_LAYOUT_COLUMN_SPACING = 10
        PANEL_TITLE_HEIGHT = 20
        LINES_COUNT = 8
    end

    properties (Access = private)
        caller              (1,1)           % 1-by-1 HardwareConfiguration object handle
        valve_controllers   (:,1)   cell    % n-by-1 cell with Valves object
        modules             (:,2)   double  % valves port array, [port, line]
        ports               (:,1)   double  % valves controller sub ports
        code                (1,:)   logical % 
    end
    
    methods (Access = ?ValvesController)
        function send_command(app, port, command)
            % combine code from command
            lines = app.modules(app.modules(:,1)==port, 2)+1;   % index from 0

            app.code(app.modules(:,1)==port) = command(lines);

            % send code to
            app.caller.SetValves(app.code);
        end
    end
    
    methods (Access = private)
        function InitData(app, modules)
            app.modules = modules;
            app.ports = unique(modules(:,1));
            app.code = false(1, size(modules, 1));
            n_controller = numel(app.ports);
            app.valve_controllers = cell(n_controller, 1);
        end

        function UpdateApperance(app)
            % set figure height
            app.ManualValvesControlUIFigure.Position(4) ...
                = numel(app.valve_controllers)*app.VALVES_COMPONENT_HEIGHT ...
                + (numel(app.valve_controllers)+1)*app.GRID_LAYOUT_ROW_SPACING ...
                + app.PANEL_TITLE_HEIGHT;

            % modify grid layout
            app.GridLayout_ValvesGroup.RowHeight = repmat({'1x'}, 1, numel(app.valve_controllers));

            for k = 1:numel(app.valve_controllers)
                % append valves in grid layout
                valve_controller = ValvesController(app.GridLayout_ValvesGroup);

                % manage controller location
                valve_controller.Layout.Row = k;
                valve_controller.Layout.Column = 1;
                
                % manage controller valves status
                lines_enable = app.modules(app.modules(:,1)==app.ports(k), 2)+1;    % index start from 0
                valve_controller.Enable(lines_enable) = true;
                valve_controller.setCaller(app);
                valve_controller.setPort(app.ports(k));

                app.valve_controllers{k} = valve_controller;
            end
        end

        function CloseAllValves(app)
            for k = 1:numel(app.ports)
                app.send_command(app.ports(k), false(1, app.LINES_COUNT));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, modules)
            arguments
                app
                caller  (1,1)   HardwareConfiguration
                modules (:,2)   double  {mustBeNonnegative, mustBeInteger}
            end
            
            app.caller = caller;

            InitData(app, modules);

            UpdateApperance(app);

            % First Test
            CloseAllValves(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ManualValvesControlUIFigure = uifigure('Visible', 'off');
            app.ManualValvesControlUIFigure.Name = 'ManualValvesControl';
            app.ManualValvesControlUIFigure.Icon = 'valves.png';
            app.GridLayout = uigridlayout(app.ManualValvesControlUIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.Panel.Title = '手动控制器(电磁阀)';
            app.GridLayout_ValvesGroup = uigridlayout(app.Panel);
            % Show the figure after all components are created
            app.ManualValvesControlUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ManualController(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ManualValvesControlUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ManualValvesControlUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ManualValvesControlUIFigure)
        end
    end
end