classdef MovieExporter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VideoExporterUIFigure      matlab.ui.Figure
        GridLayout11               matlab.ui.container.GridLayout
        Panel_Setting              matlab.ui.container.Panel
        GridLayout                 matlab.ui.container.GridLayout
        GridLayout14               matlab.ui.container.GridLayout
        DropDown_Resource          matlab.ui.control.DropDown
        Label_11                   matlab.ui.control.Label
        GridLayout13               matlab.ui.container.GridLayout
        Button_Cancel              matlab.ui.control.Button
        Button_OK                  matlab.ui.control.Button
        GridLayout12               matlab.ui.container.GridLayout
        DropDown_OutputMarker      matlab.ui.control.DropDown
        Label_9                    matlab.ui.control.Label
        DropDown_Interpolation     matlab.ui.control.DropDown
        Label_6                    matlab.ui.control.Label
        GridLayout10               matlab.ui.container.GridLayout
        Label_Mbps                 matlab.ui.control.Label
        EditField_TargetMBitrate   matlab.ui.control.NumericEditField
        Label_7                    matlab.ui.control.Label
        GridLayout9                matlab.ui.container.GridLayout
        CheckBox_LosslessCompress  matlab.ui.control.CheckBox
        Label_10                   matlab.ui.control.Label
        Spinner_RenderingQuality   matlab.ui.control.Spinner
        Label_4                    matlab.ui.control.Label
        GridLayout8                matlab.ui.container.GridLayout
        CheckBox_FrameRateSync     matlab.ui.control.CheckBox
        Label_FPS                  matlab.ui.control.Label
        EditField_FrameRate        matlab.ui.control.NumericEditField
        Label_8                    matlab.ui.control.Label
        GridLayout3                matlab.ui.container.GridLayout
        GridLayout7                matlab.ui.container.GridLayout
        Image_Link                 matlab.ui.control.Image
        GridLayout6                matlab.ui.container.GridLayout
        EditField_Height           matlab.ui.control.NumericEditField
        Label_3                    matlab.ui.control.Label
        EditField_Width            matlab.ui.control.NumericEditField
        Label_2                    matlab.ui.control.Label
        GridLayout5                matlab.ui.container.GridLayout
        Button_Recover             matlab.ui.control.Button
        GridLayout4                matlab.ui.container.GridLayout
        Button_LockHWRatio         matlab.ui.control.StateButton
        GridLayout2                matlab.ui.container.GridLayout
        DropDown_OutputFormat      matlab.ui.control.DropDown
        Label                      matlab.ui.control.Label
    end

    properties (GetAccess = ?BugReporter, Constant)
        VER = "1.0.0"
    end

    properties (Access = private)
        caller      % the parent app
        opts        % export options struct
        width_raw   % raw image width
        height_raw  % raw image height
        hw_ratio    % ratio of image height/width
        channels_n  % number of color channels
        mfilepath   % full path to this app
    end

    methods(Access = ?EasyBehaviour)
        function Close(app)
            % ~
            delete(app);
        end
    end
    
    methods (Access = private)
        
        function InitData(app, mainapp)
            app.caller = mainapp;

            app.opts = mainapp.GetExportOptions();

            app.width_raw = app.opts.OutputWidth;
            app.height_raw = app.opts.OutputHeight;

            switch app.opts.OutputFormat
                case {'Archival', 'Motion JPEG AVI', 'MPEG-4'}
                    app.channels_n = 3;
                otherwise
                    app.channels_n = 1;
            end

            app.hw_ratio = app.height_raw / app.width_raw;

            app.mfilepath = fileparts(mfilename('fullpath'));
        end

        function InitPanel(app)
            app.DropDown_OutputFormat.Value = app.opts.OutputFormat;
            updateAppearenceBy(app);

            app.EditField_Width.Value = app.opts.OutputWidth;
            app.EditField_Height.Value = app.opts.OutputHeight;
            app.EditField_FrameRate.Value = app.opts.OutputFrameRate;
            app.CheckBox_FrameRateSync.Value = app.opts.FrameRateSync;
            app.EditField_FrameRate.Enable = ~app.opts.FrameRateSync;
            app.Spinner_RenderingQuality.Value = app.opts.RenderingQuality;
            app.CheckBox_LosslessCompress.Value = app.opts.LosslessCompress;

            mbitrate_max = app.opts.OutputWidth * app.opts.OutputHeight * ...
                app.opts.OutputFrameRate*app.channels_n/1048576;
            app.EditField_TargetMBitrate.Limits = [0.001, mbitrate_max];
            app.EditField_TargetMBitrate.Value = min(app.opts.TargetMBitrate, mbitrate_max);
            app.DropDown_Interpolation.Value = app.opts.Interpolation;

            app.DropDown_Resource.Value = app.opts.Resource;
            switch app.opts.Resources
                case "camera"
                    app.DropDown_OutputMarker.Enable = "off";
                    app.DropDown_OutputMarker.Value = "none";
                otherwise
                    app.DropDown_OutputMarker.Enable = "on";
                    app.DropDown_OutputMarker.Value = app.opts.OutputMarker;
            end
        end

        function updateAppearenceBy(app)
            switch app.opts.OutputFormat
                case "Motion JPEG 2000"
                    app.EditField_TargetMBitrate.Enable = "on";
                    app.CheckBox_LosslessCompress.Enable = "on";
                    app.Spinner_RenderingQuality.Enable = "off";
                case {'MPEG-4', 'Motion JEPG AVI'}
                    app.EditField_TargetMBitrate.Enable = "off";
                    app.CheckBox_LosslessCompress.Enable = "off";
                    app.Spinner_RenderingQuality.Enable = "on";
                otherwise
                    app.EditField_TargetMBitrate.Enable = "off";
                    app.CheckBox_LosslessCompress.Enable = "off";
                    app.Spinner_RenderingQuality.Enable = "off";
            end
        end

        function syncTargetMBitRateLimit(app)
            mbitrate_max = app.opts.OutputWidth * app.opts.OutputHeight ...
                * app.opts.OutputFrameRate * app.channels_n / 1048576;
            app.EditField_TargetMBitrate.Limits = [0.001, mbitrate_max];
        end

        function overloadProfile(app)
            % This function re-generate profile and put on opts
            % by different output format
            switch app.opts.OutputFormat
                case "Motion JPEG 2000"
                    profiles = struct("CompressionRatio",   app.opts.CompressRatio, ...
                                      "FrameRate",          app.opts.OutputFrameRate, ...
                                      "LosslessCompression",app.opts.LosslessCompression);
                case {'MPEG-4', 'Motion JPEG AVI'}
                    profiles = struct("Quality",            app.opts.RenderingQuality, ...
                                      "FrameRate",          app.opts.OutputFrameRate);
                otherwise
                    profiles = struct();
            end

            app.opts.Profile = profiles;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            InitData(app, mainapp);

            InitPanel(app);
        end

        % Value changed function: Button_LockHWRatio
        function Button_LockHWRatioValueChanged(app, event)
            value = app.Button_LockHWRatio.Value;

            if value == true
                app.Button_LockHWRatio.Icon = fullfile(app.mfilepath, 'sources', 'lock.png');
            else
                app.Button_LockHWRatio.Icon = fullfile(app.mfilepath, 'sources', 'unlock.png');
            end
        end

        % Button pushed function: Button_OK
        function Button_OKPushed(app, event)
            % append profile
            app.overloadProfile();
            
            app.opts.ExportStatus = true;
            
            app.caller.SetExportOptions(app.opts);

            % ~
            delete(app);
        end

        % Button pushed function: Button_Cancel
        function Button_CancelPushed(app, event)
            app.opts.ExportStatus = false;

            app.caller.SetExportOptions(app.opts);

            % ~
            delete(app);
        end

        % Value changed function: DropDown_OutputFormat
        function DropDown_OutputFormatValueChanged(app, event)
            value = app.DropDown_OutputFormat.Value;

            app.opts.OutputFormat = value;

            updateAppearenceBy(app);

            switch value
                case {'Archival', 'Motion JPEG AVI', 'MPEG-4'}
                    app.channels_n = 3;
                otherwise
                    app.channels_n = 1;
            end

            % sync target bitrate limitation
            syncTargetMBitRateLimit(app);
        end

        % Value changed function: EditField_Width
        function EditField_WidthValueChanged(app, event)
            value = app.EditField_Width.Value;

            app.opts.OutputWidth = value;

            if app.Button_LockHWRatio.Value == true
                % keep the width heigth ratio
                app.opts.OutputHeight = ceil(value*app.hw_ratio);
                app.EditField_Height.Value = app.opts.OutputHeight;
            else
                % update hw ratio
                app.hw_ratio = app.opts.OutputHeight / value;
            end

            % sync target bitrate limitation
            syncTargetMBitRateLimit(app);
        end

        % Value changed function: EditField_Height
        function EditField_HeightValueChanged(app, event)
            value = app.EditField_Height.Value;

            app.opts.OutputHeight = value;

            if app.Button_LockHWRatio.Value == true
                % keep the width heigth ratio
                app.opts.OutputWidth = ceil(value/app.hw_ratio);
                app.EditField_Width.Value = app.opts.OutputWidth;
            else
                % update hw ratio
                app.hw_ratio = value / app.opts.OutputWidth;
            end

            % sync target bitrate limitation
            syncTargetMBitRateLimit(app);
        end

        % Value changed function: EditField_FrameRate
        function EditField_FrameRateValueChanged(app, event)
            value = app.EditField_FrameRate.Value;

            app.opts.OutputFrameRate = value;

            % sync target bitrate limitation
            syncTargetMBitRateLimit(app);
        end

        % Value changed function: Spinner_RenderingQuality
        function Spinner_RenderingQualityValueChanged(app, event)
            value = app.Spinner_RenderingQuality.Value;

            app.opts.RenderingQuality = value;
        end

        % Value changed function: CheckBox_LosslessCompress
        function CheckBox_LosslessCompressValueChanged(app, event)
            value = app.CheckBox_LosslessCompress.Value;

            app.opts.NoCompress = value;

            app.EditField_TargetMBitrate.Enable = ~value;
        end

        % Value changed function: EditField_TargetMBitrate
        function EditField_TargetMBitrateValueChanged(app, event)
            value = app.EditField_TargetMBitrate.Value;  % target

            app.opts.TargetMBitrate = value;

            % calculate the real bit rate
            mbitrate_max = app.opts.OutputWidth * app.opts.OutputHeight ...
                * app.opts.OutputFrameRate * app.channels_n / 1048576;

            app.opts.CompressRatio = min(1, mbitrate_max/value);  % 1 ~
        end

        % Value changed function: DropDown_Interpolation
        function DropDown_InterpolationValueChanged(app, event)
            value = app.DropDown_Interpolation.Value;

            app.opts.Interpolation = value;
        end

        % Button pushed function: Button_Recover
        function Button_RecoverPushed(app, event)
            app.EditField_Width.Value = app.width_raw;
            app.EditField_Height.Value = app.height_raw;

            app.hw_ratio = app.height_raw / app.width_raw;
        end

        % Close request function: VideoExporterUIFigure
        function VideoExporterUIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: CheckBox_FrameRateSync
        function CheckBox_FrameRateSyncValueChanged(app, event)
            value = app.CheckBox_FrameRateSync.Value;
            
            app.EditField_FrameRate.Enable = ~value;

            app.opts.FrameRateSync = value;
        end

        % Value changed function: DropDown_OutputMarker
        function DropDown_OutputMarkerValueChanged(app, event)
            value = app.DropDown_OutputMarker.Value;
            
            app.opts.OutputMarker = value;
        end

        % Value changed function: DropDown_Resource
        function DropDown_ResourceValueChanged(app, event)
            value = app.DropDown_Resource.Value;
            
            switch value
                case "camera"
                    app.DropDown_OutputMarker.Enable = "off";
                    app.DropDown_OutputMarker.Value = "none";
                    app.opts.OutputMarker = "none";
                otherwise
                    app.DropDown_OutputMarker.Enable = "on";
            end

            app.opts.Resource = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.VideoExporterUIFigure = uifigure('Visible', 'off');
            app.VideoExporterUIFigure.Name = 'Video Exporter';
            app.VideoExporterUIFigure.Icon = fullfile(pathToMLAPP, 'sources', 'exporter.png');
            app.VideoExporterUIFigure.CloseRequestFcn = createCallbackFcn(app, @VideoExporterUIFigureCloseRequest, true);
            app.GridLayout11 = uigridlayout(app.VideoExporterUIFigure);
            app.Panel_Setting = uipanel(app.GridLayout11);
            app.Panel_Setting.Title = '影片参数';
            app.GridLayout = uigridlayout(app.Panel_Setting);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = '影片格式';
            app.DropDown_OutputFormat = uidropdown(app.GridLayout2);
            app.DropDown_OutputFormat.Items = {'Motion JPEG AVI', 'Motion JPEG 2000', 'MPEG-4', 'Uncompress Motion JPEG 2000', 'Uncompress Grayscale AVI'};
            app.DropDown_OutputFormat.ItemsData = {'Motion JPEG AVI', 'Motion JPEG 2000', 'MPEG-4', 'Archival', 'Grayscale AVI'};
            app.DropDown_OutputFormat.ValueChangedFcn = createCallbackFcn(app, @DropDown_OutputFormatValueChanged, true);
            app.DropDown_OutputFormat.Value = 'Motion JPEG AVI';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.Button_LockHWRatio = uibutton(app.GridLayout4, 'state');
            app.Button_LockHWRatio.ValueChangedFcn = createCallbackFcn(app, @Button_LockHWRatioValueChanged, true);
            app.Button_LockHWRatio.Icon = fullfile(pathToMLAPP, 'sources', 'lock.png');
            app.Button_LockHWRatio.Text = '';
            app.Button_LockHWRatio.Value = true;
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.Button_Recover = uibutton(app.GridLayout5, 'push');
            app.Button_Recover.ButtonPushedFcn = createCallbackFcn(app, @Button_RecoverPushed, true);
            app.Button_Recover.Text = '还原';
            app.GridLayout6 = uigridlayout(app.GridLayout3);
            app.Label_2 = uilabel(app.GridLayout6);
            app.Label_2.Text = '宽';
            app.EditField_Width = uieditfield(app.GridLayout6, 'numeric');
            app.EditField_Width.Limits = [1 8192];
            app.EditField_Width.RoundFractionalValues = 'on';
            app.EditField_Width.ValueDisplayFormat = '%.0f';
            app.EditField_Width.ValueChangedFcn = createCallbackFcn(app, @EditField_WidthValueChanged, true);
            app.EditField_Width.Value = 1024;
            app.Label_3 = uilabel(app.GridLayout6);
            app.Label_3.Text = '高';
            app.EditField_Height = uieditfield(app.GridLayout6, 'numeric');
            app.EditField_Height.Limits = [1 8192];
            app.EditField_Height.RoundFractionalValues = 'on';
            app.EditField_Height.ValueDisplayFormat = '%.0f';
            app.EditField_Height.ValueChangedFcn = createCallbackFcn(app, @EditField_HeightValueChanged, true);
            app.EditField_Height.Value = 1024;
            app.GridLayout7 = uigridlayout(app.GridLayout3);
            app.Image_Link = uiimage(app.GridLayout7);
            app.Image_Link.ImageSource = fullfile(pathToMLAPP, 'sources', 'linkicon.png');
            app.GridLayout8 = uigridlayout(app.GridLayout);
            app.Label_8 = uilabel(app.GridLayout8);
            app.Label_8.Text = '帧率';
            app.EditField_FrameRate = uieditfield(app.GridLayout8, 'numeric');
            app.EditField_FrameRate.Limits = [0 Inf];
            app.EditField_FrameRate.ValueChangedFcn = createCallbackFcn(app, @EditField_FrameRateValueChanged, true);
            app.EditField_FrameRate.Value = 25;
            app.Label_FPS = uilabel(app.GridLayout8);
            app.Label_FPS.Text = 'FPS';
            app.CheckBox_FrameRateSync = uicheckbox(app.GridLayout8);
            app.CheckBox_FrameRateSync.ValueChangedFcn = createCallbackFcn(app, @CheckBox_FrameRateSyncValueChanged, true);
            app.CheckBox_FrameRateSync.Text = '获取同步';
            app.GridLayout9 = uigridlayout(app.GridLayout);
            app.Label_4 = uilabel(app.GridLayout9);
            app.Label_4.Text = '渲染质量';
            app.Spinner_RenderingQuality = uispinner(app.GridLayout9);
            app.Spinner_RenderingQuality.Limits = [0 100];
            app.Spinner_RenderingQuality.ValueDisplayFormat = '%.0f';
            app.Spinner_RenderingQuality.ValueChangedFcn = createCallbackFcn(app, @Spinner_RenderingQualityValueChanged, true);
            app.Spinner_RenderingQuality.Value = 75;
            app.Label_10 = uilabel(app.GridLayout9);
            app.Label_10.Text = '%';
            app.CheckBox_LosslessCompress = uicheckbox(app.GridLayout9);
            app.CheckBox_LosslessCompress.ValueChangedFcn = createCallbackFcn(app, @CheckBox_LosslessCompressValueChanged, true);
            app.CheckBox_LosslessCompress.Text = '无损压缩';
            app.GridLayout10 = uigridlayout(app.GridLayout);
            app.Label_7 = uilabel(app.GridLayout10);
            app.Label_7.Text = '目标码率';
            app.EditField_TargetMBitrate = uieditfield(app.GridLayout10, 'numeric');
            app.EditField_TargetMBitrate.Limits = [0 Inf];
            app.EditField_TargetMBitrate.RoundFractionalValues = 'on';
            app.EditField_TargetMBitrate.ValueDisplayFormat = '%.2f';
            app.EditField_TargetMBitrate.ValueChangedFcn = createCallbackFcn(app, @EditField_TargetMBitrateValueChanged, true);
            app.EditField_TargetMBitrate.Value = 10;
            app.Label_Mbps = uilabel(app.GridLayout10);
            app.Label_Mbps.Text = 'Mbps';
            app.GridLayout12 = uigridlayout(app.GridLayout);
            app.Label_6 = uilabel(app.GridLayout12);
            app.Label_6.Text = '插值';
            app.DropDown_Interpolation = uidropdown(app.GridLayout12);
            app.DropDown_Interpolation.Items = {'双线性', '双立方'};
            app.DropDown_Interpolation.ItemsData = {'bilinear', 'bicubic'};
            app.DropDown_Interpolation.ValueChangedFcn = createCallbackFcn(app, @DropDown_InterpolationValueChanged, true);
            app.DropDown_Interpolation.Value = 'bilinear';
            app.Label_9 = uilabel(app.GridLayout12);
            app.Label_9.Text = '标记';
            app.DropDown_OutputMarker = uidropdown(app.GridLayout12);
            app.DropDown_OutputMarker.Items = {'无', '当前', '完整'};
            app.DropDown_OutputMarker.ItemsData = {'none', 'current', 'full'};
            app.DropDown_OutputMarker.ValueChangedFcn = createCallbackFcn(app, @DropDown_OutputMarkerValueChanged, true);
            app.DropDown_OutputMarker.Enable = 'off';
            app.DropDown_OutputMarker.Value = 'none';
            app.GridLayout13 = uigridlayout(app.GridLayout);
            app.Button_OK = uibutton(app.GridLayout13, 'push');
            app.Button_OK.ButtonPushedFcn = createCallbackFcn(app, @Button_OKPushed, true);
            app.Button_OK.Text = '确定';
            app.Button_Cancel = uibutton(app.GridLayout13, 'push');
            app.Button_Cancel.ButtonPushedFcn = createCallbackFcn(app, @Button_CancelPushed, true);
            app.Button_Cancel.Text = '取消';
            app.GridLayout14 = uigridlayout(app.GridLayout);
            app.Label_11 = uilabel(app.GridLayout14);
            app.Label_11.Text = '导出源';
            app.DropDown_Resource = uidropdown(app.GridLayout14);
            app.DropDown_Resource.Items = {'相机队列', '处理队列'};
            app.DropDown_Resource.ItemsData = {'camera', 'processed'};
            app.DropDown_Resource.ValueChangedFcn = createCallbackFcn(app, @DropDown_ResourceValueChanged, true);
            app.DropDown_Resource.Value = 'camera';
            % Show the figure after all components are created
            app.VideoExporterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MovieExporter(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.VideoExporterUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.VideoExporterUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VideoExporterUIFigure)
        end
    end
end