classdef Preference < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PreferenceUIFigure              matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        Panel_Main                      matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        EditField_Tooltip               matlab.ui.control.EditField
        GridLayout3                     matlab.ui.container.GridLayout
        Panel_Parameters                matlab.ui.container.Panel
        GridLayout_Main                 matlab.ui.container.GridLayout
        GridLayout_Parameters           matlab.ui.container.GridLayout
        GridLayout11                    matlab.ui.container.GridLayout
        DropDown_DetectBoxColor         matlab.ui.control.DropDown
        DropDown_DetectBox              matlab.ui.control.DropDown
        Label_14                        matlab.ui.control.Label
        Spinner_TraceTransparency       matlab.ui.control.Spinner
        Label_13                        matlab.ui.control.Label
        DropDown_TraceColormap          matlab.ui.control.DropDown
        Label_12                        matlab.ui.control.Label
        Button_Font                     matlab.ui.control.Button
        DropDown_TraceStay              matlab.ui.control.DropDown
        Label_11                        matlab.ui.control.Label
        DropDown_VerticalAlignment      matlab.ui.control.DropDown
        Label_10                        matlab.ui.control.Label
        DropDown_HorizontalAlignment    matlab.ui.control.DropDown
        Label_9                         matlab.ui.control.Label
        GridLayout10                    matlab.ui.container.GridLayout
        EditField_MarkerFontColor       matlab.ui.control.EditField
        DropDown_MarkerFontColor        matlab.ui.control.DropDown
        Spinner_OffsetY                 matlab.ui.control.Spinner
        Spinner_2Label                  matlab.ui.control.Label
        Spinner_OffsetX                 matlab.ui.control.Spinner
        Label_8                         matlab.ui.control.Label
        DropDown_ProgressBarState       matlab.ui.control.DropDown
        Label_7                         matlab.ui.control.Label
        Spinner_NWorkersGPU             matlab.ui.control.Spinner
        GPUSpinnerLabel                 matlab.ui.control.Label
        Spinner_NWorkersCPU             matlab.ui.control.Spinner
        Label_6                         matlab.ui.control.Label
        DropDown_CameraCache            matlab.ui.control.DropDown
        Label_5                         matlab.ui.control.Label
        DropDown_WindowStyle            matlab.ui.control.DropDown
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        GridLayout7                     matlab.ui.container.GridLayout
        DropDown_ProgressBarBackColor   matlab.ui.control.DropDown
        EditField_ProgressBarBackColor  matlab.ui.control.EditField
        Label_2                         matlab.ui.control.Label
        GridLayout6                     matlab.ui.container.GridLayout
        DropDown_ProgressBarForeColor   matlab.ui.control.DropDown
        EditField_ProgressBarForeColor  matlab.ui.control.EditField
        DropDown_Language               matlab.ui.control.DropDown
        Label                           matlab.ui.control.Label
        UIAxes_MarkerPreview            matlab.ui.control.UIAxes
        Tree_Options                    matlab.ui.container.Tree
        Node_Appearance                 matlab.ui.container.TreeNode
        Node_Language                   matlab.ui.container.TreeNode
        Node_ProgressBar                matlab.ui.container.TreeNode
        Node_Style                      matlab.ui.container.TreeNode
        Node_DataStorage                matlab.ui.container.TreeNode
        Node_TemporaryFile              matlab.ui.container.TreeNode
        Node_ParallelComputing          matlab.ui.container.TreeNode
        Node_ConfigureCPU               matlab.ui.container.TreeNode
        Node_ConfigureGPU               matlab.ui.container.TreeNode
        Node_VideoMarker                matlab.ui.container.TreeNode
        Node_TimeStamp                  matlab.ui.container.TreeNode
        Node_ScaleBar                   matlab.ui.container.TreeNode
        Node_Metadata                   matlab.ui.container.TreeNode
        Node_PlaySpeed                  matlab.ui.container.TreeNode
        Node_DetectBox                  matlab.ui.container.TreeNode
        Node_Trace                      matlab.ui.container.TreeNode
    end

    properties (Constant, Hidden)
        VER = "1.0.0"
        FEATURES_NUMBER = 17
    end

    properties (Access = private)
        caller

        opts

        is_lock
    end
    
    methods (Access = public)

        function Lock(app)
            %
        end

        function Unlock(app)
            %
        end
        
        function CloseThis(app)
            % ~
            delete(app);
        end
    end

    methods (Access = private)

        function InitData(app)
            [app.opts, app.is_lock] = app.caller.GetPreferOptions();
        end

        function InitAppearance(app)
            %% Init Appearence Profile
            app.DropDown_Language.Value = app.opts.Language;

            app.DropDown_ProgressBarState.Value = app.opts.ProgressBarVisible;

            app.EditField_ProgressBarForeColor.Enable = app.opts.ProgressBarVisible;
            app.EditField_ProgressBarForeColor.Value = ...
                num2str(app.opts.ProgressBarForeColor,"%.2f ");
            app.DropDown_ProgressBarForeColor.Enable = app.opts.ProgressBarVisible;
            app.DropDown_ProgressBarForeColor.BackgroundColor = app.opts.ProgressBarForeColor;

            app.EditField_ProgressBarBackColor.Enable = app.opts.ProgressBarVisible;
            app.EditField_ProgressBarBackColor.Value = ...
                num2str(app.opts.ProgressBarBackColor, "%.2f ");
            app.DropDown_ProgressBarBackColor.Enable = app.opts.ProgressBarVisible;
            app.DropDown_ProgressBarBackColor.BackgroundColor = app.opts.ProgressBarBackColor;

            app.DropDown_WindowStyle.Value = app.opts.WindowStyle;
            
            %% Init Data Storage Profile
            app.DropDown_CameraCache.Value = app.opts.CameraCache;

            %% Init Parallel Computing Profile
            app.Spinner_NWorkersCPU.Enable = app.opts.IsParallelValid;
            app.Spinner_NWorkersCPU.Limits = [double(app.opts.CoresEnableCPU>1), ...
                                              app.opts.CoresEnableCPU];
            app.Spinner_NWorkersCPU.Value = app.opts.CoresEnableCPU;

            app.Spinner_NWorkersGPU.Enable = app.opts.IsGPUValid;
            app.Spinner_NWorkersGPU.Limits = [0, max(1, app.opts.CoresEnableGPU)];
            app.Spinner_NWorkersGPU.Value = app.opts.CoresEnableGPU;

            %% Init Video Marker Profile
            %

            %% Beginning Profile
            app.Tree_Options.SelectedNodes = app.Node_Appearance;
            DisplayParamsWith(app, "APPEARANCE");
        end

        function HiddenAllParams(app)
            app.GridLayout_Main.RowHeight = {0, '1x'};
            app.GridLayout_Parameters.RowHeight = zeros(1, app.FEATURES_NUMBER);
        end

        function DisplayParamsWith(app, Tag)
            switch Tag
                %% APPEARANCE
                case "APPEARANCE"
                    app.GridLayout_Main.RowHeight = {0, '1x'};
                    app.GridLayout_Parameters.RowHeight = [23,23,23,23,23,0,0,0,0,0,0,0,0,0,0,0,0];
                case "LANGUAGE"
                    app.GridLayout_Main.RowHeight = {0, '1x'};
                    app.GridLayout_Parameters.RowHeight = [23,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0];
                case "PROGRESS_BAR"
                    app.GridLayout_Main.RowHeight = {0, '1x'};
                    app.GridLayout_Parameters.RowHeight = [0,23,23,23,0,0,0,0,0,0,0,0,0,0,0,0,0];
                case "STYLE"
                    app.GridLayout_Main.RowHeight = {0, '1x'};
                    app.GridLayout_Parameters.RowHeight = [0,0,0,0,23,0,0,0,0,0,0,0,0,0,0,0,0];

                %% DATA_STORAGE
                case "DATA_STORAGE"
                    app.GridLayout_Main.RowHeight = {0, '1x'};
                    app.GridLayout_Parameters.RowHeight = [0,0,0,0,0,23,0,0,0,0,0,0,0,0,0,0,0];
                case "TEMPORARY_FILE"
                    app.GridLayout_Main.RowHeight = {0, '1x'};
                    app.GridLayout_Parameters.RowHeight = [0,0,0,0,0,23,0,0,0,0,0,0,0,0,0,0,0];

                %% PARALLEL COMPUTING
                case "PARALLEL_COMPUTING"
                    app.GridLayout_Main.RowHeight = {0, '1x'};
                    app.GridLayout_Parameters.RowHeight = [0,0,0,0,0,0,23,23,0,0,0,0,0,0,0,0,0];
                case "CONFIGURATION_CPU"
                    app.GridLayout_Main.RowHeight = {0, '1x'};
                    app.GridLayout_Parameters.RowHeight = [0,0,0,0,0,0,23,0,0,0,0,0,0,0,0,0,0];
                case "CONFIGURATION_GPU"
                    app.GridLayout_Main.RowHeight = {0, '1x'};
                    app.GridLayout_Parameters.RowHeight = [0,0,0,0,0,0,23,0,0,0,0,0,0,0,0,0,0];

                %% VIDEO MARKER
                case "VIDEO_MARKER"
                    app.GridLayout_Main.RowHeight = {170, '1x'};
                    app.GridLayout_Parameters.RowHeight = [0,0,0,0,0,0,0,0,23,23,23,23,23,23,23,23,23];
                    configureVideoMarker(app, Tag);
                case "TIME_STAMP"
                    app.GridLayout_Main.RowHeight = {170, '1x'};
                    app.GridLayout_Parameters.RowHeight = [0,0,0,0,0,0,0,0,23,23,23,23,23,0,0,0,0];
                    configureVideoMarker(app, Tag);
                case "SCALE_BAR"
                    app.GridLayout_Main.RowHeight = {170, '1x'};
                    app.GridLayout_Parameters.RowHeight = [0,0,0,0,0,0,0,0,23,23,23,23,23,0,0,0,0];
                    configureVideoMarker(app, Tag);
                case "METADATA"
                    app.GridLayout_Main.RowHeight = {170, '1x'};
                    app.GridLayout_Parameters.RowHeight = [0,0,0,0,0,0,0,0,23,23,23,23,23,0,0,0,0];
                    configureVideoMarker(app, Tag);
                case "PLAY_SPEED"
                    app.GridLayout_Main.RowHeight = {170, '1x'};
                    app.GridLayout_Parameters.RowHeight = [0,0,0,0,0,0,0,0,23,23,23,23,23,0,0,0,0];
                    configureVideoMarker(app, Tag);
                case "DETECT_BOX"
                    app.GridLayout_Main.RowHeight = {170, '1x'};
                    app.GridLayout_Parameters.RowHeight = [0,0,0,0,0,0,0,0,0,0,23,23,0,23,0,0,0];
                    configureVideoMarker(app, Tag);
                case "TRACE"
                    app.GridLayout_Main.RowHeight = {170, '1x'};
                    app.GridLayout_Parameters.RowHeight = [0,0,0,0,0,0,0,0,0,0,0,0,0,0,23,23,23];
                    configureVideoMarker(app, Tag);
                otherwise
            end
        end

        function configureVideoMarker(app, Tag)
            switch Tag
                case "VIDEO_MARKER"
                    switchCompState("off");
                case "TIME_STAMP"
                    opt = app.opts.TimeStampOpts;
                    switchCompState("on");
                    setCompsValue(opt);
                case "SCALE_BAR"
                    opt = app.opts.ScaleBarOpts;
                    switchCompState("on");
                    setCompsValue(opt);
                case "METADATA"
                    opt = app.opts.MetadataOpts;
                    switchCompState("on");
                    setCompsValue(opt);
                case "PLAY_SPEED"
                    opt = app.opts.PlaySpeedOpts;
                    switchCompState("on");
                    setCompsValue(opt);
                case "DETECT_BOX"
                    opt = app.opts.DetectBoxOpts;
                    switchCompState("on");
                    setCompsValue(opt);
                case "TRACE"
                     opt = app.opts.TraceOpts;
                    switchCompState("on");
                    setCompsValue(opt);
                otherwise
            end

            function switchCompState(state)
                switch Tag
                    case {'TIME_STAMP', 'SCALE_BAR', 'METADATA', 'PLAY_SPEED'}
                        app.Spinner_OffsetX.Enable = state;
                        app.Spinner_OffsetY.Enable = state;
                        app.DropDown_HorizontalAlignment.Enable = state;
                        app.DropDown_VerticalAlignment.Enable = state;
                        app.Button_Font.Enable = state;
                        app.EditField_MarkerFontColor.Enable = state;
                        app.DropDown_MarkerFontColor.Enable = state;
                    case "DETECT_BOX"
                        app.DropDown_HorizontalAlignment.Enable = state;
                        app.DropDown_VerticalAlignment.Enable = state;
                        app.DropDown_DetectBox.Enable = state;
                        app.DropDown_DetectBoxColor.Enable = state;
                    case "TRACE"
                        app.DropDown_TraceStay.Enable = state;
                        app.DropDown_TraceColormap.Enable = state;
                        app.Spinner_TraceTransparency.Enable = state;
                    otherwise
                end
                
            end

            function setCompsValue(opts)
                switch Tag
                    case {'TIME_STAMP', 'SCALE_BAR', 'METADATA', 'PLAY_SPEED'}
                        app.Spinner_OffsetX.Value = opts.OffsetX;
                        app.Spinner_OffsetY.Value = opts.OffsetY;
                        app.DropDown_HorizontalAlignment.Value = opts.HorizontalAligmnent;
                        app.DropDown_VerticalAlignment.Value = opts.VerticalAlignment;
                        app.EditField_MarkerFontColor.Value = num2str(opts.FontColor, "%.2f ");
                        app.DropDown_MarkerFontColor.BackgroundColor = opts.FontColor;
                    case "DETECT_BOX"
                        app.DropDown_HorizontalAlignment.Value = opts.HorizontalAligmnent;
                        app.DropDown_VerticalAlignment.Value = opts.VerticalAlignment;
                        app.DropDown_DetectBox.ItemsData = opts.BoxColor.keys("uniform");
                        app.DropDown_DetectBoxColor.BackgroundColor ...
                            = opts.BoxColor.values{1};
                    case "TRACE"
                        app.DropDown_TraceStay.Value = num2str(opts.StayDuration);
                        app.DropDown_TraceColormap.Value = opts.ColorMap;
                        app.Spinner_TraceTransparency.Value = opts.Transparency;
                    otherwise
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            HiddenAllParams(app);
            
            app.caller = mainapp;

            InitData(app);

            InitAppearance(app);
        end

        % Close request function: PreferenceUIFigure
        function PreferenceUIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Selection changed function: Tree_Options
        function Tree_OptionsSelectionChanged(app, event)
            selectedNodes = app.Tree_Options.SelectedNodes;
            
            DisplayParamsWith(app, selectedNodes.Tag);
        end

        % Value changed function: DropDown_Language
        function DropDown_LanguageValueChanged(app, event)
            value = app.DropDown_Language.Value;
            
            app.opts.Language = value;
        end

        % Value changed function: DropDown_ProgressBarState
        function DropDown_ProgressBarStateValueChanged(app, event)
            value = app.DropDown_ProgressBarState.Value;
            
            app.opts.ProgressBarVisible = value;

            app.caller.SetPreferOptions(app.opts);
        end

        % Value changed function: EditField_ProgressBarForeColor
        function EditField_ProgressBarForeColorValueChanged(app, event)
            value = app.EditField_ProgressBarForeColor.Value;
            
            value = str2num(value); %#ok<ST2NM>
            if ~isempty(value) && numel(value)==3 && all(value>=0 & value<=1)
                app.opts.ProgressBarForeColor = value;
                app.DropDown_ProgressBarForeColor.BackgroundColor = value;

                app.caller.SetPreferOptions(app.opts);
            else
                app.EditField_ProgressBarForeColor.Value = ...
                    string(app.opts.ProgressBarForeColor).join(",");
            end
        end

        % Value changed function: EditField_ProgressBarBackColor
        function EditField_ProgressBarBackColorValueChanged(app, event)
            value = app.EditField_ProgressBarBackColor.Value;

            value = str2num(value); %#ok<ST2NM>
            if ~isempty(value) && numel(value)==3 && all(value>=0 & value<=1)
                app.opts.ProgressBarBackColor = value;
                app.DropDown_ProgressBarBackColor.BackgroundColor = value;

                app.caller.SetPreferOptions(app.opts);
            else
                app.EditField_ProgressBarBackColor.Value = ...
                    string(app.opts.ProgressBarBackColor).join(",");
            end
        end

        % Value changed function: DropDown_WindowStyle
        function DropDown_WindowStyleValueChanged(app, event)
            value = app.DropDown_WindowStyle.Value;
            
            app.opts.WindowStyle = value;

            app.caller.SetPreferOptions(app.opts);
        end

        % Value changed function: DropDown_CameraCache
        function DropDown_CameraCacheValueChanged(app, event)
            value = app.DropDown_CameraCache.Value;
            
            app.opts.CameraCache = value;

            app.caller.SetPreferOptions(app.opts);
        end

        % Value changed function: Spinner_NWorkersCPU
        function Spinner_NWorkersCPUValueChanged(app, event)
            value = app.Spinner_NWorkersCPU.Value;

            app.opts.CoresEnableCPU = value;

            app.caller.SetPreferOptions(app.opts);
        end

        % Value changed function: Spinner_NWorkersGPU
        function Spinner_NWorkersGPUValueChanged(app, event)
            value = app.Spinner_NWorkersGPU.Value;
            
            app.opts.CoresEnableGPU = value;

            app.caller.SetPreferOptions(app.opts);
        end

        % Clicked callback: DropDown_ProgressBarBackColor
        function DropDown_ProgressBarBackColorClicked(app, event)
            value = uisetcolor(app.opts.ProgressBarBackColor, "选择背景颜色");
            value = round(value, 2);

            app.opts.ProgressBarBackColor = value;
            app.DropDown_ProgressBarBackColor.BackgroundColor = value;
            app.EditField_ProgressBarBackColor.Value = string(value).join(",");

            app.caller.SetPreferOptions(app.opts);
        end

        % Clicked callback: DropDown_ProgressBarForeColor
        function DropDown_ProgressBarForeColorClicked(app, event)
            value = uisetcolor(app.opts.ProgressBarForeColor, "选择前景颜色");
            value = round(value, 2);

            app.opts.ProgressBarForeColor = value;
            app.DropDown_ProgressBarForeColor.BackgroundColor = value;
            app.EditField_ProgressBarForeColor.Value = string(value).join(",");

            app.caller.SetPreferOptions(app.opts);
        end

        % Value changed function: Spinner_OffsetX
        function Spinner_OffsetXValueChanged(app, event)
            value = app.Spinner_OffsetX.Value;
            
            switch app.Tree_Options.SelectedNodes.Tag
                case "TIME_STAMP"
                    app.opts.TimeStampOpts.OffsetX = value;
                case "SCALE_BAR"
                    app.opts.ScaleBarOpts.OffsetX = value;
                case "METADATA"
                    app.opts.MetadataOpts.OffsetX = value;
                case "PLAY_SPEED"
                    app.opts.PlaySpeedOpts.OffsetX = value;
                otherwise
            end

            app.caller.SetPreferOptions(app.opts);
        end

        % Value changed function: Spinner_OffsetY
        function Spinner_OffsetYValueChanged(app, event)
            value = app.Spinner_OffsetY.Value;
            
            switch app.Tree_Options.SelectedNodes.Tag
                case "TIME_STAMP"
                    app.opts.TimeStampOpts.OffsetY = value;
                case "SCALE_BAR"
                    app.opts.ScaleBarOpts.OffsetY = value;
                case "METADATA"
                    app.opts.MetadataOpts.OffsetY = value;
                case "PLAY_SPEED"
                    app.opts.PlaySpeedOpts.OffsetY = value;
                otherwise
            end

            app.caller.SetPreferOptions(app.opts);
        end

        % Value changed function: DropDown_HorizontalAlignment
        function DropDown_HorizontalAlignmentValueChanged(app, event)
            value = app.DropDown_HorizontalAlignment.Value;
            
            switch app.Tree_Options.SelectedNodes.Tag
                case "TIME_STAMP"
                    app.opts.TimeStampOpts.HorizontalAlignment = value;
                case "SCALE_BAR"
                    app.opts.ScaleBarOpts.HorizontalAlignment = value;
                case "METADATA"
                    app.opts.MetadataOpts.HorizontalAlignment = value;
                case "PLAY_SPEED"
                    app.opts.PlaySpeedOpts.HorizontalAlignment = value;
                otherwise
            end

            app.caller.SetPreferOptions(app.opts);
        end

        % Value changed function: DropDown_VerticalAlignment
        function DropDown_VerticalAlignmentValueChanged(app, event)
            value = app.DropDown_VerticalAlignment.Value;
            
            switch app.Tree_Options.SelectedNodes.Tag
                case "TIME_STAMP"
                    app.opts.TimeStampOpts.VerticalAlignment = value;
                case "SCALE_BAR"
                    app.opts.ScaleBarOpts.VerticalAlignment = value;
                case "METADATA"
                    app.opts.MetadataOpts.VerticalAlignment = value;
                case "PLAY_SPEED"
                    app.opts.PlaySpeedOpts.VerticalAlignment = value;
                otherwise
            end

            app.caller.SetPreferOptions(app.opts);
        end

        % Button pushed function: Button_Font
        function Button_FontPushed(app, event)
            switch app.Tree_Options.SelectedNodes.Tag
                case "TIME_STAMP"
                    app.opts.TimeStampOpts.Font = ...
                        uisetfont(app.opts.TimeStampOpts.Font);
                case "SCALE_BAR"
                    app.opts.ScaleBarOpts.Font = ...
                        uisetfont(app.opts.ScaleBarOpts.Font);
                case "METADATA"
                    app.opts.MetadataOpts.Font = ...
                        uisetfont(app.opts.MetadataOpts.Font);
                case "PLAY_SPEED"
                    app.opts.PlaySpeedOpts.Font = ...
                        uisetfont(app.opts.PlaySpeedOpts.Font);
                otherwise
            end

            app.caller.SetPreferOptions(app.opts);
        end

        % Value changed function: EditField_MarkerFontColor
        function EditField_MarkerFontColorValueChanged(app, event)
            value = app.EditField_MarkerFontColor.Value;
            value = str2num(value); %#ok<ST2NM>
            
            switch app.Tree_Options.SelectedNodes.Tag
                case "TIME_STAMP"
                    if ~isempty(value) && numel(value)==3 ...
                            && all(value>=0 & value<=1)
                        app.opts.TimeStampOpts.FontColor = value;
                        app.DropDown_MarkerFontColor.BackgroundColor = value;
                    else
                        app.EditField_MarkerFontColor.Value ...
                            = string(app.opts.TimeStampOpts.FontColor).join(",");
                    end
                case "SCALE_BAR"
                    if ~isempty(value) && numel(value)==3 ...
                            && all(value>=0 & value<=1)
                        app.opts.ScaleBarOpts.FontColor = value;
                        app.DropDown_MarkerFontColor.BackgroundColor = value;
                    else
                        app.EditField_MarkerFontColor.Value ...
                            = string(app.opts.ScaleBarOpts.FontColor).join(",");
                    end
                case "METADATA"
                    if ~isempty(value) && numel(value)==3 ...
                            && all(value>=0 & value<=1)
                        app.opts.MetadataOpts.FontColor = value;
                        app.DropDown_MarkerFontColor.BackgroundColor = value;
                    else
                        app.EditField_MarkerFontColor.Value ...
                            = string(app.opts.MetadataOpts.FontColor).join(",");
                    end
                case "PLAY_SPEED"
                    if ~isempty(value) && numel(value)==3 ...
                            && all(value>=0 & value<=1)
                        app.opts.PlaySpeedOpts.FontColor = value;
                        app.DropDown_MarkerFontColor.BackgroundColor = value;
                    else
                        app.EditField_MarkerFontColor.Value ...
                            = string(app.opts.PlaySpeedOpts.FontColor).join(",");
                    end
                otherwise
            end

            app.caller.SetPreferOptions(app.opts);
        end

        % Clicked callback: DropDown_MarkerFontColor
        function DropDown_MarkerFontColorClicked(app, event)
            switch app.Tree_Options.SelectedNodes.Tag
                case "TIME_STAMP"
                    value = uisetcolor(app.opts.TimeStampOpts.FontColor, "选择文字颜色");
                    value = round(value, 2);

                    app.opts.TimeStampOpts.FontColor = value;
                    app.DropDown_MarkerFontColor.BackgroundColor = value;
                    app.EditField_MarkerFontColor.Value = string(value).join(",");
                case "SCALE_BAR"
                    value = uisetcolor(app.opts.ScaleBarOpts.FontColor, "选择文字颜色");
                    value = round(value, 2);

                    app.opts.ScaleBarOpts.FontColor = value;
                    app.DropDown_MarkerFontColor.BackgroundColor = value;
                    app.EditField_MarkerFontColor.Value = string(value).join(",");
                case "METADATA"
                    value = uisetcolor(app.opts.MetadataOpts.FontColor, "选择文字颜色");
                    value = round(value, 2);

                    app.opts.MetadataOpts.FontColor = value;
                    app.DropDown_MarkerFontColor.BackgroundColor = value;
                    app.EditField_MarkerFontColor.Value = string(value).join(",");
                case "PLAY_SPEED"
                    value = uisetcolor(app.opts.PlaySpeedOpts.FontColor, "选择文字颜色");
                    value = round(value, 2);

                    app.opts.PlaySpeedOpts.FontColor = value;
                    app.DropDown_MarkerFontColor.BackgroundColor = value;
                    app.EditField_MarkerFontColor.Value = string(value).join(",");
                otherwise
            end

            app.caller.SetPreferOptions(app.opts);
        end

        % Value changed function: DropDown_DetectBox
        function DropDown_DetectBoxValueChanged(app, event)
            value = app.DropDown_DetectBox.Value;
            
            app.DropDown_DetectBoxColor.BackgroundColor ...
                = app.opts.DetectBoxOpts.BoxColor(value);
        end

        % Clicked callback: DropDown_DetectBoxColor
        function DropDown_DetectBoxColorClicked(app, event)
            value = uisetcolor(app.DropDown_DetectBoxColor.BackgroundColor, ...
                "选择文字颜色");
            value = round(value, 2);
            
            label = app.DropDown_DetectBox.Value;
            app.opts.DetectBoxOpts.BoxColor(label) = value;

            app.DropDown_DetectBoxColor.BackgroundColor = value;

            app.caller.SetPreferOptions(app.opts);
        end

        % Value changed function: DropDown_TraceStay
        function DropDown_TraceStayValueChanged(app, event)
            value = app.DropDown_TraceStay.Value;
            
            app.opts.TraceOpts.StayDuration = str2num(value); %#ok<ST2NM>

            app.caller.SetPreferOptions(app.opts);
        end

        % Value changed function: DropDown_TraceColormap
        function DropDown_TraceColormapValueChanged(app, event)
            value = app.DropDown_TraceColormap.Value;
            
            app.opts.TraceOpts.Colormap = value;

            app.caller.SetPreferOptions(app.opts);
        end

        % Value changed function: Spinner_TraceTransparency
        function Spinner_TraceTransparencyValueChanged(app, event)
            value = app.Spinner_TraceTransparency.Value;
            
            app.opts.TraceOpts.Transparency = value;

            app.caller.SetPreferOptions(app.opts);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PreferenceUIFigure = uifigure('Visible', 'off');
            app.PreferenceUIFigure.Name = 'Preference';
            app.PreferenceUIFigure.Icon = fullfile(pathToMLAPP, 'sources', 'controls.png');
            app.PreferenceUIFigure.CloseRequestFcn = createCallbackFcn(app, @PreferenceUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.PreferenceUIFigure);
            app.Panel_Main = uipanel(app.GridLayout);
            app.Panel_Main.Title = '首选项';
            app.GridLayout4 = uigridlayout(app.Panel_Main);
            app.GridLayout3 = uigridlayout(app.GridLayout4);
            app.Tree_Options = uitree(app.GridLayout3);
            app.Tree_Options.SelectionChangedFcn = createCallbackFcn(app, @Tree_OptionsSelectionChanged, true);
            app.Node_Appearance = uitreenode(app.Tree_Options);
            app.Node_Appearance.Tag = 'APPEARANCE';
            app.Node_Appearance.Text = '外观';
            app.Node_Language = uitreenode(app.Node_Appearance);
            app.Node_Language.Tag = 'LANGUAGE';
            app.Node_Language.Text = '语言';
            app.Node_ProgressBar = uitreenode(app.Node_Appearance);
            app.Node_ProgressBar.Tag = 'PROGRESS_BAR';
            app.Node_ProgressBar.Text = '进度条';
            app.Node_Style = uitreenode(app.Node_Appearance);
            app.Node_Style.Tag = 'STYLE';
            app.Node_Style.Text = '风格';
            app.Node_DataStorage = uitreenode(app.Tree_Options);
            app.Node_DataStorage.Tag = 'DATA_STORAGE';
            app.Node_DataStorage.Text = '数据管理';
            app.Node_TemporaryFile = uitreenode(app.Node_DataStorage);
            app.Node_TemporaryFile.Tag = 'TEMPORARY_FILE';
            app.Node_TemporaryFile.Text = '临时文件';
            app.Node_ParallelComputing = uitreenode(app.Tree_Options);
            app.Node_ParallelComputing.Tag = 'PARALLEL_COMPUTING';
            app.Node_ParallelComputing.Text = '并行计算';
            app.Node_ConfigureCPU = uitreenode(app.Node_ParallelComputing);
            app.Node_ConfigureCPU.Tag = 'CONFIGURATION_CPU';
            app.Node_ConfigureCPU.Text = '配置CPU';
            app.Node_ConfigureGPU = uitreenode(app.Node_ParallelComputing);
            app.Node_ConfigureGPU.Tag = 'CONFIGURATION_GPU';
            app.Node_ConfigureGPU.Text = '配置GPU';
            app.Node_VideoMarker = uitreenode(app.Tree_Options);
            app.Node_VideoMarker.Tag = 'VIDEO_MARKER';
            app.Node_VideoMarker.Text = '视频标记';
            app.Node_TimeStamp = uitreenode(app.Node_VideoMarker);
            app.Node_TimeStamp.Tag = 'TIME_STAMP';
            app.Node_TimeStamp.Text = '时间戳';
            app.Node_ScaleBar = uitreenode(app.Node_VideoMarker);
            app.Node_ScaleBar.Tag = 'SCALE_BAR';
            app.Node_ScaleBar.Text = '比例尺';
            app.Node_Metadata = uitreenode(app.Node_VideoMarker);
            app.Node_Metadata.Tag = 'METADATA';
            app.Node_Metadata.Text = '元数据';
            app.Node_PlaySpeed = uitreenode(app.Node_VideoMarker);
            app.Node_PlaySpeed.Tag = 'PLAY_SPEED';
            app.Node_PlaySpeed.Text = '播放速度';
            app.Node_DetectBox = uitreenode(app.Node_VideoMarker);
            app.Node_DetectBox.Tag = 'DETECT_BOX';
            app.Node_DetectBox.Text = '检测框';
            app.Node_Trace = uitreenode(app.Node_VideoMarker);
            app.Node_Trace.Tag = 'TRACE';
            app.Node_Trace.Text = '运动轨迹';
            app.Panel_Parameters = uipanel(app.GridLayout3);
            app.Panel_Parameters.Title = '参数';
            app.GridLayout_Main = uigridlayout(app.Panel_Parameters);
            app.UIAxes_MarkerPreview = uiaxes(app.GridLayout_Main);
            title(app.UIAxes_MarkerPreview, '视频标记 (位置预览)')
            zlabel(app.UIAxes_MarkerPreview, 'Z')
            app.UIAxes_MarkerPreview.XTick = [0 1];
            app.UIAxes_MarkerPreview.YTick = [0 1];
            app.GridLayout_Parameters = uigridlayout(app.GridLayout_Main);
            app.Label = uilabel(app.GridLayout_Parameters);
            app.Label.Text = '语言';
            app.DropDown_Language = uidropdown(app.GridLayout_Parameters);
            app.DropDown_Language.Items = {'简体中文', '繁體中文', 'English'};
            app.DropDown_Language.ItemsData = {'Chinese', 'Traditional', 'English'};
            app.DropDown_Language.ValueChangedFcn = createCallbackFcn(app, @DropDown_LanguageValueChanged, true);
            app.DropDown_Language.Value = 'Chinese';
            app.GridLayout6 = uigridlayout(app.GridLayout_Parameters);
            app.EditField_ProgressBarForeColor = uieditfield(app.GridLayout6, 'text');
            app.EditField_ProgressBarForeColor.ValueChangedFcn = createCallbackFcn(app, @EditField_ProgressBarForeColorValueChanged, true);
            app.EditField_ProgressBarForeColor.Value = '0.30,0.75,0.93';
            app.DropDown_ProgressBarForeColor = uidropdown(app.GridLayout6);
            app.DropDown_ProgressBarForeColor.Items = {};
            app.DropDown_ProgressBarForeColor.ClickedFcn = createCallbackFcn(app, @DropDown_ProgressBarForeColorClicked, true);
            app.DropDown_ProgressBarForeColor.Value = {};
            app.Label_2 = uilabel(app.GridLayout_Parameters);
            app.Label_2.Text = '前景颜色';
            app.GridLayout7 = uigridlayout(app.GridLayout_Parameters);
            app.EditField_ProgressBarBackColor = uieditfield(app.GridLayout7, 'text');
            app.EditField_ProgressBarBackColor.ValueChangedFcn = createCallbackFcn(app, @EditField_ProgressBarBackColorValueChanged, true);
            app.EditField_ProgressBarBackColor.Value = '0.90,0.90,0.90';
            app.DropDown_ProgressBarBackColor = uidropdown(app.GridLayout7);
            app.DropDown_ProgressBarBackColor.Items = {};
            app.DropDown_ProgressBarBackColor.ClickedFcn = createCallbackFcn(app, @DropDown_ProgressBarBackColorClicked, true);
            app.DropDown_ProgressBarBackColor.Value = {};
            app.Label_3 = uilabel(app.GridLayout_Parameters);
            app.Label_3.Text = '背景颜色';
            app.Label_4 = uilabel(app.GridLayout_Parameters);
            app.Label_4.Text = '窗体风格';
            app.DropDown_WindowStyle = uidropdown(app.GridLayout_Parameters);
            app.DropDown_WindowStyle.Items = {'简洁', '经典'};
            app.DropDown_WindowStyle.ItemsData = {'simple', 'classical'};
            app.DropDown_WindowStyle.ValueChangedFcn = createCallbackFcn(app, @DropDown_WindowStyleValueChanged, true);
            app.DropDown_WindowStyle.Value = 'simple';
            app.Label_5 = uilabel(app.GridLayout_Parameters);
            app.Label_5.Text = '相机缓冲区';
            app.DropDown_CameraCache = uidropdown(app.GridLayout_Parameters);
            app.DropDown_CameraCache.Items = {'内存', '硬盘'};
            app.DropDown_CameraCache.ItemsData = {'memory', 'hard drive'};
            app.DropDown_CameraCache.ValueChangedFcn = createCallbackFcn(app, @DropDown_CameraCacheValueChanged, true);
            app.DropDown_CameraCache.Value = 'memory';
            app.Label_6 = uilabel(app.GridLayout_Parameters);
            app.Label_6.Text = 'CPU核心数';
            app.Spinner_NWorkersCPU = uispinner(app.GridLayout_Parameters);
            app.Spinner_NWorkersCPU.Limits = [1 8];
            app.Spinner_NWorkersCPU.RoundFractionalValues = 'on';
            app.Spinner_NWorkersCPU.ValueDisplayFormat = '%.0f';
            app.Spinner_NWorkersCPU.ValueChangedFcn = createCallbackFcn(app, @Spinner_NWorkersCPUValueChanged, true);
            app.Spinner_NWorkersCPU.Value = 8;
            app.GPUSpinnerLabel = uilabel(app.GridLayout_Parameters);
            app.GPUSpinnerLabel.Text = 'GPU核心数';
            app.Spinner_NWorkersGPU = uispinner(app.GridLayout_Parameters);
            app.Spinner_NWorkersGPU.Limits = [0 2];
            app.Spinner_NWorkersGPU.ValueDisplayFormat = '%.0f';
            app.Spinner_NWorkersGPU.ValueChangedFcn = createCallbackFcn(app, @Spinner_NWorkersGPUValueChanged, true);
            app.Label_7 = uilabel(app.GridLayout_Parameters);
            app.Label_7.Text = '进度条状态';
            app.DropDown_ProgressBarState = uidropdown(app.GridLayout_Parameters);
            app.DropDown_ProgressBarState.Items = {'启用', '禁用'};
            app.DropDown_ProgressBarState.ItemsData = {'on', 'off'};
            app.DropDown_ProgressBarState.ValueChangedFcn = createCallbackFcn(app, @DropDown_ProgressBarStateValueChanged, true);
            app.DropDown_ProgressBarState.Value = 'on';
            app.Label_8 = uilabel(app.GridLayout_Parameters);
            app.Label_8.Text = '水平偏移量';
            app.Spinner_OffsetX = uispinner(app.GridLayout_Parameters);
            app.Spinner_OffsetX.Limits = [0 Inf];
            app.Spinner_OffsetX.RoundFractionalValues = 'on';
            app.Spinner_OffsetX.ValueDisplayFormat = '%.0f';
            app.Spinner_OffsetX.ValueChangedFcn = createCallbackFcn(app, @Spinner_OffsetXValueChanged, true);
            app.Spinner_2Label = uilabel(app.GridLayout_Parameters);
            app.Spinner_2Label.Text = '垂直偏移量';
            app.Spinner_OffsetY = uispinner(app.GridLayout_Parameters);
            app.Spinner_OffsetY.Limits = [0 Inf];
            app.Spinner_OffsetY.RoundFractionalValues = 'on';
            app.Spinner_OffsetY.ValueDisplayFormat = '%.0f';
            app.Spinner_OffsetY.ValueChangedFcn = createCallbackFcn(app, @Spinner_OffsetYValueChanged, true);
            app.GridLayout10 = uigridlayout(app.GridLayout_Parameters);
            app.DropDown_MarkerFontColor = uidropdown(app.GridLayout10);
            app.DropDown_MarkerFontColor.Items = {};
            app.DropDown_MarkerFontColor.ClickedFcn = createCallbackFcn(app, @DropDown_MarkerFontColorClicked, true);
            app.DropDown_MarkerFontColor.Value = {};
            app.EditField_MarkerFontColor = uieditfield(app.GridLayout10, 'text');
            app.EditField_MarkerFontColor.ValueChangedFcn = createCallbackFcn(app, @EditField_MarkerFontColorValueChanged, true);
            app.EditField_MarkerFontColor.Value = '1.00,1.00,0.00';
            app.Label_9 = uilabel(app.GridLayout_Parameters);
            app.Label_9.Text = '水平对齐';
            app.DropDown_HorizontalAlignment = uidropdown(app.GridLayout_Parameters);
            app.DropDown_HorizontalAlignment.Items = {'左对齐', '居中', '右对齐'};
            app.DropDown_HorizontalAlignment.ItemsData = {'left', 'center', 'right'};
            app.DropDown_HorizontalAlignment.ValueChangedFcn = createCallbackFcn(app, @DropDown_HorizontalAlignmentValueChanged, true);
            app.DropDown_HorizontalAlignment.Value = 'center';
            app.Label_10 = uilabel(app.GridLayout_Parameters);
            app.Label_10.Text = '垂直对齐';
            app.DropDown_VerticalAlignment = uidropdown(app.GridLayout_Parameters);
            app.DropDown_VerticalAlignment.Items = {'上对齐', '居中', '下对齐'};
            app.DropDown_VerticalAlignment.ItemsData = {'top', 'center', 'bottom'};
            app.DropDown_VerticalAlignment.ValueChangedFcn = createCallbackFcn(app, @DropDown_VerticalAlignmentValueChanged, true);
            app.DropDown_VerticalAlignment.Value = 'top';
            app.Label_11 = uilabel(app.GridLayout_Parameters);
            app.Label_11.Text = '轨迹保留';
            app.DropDown_TraceStay = uidropdown(app.GridLayout_Parameters);
            app.DropDown_TraceStay.Items = {'仅当前帧', '10 s', '30 s', '60 s', '全部'};
            app.DropDown_TraceStay.ItemsData = {'0', '10', '30', '60', 'Inf'};
            app.DropDown_TraceStay.ValueChangedFcn = createCallbackFcn(app, @DropDown_TraceStayValueChanged, true);
            app.DropDown_TraceStay.Value = '10';
            app.Button_Font = uibutton(app.GridLayout_Parameters, 'push');
            app.Button_Font.ButtonPushedFcn = createCallbackFcn(app, @Button_FontPushed, true);
            app.Button_Font.Text = '字体';
            app.Label_12 = uilabel(app.GridLayout_Parameters);
            app.Label_12.Text = '轨迹颜色';
            app.DropDown_TraceColormap = uidropdown(app.GridLayout_Parameters);
            app.DropDown_TraceColormap.Items = {'turbo', 'jet', 'parula', 'spring', 'summer', 'autumn', 'winter', 'hot', 'cool', 'sky'};
            app.DropDown_TraceColormap.ItemsData = {'turbo', 'jet', 'parula', 'spring', 'summer', 'autumn', 'winter', 'hot', 'cool', 'sky'};
            app.DropDown_TraceColormap.ValueChangedFcn = createCallbackFcn(app, @DropDown_TraceColormapValueChanged, true);
            app.DropDown_TraceColormap.Value = 'turbo';
            app.Label_13 = uilabel(app.GridLayout_Parameters);
            app.Label_13.Text = '轨迹透明化';
            app.Spinner_TraceTransparency = uispinner(app.GridLayout_Parameters);
            app.Spinner_TraceTransparency.Step = 0.05;
            app.Spinner_TraceTransparency.Limits = [0 1];
            app.Spinner_TraceTransparency.ValueDisplayFormat = '%.2f';
            app.Spinner_TraceTransparency.ValueChangedFcn = createCallbackFcn(app, @Spinner_TraceTransparencyValueChanged, true);
            app.Spinner_TraceTransparency.Tooltip = {'start point transparency with linear increasing'};
            app.Label_14 = uilabel(app.GridLayout_Parameters);
            app.Label_14.Text = '目标框';
            app.GridLayout11 = uigridlayout(app.GridLayout_Parameters);
            app.DropDown_DetectBox = uidropdown(app.GridLayout11);
            app.DropDown_DetectBox.Items = {'larva', 'bubble', 'impurities'};
            app.DropDown_DetectBox.ItemsData = {'larva', 'bubble', 'impurities'};
            app.DropDown_DetectBox.ValueChangedFcn = createCallbackFcn(app, @DropDown_DetectBoxValueChanged, true);
            app.DropDown_DetectBox.Value = 'larva';
            app.DropDown_DetectBoxColor = uidropdown(app.GridLayout11);
            app.DropDown_DetectBoxColor.Items = {};
            app.DropDown_DetectBoxColor.ClickedFcn = createCallbackFcn(app, @DropDown_DetectBoxColorClicked, true);
            app.DropDown_DetectBoxColor.Value = {};
            app.EditField_Tooltip = uieditfield(app.GridLayout4, 'text');
            app.EditField_Tooltip.Editable = 'off';
            app.EditField_Tooltip.Tooltip = {'提示'};
            % Show the figure after all components are created
            app.PreferenceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Preference(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.PreferenceUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.PreferenceUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PreferenceUIFigure)
        end
    end
end