classdef ScaleBar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ScaleBarUIFigure     matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        Panel_Ratio          matlab.ui.container.Panel
        GridLayout2          matlab.ui.container.GridLayout
        Label_XLen           matlab.ui.control.Label
        EditField_XLen       matlab.ui.control.NumericEditField
        Label_7              matlab.ui.control.Label
        DropDown_LengthUnit  matlab.ui.control.DropDown
        Label                matlab.ui.control.Label
        Label_XRes           matlab.ui.control.Label
        EditField_XRes       matlab.ui.control.NumericEditField
        Label_8              matlab.ui.control.Label
        EditField_YRes       matlab.ui.control.NumericEditField
        Label_9              matlab.ui.control.Label
        Label_YRes           matlab.ui.control.Label
    end

    properties (Constant, Hidden)
        VER = "1.0.0"
    end

    properties (Access = private)
        caller

        opts

        is_lock
    end

    methods (Access = public)

        function Lock(app)

        end

        function Unlock(app)

        end

        function CloseThis(app)
            % ~
            delete(app);
        end
    end
    
    methods (Access = private)

        function InitData(app)
            [app.opts, app.is_lock] = app.caller.GetScaleOptions();
        end
        
        function InitPanel(app)
            app.EditField_XRes.Value = app.opts.XRes;
            app.EditField_YRes.Value = app.opts.YRes;
            app.DropDown_LengthUnit.Value = app.opts.ResUnit;
            app.EditField_XLen.Value = app.opts.BarLength;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.caller = mainapp;

            InitData(app);

            InitPanel(app);
        end

        % Value changed function: EditField_XRes
        function EditField_XResValueChanged(app, event)
            value = app.EditField_XRes.Value;
            
            app.opts.XRes = value;

            app.caller.SetScaleOptions(app.opts);
        end

        % Value changed function: EditField_YRes
        function EditField_YResValueChanged(app, event)
            value = app.EditField_YRes.Value;
            
            app.opts.YRes = value;
            
            app.caller.SetScaleOptions(app.opts);
        end

        % Value changed function: DropDown_LengthUnit
        function DropDown_LengthUnitValueChanged(app, event)
            value = string(app.DropDown_LengthUnit.Value);

            app.Label_XRes.Text = value + "/pixel";
            app.Label_YRes.Text = value + "/pixel";

            app.opts.ResUnit = value;

            app.caller.SetScaleOptions(app.opts);
        end

        % Value changed function: EditField_XLen
        function EditField_XLenValueChanged(app, event)
            value = app.EditField_XLen.Value;
            
            app.opts.BarLength = value;

            app.caller.SetScaleOptions(app.opts);
        end

        % Close request function: ScaleBarUIFigure
        function ScaleBarUIFigureCloseRequest(app, event)
            % ~

            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ScaleBarUIFigure = uifigure('Visible', 'off');
            app.ScaleBarUIFigure.Name = 'Scale Bar';
            app.ScaleBarUIFigure.Icon = 'E:\si lab\Matlab Projects\EasyBehaviour\subapps\ScaleBar\sources\ruler.png';
            app.ScaleBarUIFigure.CloseRequestFcn = createCallbackFcn(app, @ScaleBarUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.ScaleBarUIFigure);
            app.Panel_Ratio = uipanel(app.GridLayout);
            app.Panel_Ratio.Title = '比例尺';
            app.GridLayout2 = uigridlayout(app.Panel_Ratio);
            app.Label_YRes = uilabel(app.GridLayout2);
            app.Label_YRes.Text = 'mm/pixel';
            app.Label_9 = uilabel(app.GridLayout2);
            app.Label_9.Text = '竖直分辨率';
            app.EditField_YRes = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_YRes.Limits = [0 1];
            app.EditField_YRes.ValueDisplayFormat = '%.2f';
            app.EditField_YRes.ValueChangedFcn = createCallbackFcn(app, @EditField_YResValueChanged, true);
            app.EditField_YRes.Value = 0.1;
            app.Label_8 = uilabel(app.GridLayout2);
            app.Label_8.Text = '水平分辨率';
            app.EditField_XRes = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_XRes.Limits = [0 1];
            app.EditField_XRes.ValueDisplayFormat = '%.2f';
            app.EditField_XRes.ValueChangedFcn = createCallbackFcn(app, @EditField_XResValueChanged, true);
            app.EditField_XRes.Value = 0.1;
            app.Label_XRes = uilabel(app.GridLayout2);
            app.Label_XRes.Text = 'mm/pixel';
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = '长度单位';
            app.DropDown_LengthUnit = uidropdown(app.GridLayout2);
            app.DropDown_LengthUnit.Items = {'mm', 'cm', 'inch'};
            app.DropDown_LengthUnit.ItemsData = {'mm', 'cm', 'inch'};
            app.DropDown_LengthUnit.ValueChangedFcn = createCallbackFcn(app, @DropDown_LengthUnitValueChanged, true);
            app.DropDown_LengthUnit.Value = 'mm';
            app.Label_7 = uilabel(app.GridLayout2);
            app.Label_7.Text = '比例尺长度';
            app.EditField_XLen = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_XLen.Limits = [0 Inf];
            app.EditField_XLen.ValueDisplayFormat = '%.1f';
            app.EditField_XLen.ValueChangedFcn = createCallbackFcn(app, @EditField_XLenValueChanged, true);
            app.EditField_XLen.Value = 10;
            app.Label_XLen = uilabel(app.GridLayout2);
            app.Label_XLen.Text = 'mm';
            % Show the figure after all components are created
            app.ScaleBarUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ScaleBar(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ScaleBarUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ScaleBarUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ScaleBarUIFigure)
        end
    end
end